classdef cv_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        DashboardTab                    matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        Panel_7                         matlab.ui.container.Panel
        GridLayout16_6                  matlab.ui.container.GridLayout
        GridLayout37                    matlab.ui.container.GridLayout
        StatusReadyLabel                matlab.ui.control.Label
        GUIInfoLabel                    matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        GridLayout16_5                  matlab.ui.container.GridLayout
        GridLayout20_2                  matlab.ui.container.GridLayout
        EndImgFilenameLabel             matlab.ui.control.Label
        EndImageLabel                   matlab.ui.control.Label
        GridLayoutEndImage              matlab.ui.container.GridLayout
        Panel_5                         matlab.ui.container.Panel
        GridLayout16_4                  matlab.ui.container.GridLayout
        GridLayout20                    matlab.ui.container.GridLayout
        StartImgFilenameLabel           matlab.ui.control.Label
        StartImageLabel                 matlab.ui.control.Label
        GridLayoutStartImage            matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout19                    matlab.ui.container.GridLayout
        PreviewFilenameLabel            matlab.ui.control.Label
        ImagePreviewLabel               matlab.ui.control.Label
        GridLayoutImagePreview          matlab.ui.container.GridLayout
        UIAxesImagePreview              matlab.ui.control.UIAxes
        GridLayout9                     matlab.ui.container.GridLayout
        Panel_4                         matlab.ui.container.Panel
        GridLayout16_3                  matlab.ui.container.GridLayout
        GridLayout18                    matlab.ui.container.GridLayout
        QuickSelectSlider               matlab.ui.control.Slider
        QuickSelectSliderLabel          matlab.ui.control.Label
        PreviewModeDropDown             matlab.ui.control.DropDown
        PreviewModeDropDownLabel        matlab.ui.control.Label
        ImageSelectDropDown             matlab.ui.control.DropDown
        ImageSelectDropDownLabel        matlab.ui.control.Label
        PreviewSettingsLabel            matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        GridLayout16_2                  matlab.ui.container.GridLayout
        GridLayout22                    matlab.ui.container.GridLayout
        ImageSetNameLabel               matlab.ui.control.Label
        ImageSetNameTagLabel            matlab.ui.control.Label
        DatasetInformationLabel         matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout7_4                   matlab.ui.container.GridLayout
        ImageSizeLabel                  matlab.ui.control.Label
        ProcessedResolutionLabel        matlab.ui.control.Label
        GridLayout7_3                   matlab.ui.container.GridLayout
        MonthLabel                      matlab.ui.control.Label
        AverageIntervalLabel            matlab.ui.control.Label
        GridLayout7_2                   matlab.ui.container.GridLayout
        YearsLabel                      matlab.ui.control.Label
        TimespanCoverageLabel           matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        ImagesLabel                     matlab.ui.control.Label
        ImagesLoadedLabel               matlab.ui.control.Label
        SidebySideTab                   matlab.ui.container.Tab
        SideBySide_GridLayout1          matlab.ui.container.GridLayout
        SideBySide_GridLayout2          matlab.ui.container.GridLayout
        SideBySideBottomBar             matlab.ui.container.Panel
        SideBySideBottomLayout          matlab.ui.container.GridLayout
        SideBySide_InfoTextArea         matlab.ui.control.TextArea
        SideBySide_Panel_Control        matlab.ui.container.Panel
        SideBySideControlLayout         matlab.ui.container.GridLayout
        SideBySideControlTitle          matlab.ui.control.Label
        GridLayout25_7                  matlab.ui.container.GridLayout
        SideBySideControlDivider1       matlab.ui.container.Panel
        GridLayout24_3                  matlab.ui.container.GridLayout
        SideBySide_Img1DropDown         matlab.ui.control.DropDown
        SideBySide_Img2DropDown         matlab.ui.control.DropDown
        DropDown_2Label_4               matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        GridLayout25_8                  matlab.ui.container.GridLayout
        SideBySideControlDivider2       matlab.ui.container.Panel
        SideBySideNavLayout             matlab.ui.container.GridLayout
        SideBySide_ZoomButton           matlab.ui.control.Button
        SideBySide_PanButton            matlab.ui.control.Button
        SideBySide_ResetButton          matlab.ui.control.Button
        GridLayout25_9                  matlab.ui.container.GridLayout
        SideBySideControlDivider3       matlab.ui.container.Panel
        GridLayout27_3                  matlab.ui.container.GridLayout
        SideBySide_SyncButton           matlab.ui.control.StateButton
        SideBySide_SaveButton           matlab.ui.control.Button
        SideBySide_Panel_Main           matlab.ui.container.Panel
        SideBySideMainLayout            matlab.ui.container.GridLayout
        SideBySideDisplayLayout         matlab.ui.container.GridLayout
        GridLayout34_2                  matlab.ui.container.GridLayout
        SideBySideRightTitle            matlab.ui.control.Label
        SideBySide_RightAxes            matlab.ui.control.UIAxes
        GridLayout34                    matlab.ui.container.GridLayout
        SideBySideLeftTitle             matlab.ui.control.Label
        SideBySide_LeftAxes             matlab.ui.control.UIAxes
        SideBySideDivider               matlab.ui.container.Panel
        SideBySideTitleBar              matlab.ui.container.Panel
        SideBySideTitleLayout           matlab.ui.container.GridLayout
        SideBySideMainTitle             matlab.ui.control.Label
        SideBySide_StatusLabel          matlab.ui.control.Label
        TimelapseTab                    matlab.ui.container.Tab
        Timelapse_GridLayout1           matlab.ui.container.GridLayout
        GridLayout8_8                   matlab.ui.container.GridLayout
        TimelapseBottomLayout           matlab.ui.container.GridLayout
        Timelapse_InfoTextArea          matlab.ui.control.TextArea
        Timelapse_Panel_Control         matlab.ui.container.Panel
        TimelapseControlLayout          matlab.ui.container.GridLayout
        Timelapse_FrameSlider           matlab.ui.control.Slider
        GridLayout25_5                  matlab.ui.container.GridLayout
        Panel_62                        matlab.ui.container.Panel
        TimelapseNavLayout              matlab.ui.container.GridLayout
        Timelapse_PrevButton            matlab.ui.control.Button
        Timelapse_NextButton            matlab.ui.control.Button
        TimelapsePlayLayout             matlab.ui.container.GridLayout
        Timelapse_PlayButton            matlab.ui.control.Button
        Timelapse_PauseButton           matlab.ui.control.Button
        Timelapse_StopButton            matlab.ui.control.Button
        Timelapse_SpeedSlider           matlab.ui.control.Slider
        GridLayout35                    matlab.ui.container.GridLayout
        Timelapse_SpeedLabel            matlab.ui.control.Label
        Timelapse_LoopCheckbox          matlab.ui.control.CheckBox
        Timelapse_SaveGIFButton         matlab.ui.control.Button
        GridLayout25_10                 matlab.ui.container.GridLayout
        Panel_77                        matlab.ui.container.Panel
        GridLayout25_11                 matlab.ui.container.GridLayout
        TimelapseDivider4               matlab.ui.container.Panel
        TimelapseControlTitle           matlab.ui.control.Label
        GridLayout25_4                  matlab.ui.container.GridLayout
        TimelapseDivider1               matlab.ui.container.Panel
        Timelapse_Panel_Main            matlab.ui.container.Panel
        TimelapseMainLayout             matlab.ui.container.GridLayout
        Timelapse_FrameLabel            matlab.ui.control.Label
        Timelapse_MainAxes              matlab.ui.control.UIAxes
        TimelapseTitleBar               matlab.ui.container.Panel
        TimelapseTitleLayout            matlab.ui.container.GridLayout
        TimelapseMainTitle              matlab.ui.control.Label
        Timelapse_StatusLabel           matlab.ui.control.Label
        SliderViewTab                   matlab.ui.container.Tab
        GridLayout4_2                   matlab.ui.container.GridLayout
        GridLayout8_2                   matlab.ui.container.GridLayout
        Panel_40                        matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        GridLayout27                    matlab.ui.container.GridLayout
        SliderInstructionCard           matlab.ui.control.Label
        Slider_SaveGIFButton            matlab.ui.control.Button
        Slider_AutoScanButton           matlab.ui.control.Button
        SliderPositionCardLayout        matlab.ui.container.GridLayout
        Slider_ResetRightButton         matlab.ui.control.Button
        Slider_ResetCenterButton        matlab.ui.control.Button
        Slider_ResetLeftButton          matlab.ui.control.Button
        GridLayout25_3                  matlab.ui.container.GridLayout
        Panel_43                        matlab.ui.container.Panel
        GridLayout25_2                  matlab.ui.container.GridLayout
        Panel_42                        matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        Panel_41                        matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        Slider_Img2DropDown             matlab.ui.control.DropDown
        DropDown_2Label_2               matlab.ui.control.Label
        Slider_Img1DropDown             matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        SliderControlsLabel             matlab.ui.control.Label
        Panel_39                        matlab.ui.container.Panel
        SliderMainLayout                matlab.ui.container.GridLayout
        Slider_StatusLabel              matlab.ui.control.Label
        Slider_MainAxes                 matlab.ui.control.UIAxes
        SliderTitleBar                  matlab.ui.container.Panel
        SliderTitleLayout               matlab.ui.container.GridLayout
        SliderMainTitle                 matlab.ui.control.Label
        ClickanddragtocontrolcurtainpositionLabel  matlab.ui.control.Label
        DifferenceHighlightTab          matlab.ui.container.Tab
        GridLayout4_3                   matlab.ui.container.GridLayout
        GridLayout10_8                  matlab.ui.container.GridLayout
        Panel_48                        matlab.ui.container.Panel
        GridLayout16_9                  matlab.ui.container.GridLayout
        GridLayout32                    matlab.ui.container.GridLayout
        DiffHighlight_ResetButton       matlab.ui.control.Button
        SaveAnalysisResultsButton       matlab.ui.control.Button
        OperationToolsLabel             matlab.ui.control.Label
        Panel_47                        matlab.ui.container.Panel
        GridLayout16_8                  matlab.ui.container.GridLayout
        GridLayout20_4                  matlab.ui.container.GridLayout
        DiffHighlight_RightImage_Label  matlab.ui.control.Label
        SecondImageLabel                matlab.ui.control.Label
        DiffHighlight_RightImage_Grid   matlab.ui.container.GridLayout
        Panel_46                        matlab.ui.container.Panel
        GridLayout16_7                  matlab.ui.container.GridLayout
        GridLayout20_3                  matlab.ui.container.GridLayout
        DiffHighlight_LeftImage_Label   matlab.ui.control.Label
        FirstImageLabel                 matlab.ui.control.Label
        DiffHighlight_LeftImage_Grid    matlab.ui.container.GridLayout
        GridLayout4_8                   matlab.ui.container.GridLayout
        Panel_45                        matlab.ui.container.Panel
        GridLayout28                    matlab.ui.container.GridLayout
        DiffDetailButtonLayout          matlab.ui.container.GridLayout
        DiffHighlight_IncreaseOnlyButton  matlab.ui.control.Button
        DiffHighlight_DecreaseOnlyButton  matlab.ui.control.Button
        DiffHighlight_HighlightToggle   matlab.ui.control.StateButton
        analysisFuncLabel               matlab.ui.control.Label
        DiffHighlight_ThresholdLabel    matlab.ui.control.Label
        DiffHighlight_ThresholdSlider   matlab.ui.control.Slider
        thresholdLabel                  matlab.ui.control.Label
        GridLayout29                    matlab.ui.container.GridLayout
        DiffHighlight_Img1DropDown      matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        DiffHighlight_Img2DropDown      matlab.ui.control.DropDown
        Label_5                         matlab.ui.control.Label
        imgSelectionLabel               matlab.ui.control.Label
        DiffHighlight_StatusLabel       matlab.ui.control.Label
        Panel_44                        matlab.ui.container.Panel
        DiffHighlightMainLayout         matlab.ui.container.GridLayout
        DiffHighlight_Analysis_Axes     matlab.ui.control.UIAxes
        HeatmapTab                      matlab.ui.container.Tab
        GridLayout4_11                  matlab.ui.container.GridLayout
        GridLayout10_10                 matlab.ui.container.GridLayout
        Panel_58                        matlab.ui.container.Panel
        GridLayout16_15                 matlab.ui.container.GridLayout
        GridLayout32_3                  matlab.ui.container.GridLayout
        Heatmap_ResetButton             matlab.ui.control.Button
        Heatmap_SaveButton              matlab.ui.control.Button
        OperationToolsLabel_3           matlab.ui.control.Label
        Panel_57                        matlab.ui.container.Panel
        GridLayout16_14                 matlab.ui.container.GridLayout
        GridLayout20_8                  matlab.ui.container.GridLayout
        SuperimposeHeatmapSettingsLabel  matlab.ui.control.Label
        Heatmap_RightImage_Grid         matlab.ui.container.GridLayout
        Heatmap_ComprehensiveToggle     matlab.ui.control.StateButton
        Panel_56                        matlab.ui.container.Panel
        GridLayout16_13                 matlab.ui.container.GridLayout
        GridLayout20_7                  matlab.ui.container.GridLayout
        IndicatorInformationLabel       matlab.ui.control.Label
        HeatmapIndicatorLayout          matlab.ui.container.GridLayout
        Heatmap_IncreaseLamp            matlab.ui.control.Label
        Heatmap_DecreaseLamp            matlab.ui.control.Label
        GridLayout4_12                  matlab.ui.container.GridLayout
        Panel_55                        matlab.ui.container.Panel
        GridLayout28_3                  matlab.ui.container.GridLayout
        HeatmapDetailButtonLayout       matlab.ui.container.GridLayout
        Heatmap_IncreaseOnlyButton      matlab.ui.control.Button
        Heatmap_DecreaseOnlyButton      matlab.ui.control.Button
        Heatmap_HeatmapToggle           matlab.ui.control.StateButton
        analysisFuncLabel_3             matlab.ui.control.Label
        Heatmap_ThresholdLabel          matlab.ui.control.Label
        Heatmap_ThresholdSlider         matlab.ui.control.Slider
        thresholdLabel_3                matlab.ui.control.Label
        GridLayout29_3                  matlab.ui.container.GridLayout
        Heatmap_Img1DropDown            matlab.ui.control.DropDown
        Label_9                         matlab.ui.control.Label
        Heatmap_Img2DropDown            matlab.ui.control.DropDown
        Label_8                         matlab.ui.control.Label
        imgSelectionLabel_3             matlab.ui.control.Label
        Heatmap_StatusLabel             matlab.ui.control.Label
        Panel_54                        matlab.ui.container.Panel
        DiffHighlightMainLayout_3       matlab.ui.container.GridLayout
        Heatmap_Analysis_Axes           matlab.ui.control.UIAxes
        FlickerViewTab                  matlab.ui.container.Tab
        GridLayout4_15                  matlab.ui.container.GridLayout
        GridLayout10_11                 matlab.ui.container.GridLayout
        GridLayout16_19                 matlab.ui.container.GridLayout
        SideBySide_InfoTextArea_2       matlab.ui.control.TextArea
        AnalysisInformationLabel        matlab.ui.control.Label
        Panel_73                        matlab.ui.container.Panel
        GridLayout16_18                 matlab.ui.container.GridLayout
        GridLayout32_4                  matlab.ui.container.GridLayout
        Flicker_ResetButton             matlab.ui.control.Button
        Flicker_SaveButton              matlab.ui.control.Button
        toolsLabel                      matlab.ui.control.Label
        GridLayout4_16                  matlab.ui.container.GridLayout
        Panel_70                        matlab.ui.container.Panel
        FlickerControlLayout            matlab.ui.container.GridLayout
        Flicker_StatusLabel             matlab.ui.control.Label
        FlickerImgSelectionLabel        matlab.ui.control.Label
        GridLayout29_4                  matlab.ui.container.GridLayout
        Flicker_Img1DropDown            matlab.ui.control.DropDown
        Label_13                        matlab.ui.control.Label
        Flicker_Img2DropDown            matlab.ui.control.DropDown
        Label_14                        matlab.ui.control.Label
        speedLabel                      matlab.ui.control.Label
        Flicker_SpeedSlider             matlab.ui.control.Slider
        Flicker_SpeedLabel              matlab.ui.control.Label
        Flicker_FlickerToggle           matlab.ui.control.StateButton
        modeLabel                       matlab.ui.control.Label
        Flicker_ModeDropDown            matlab.ui.control.DropDown
        Label_12                        matlab.ui.control.Label
        Panel_69                        matlab.ui.container.Panel
        FlickerMainLayout               matlab.ui.container.GridLayout
        Flicker_Display_Axes            matlab.ui.control.UIAxes
        ReportTab                       matlab.ui.container.Tab
        GridLayout4_7                   matlab.ui.container.GridLayout
        GridLayout10_7                  matlab.ui.container.GridLayout
        Panel_38                        matlab.ui.container.Panel
        Panel_37                        matlab.ui.container.Panel
        GridLayout8_7                   matlab.ui.container.GridLayout
        Panel_35                        matlab.ui.container.Panel
        GridLayout9_7                   matlab.ui.container.GridLayout
        Panel_36                        matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        EarthViewerGUIGroup25Label      matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        GridLayout13                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        OverviewTab                     matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        GridLayout36                    matlab.ui.container.GridLayout
        UsethecontrolsonthelefttoexploreandcomparethedataLabel_2  matlab.ui.control.Label
        UsethecontrolsonthelefttoexploreandcomparethedataLabel  matlab.ui.control.Label
        ClickavisualizationtabatthetopLabel  matlab.ui.control.Label
        Step3VisualizeLabel             matlab.ui.control.Label
        GridLayout21                    matlab.ui.container.GridLayout
        EndImageDropDown                matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        StartImageDropDown              matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        Step2RangeSelectionLabel        matlab.ui.control.Label
        Step1DataImportLabel            matlab.ui.control.Label
        SelectInputFilesButton          matlab.ui.control.Button
        FilesTab_2                      matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        FileTree                        matlab.ui.container.CheckBoxTree
        NoFilesLoadedNode               matlab.ui.container.TreeNode
        LoadedFilesLabel                matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        GridLayout14_2                  matlab.ui.container.GridLayout
        AlternativeProcessingCheckbox   matlab.ui.control.CheckBox
        ImageProcessingLabel            matlab.ui.control.Label
        FlickerViewLabel                matlab.ui.control.Label
        FlickerThickBoarder             matlab.ui.control.CheckBox
        SettingsLabel_2                 matlab.ui.control.Label
        SettingsLabel                   matlab.ui.control.Label
        GridLayout12                    matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        StartImage matlab.ui.control.Image
        EndImage matlab.ui.control.Image

        DiffHighlightStartImage matlab.ui.control.Image
        DiffHighlightEndImage matlab.ui.control.Image
        
        DashboardDropdownItems string

        PreviewImageData table
        StartImageData table
        EndImageData table
        
        ImageDatasets cell

        AlignedImages cell

        CroppedImages cell

        CurrentLoadedTable table

        DashboardPreviewMode string

        %%%%%%%%% Copy Pasta visualization tabs code %%%%%%%%%

        % Slider View Properties
        Slider_SelectedImg1 = 1
        Slider_SelectedImg2 = 2
        Slider_IsDragging = false
        Slider_CurrentCurtainPos = 0
        Slider_IsScanning = false
        Slider_ImageWidth = 0
        Slider_ImageHeight = 0
        Slider_CurrentImg1 = []
        Slider_CurrentImg2 = []
        Slider_MainImageHandle = []

        % Difference Highlight Properties
        DiffHighlight_SelectedImg1 = 1
        DiffHighlight_SelectedImg2 = 2
        DiffHighlight_CurrentThreshold = 0.5
        DiffHighlight_CurrentHighlightImg = []
        DiffHighlight_IsHighlightOn = false
        DiffHighlight_IsIncreaseOnlyOn = false
        DiffHighlight_IsDecreaseOnlyOn = false

        % Heatmap Properties
        Heatmap_SelectedImg1 = 1
        Heatmap_SelectedImg2 = 2
        Heatmap_CurrentThreshold = 0.5
        Heatmap_CurrentHeatmapImg = []
        Heatmap_IsHeatmapOn = false
        Heatmap_IsHighlightOn = false
        Heatmap_IsIncreaseOnlyOn = false
        Heatmap_IsDecreaseOnlyOn = false
        Heatmap_IsComprehensiveMode = false
        
        % Flicker Properties
        Flicker_SelectedImg1 = 1
        Flicker_SelectedImg2 = 2
        Flicker_CurrentSpeed = 0.5
        Flicker_CurrentMode = "Original"
        Flicker_IsFlickering = false
        Flicker_Thick_Boarder = true
        Flicker_State = false
        Flicker_Timer = []
        
        % Timelapse Properties
        Timelapse_CurrentFrame = 1
        Timelapse_TotalFrames = 0
        Timelapse_IsPlaying = false
        Timelapse_PlaybackTimer = []
        Timelapse_PlaybackSpeed = 2  % frames per second
        Timelapse_LoopEnabled = true
        Timelapse_ImageHandle = []
        
        % Side-by-Side Properties
        SideBySide_SelectedImg1 = 1
        SideBySide_SelectedImg2 = 2
        SideBySide_CurrentImg1 = []
        SideBySide_CurrentImg2 = []
        SideBySide_SyncEnabled = true
        SideBySide_UpdatingView = false
        SideBySide_ZoomObj = []
        SideBySide_PanObj = []
        SideBySide_ScaleX = 1
        SideBySide_ScaleY = 1
        SideBySide_LeftImageHandle = []
        SideBySide_RightImageHandle = []
        
    end
    
    methods (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Backend: Helper and data processing functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Load Image: modified from loadImageFolder.m
        function imgTable = loadImageFolder(app)

            % Load and sort all image files from selected folder into a table.
            startingFolder = fullfile(pwd, 'data');
            folder = uigetdir(startingFolder);

            if folder == 0, imgTable = []; return; end
        
            % Supported image extensions
            exts = {'*.jpg', '*.jpeg', '*.png', '*.bmp'};
            files = [];
            for i = 1:length(exts)
                files = [files; dir(fullfile(folder, exts{i}))]; %#ok<AGROW>
            end
        
            if numel(files) < 2
                errordlg('At least two images are required.', 'Error');
                imgTable = [];
                return;
            end
        
            % Extract timestamps (year and optional month) from filenames
            names = {files.name};
            timestamps = zeros(1, numel(names));
            for i = 1:numel(names)
                tokens = regexp(names{i}, '(\d{4})[_\-\.]?(\d{2})', 'tokens', 'once');
                if ~isempty(tokens)
                    year = str2double(tokens{1});
                    month = str2double(tokens{2});
                    timestamps(i) = year * 100 + month;
                else
                    yearOnly = regexp(names{i}, '\d{4}', 'match', 'once');
                    if ~isempty(yearOnly)
                        year = str2double(yearOnly);
                        timestamps(i) = year * 100;
                    else
                        timestamps(i) = Inf; % invalid or no date info
                    end
                end
            end
        
            % Sort by timestamp (ascending)
            [~, sortedIdx] = sort(timestamps);
            sortedNames = names(sortedIdx);
            sortedFiles = files(sortedIdx);
            sortedTimestamps = timestamps(sortedIdx);
            
            % Add folder name as dataset name to distinguish between datasets
            [~, folderName, ~] = fileparts(folder);
            fullNames = string(folderName) + " " + string(sortedNames(:));

            % Build output table
            paths = fullfile(folder, {sortedFiles.name}');
            imgTable = table(fullNames, paths(:), sortedTimestamps(:),'VariableNames', {'Name', 'Path', 'Timestamp'});
            app.CurrentLoadedTable = imgTable;
            app.CurrentLoadedTable.Properties.UserData = folderName;
        end
        
        % Align and crop image series: modified from martinTest.m
        function preproccessImages(app)
            numImages = height(app.CurrentLoadedTable);
            imgs = cell(1, numImages);
            for i = 1:numImages
                imgs{i} = im2double(imread(app.CurrentLoadedTable.Path{i}));
            end

            % ⇨ Bilder alignen
            if app.AlternativeProcessingCheckbox.Value
                [alignedImgs, tforms] = preprocessImageSequenceOld(imgs);
                app.AlignedImages = alignedImgs;
            else
                [alignedImgs, tforms] = preprocessImageSequence(imgs);
                app.AlignedImages = alignedImgs;
            end

            % ⇨ Gemeinsamer Ausschnitt (automatisch zugeschnitten)
            croppedImgs = cropToCommonRegion(alignedImgs);
            app.CroppedImages = croppedImgs;
            
            % Update Stat Cards
            [imgHeight, imgWidth, ~] = size(croppedImgs{1});
            app.ImageSizeLabel.Text = string(imgWidth) + " x " + string(imgHeight);
        end
        
        % Controller for which image is selected on dashboard
        function img = imageLoadController(app, imgInfo)
            if ~isempty(imgInfo)
                if ~isempty(app.AlignedImages) && isequal(app.DashboardPreviewMode, "🔹 Aligned")
                    idx = find(strcmp(app.CurrentLoadedTable.Name, imgInfo.Name{1}), 1);
                    if ~isempty(idx) 
                        img = app.AlignedImages{idx};
                    end
                elseif ~isempty(app.CroppedImages) && isequal(app.DashboardPreviewMode, "✂️ Cropped")
                    idx = find(strcmp(app.CurrentLoadedTable.Name, imgInfo.Name{1}), 1);
                    if ~isempty(idx)
                        img = app.CroppedImages{idx};
                    end
                else
                    img = im2double(imread(imgInfo.Path{1}));
                end 
            end
        end
        
        % Dataset manager
        function addDataset(app,dataset)
            % Extract dataset name from folder
            datasetName = app.getDatasetName(dataset);
            
            % Check for duplicate datasets by name
            if isempty(app.ImageDatasets)
                existingNames = {};
            else
                existingNames = cellfun(@(d) d.Name, app.ImageDatasets, 'UniformOutput', false);
            end
        
            if any(strcmp(existingNames, datasetName))
                warning("Dataset '%s' already loaded.", datasetName);
            else
                % Store dataset as struct inside ImageDatasets
                newDataset = struct('Name', datasetName, 'Table', dataset);
                app.ImageDatasets{end+1} = newDataset; 
            end
        end
        
        % Get dataset name from path 
        function datasetName = getDatasetName(~,dataset)
            firstImagePath = dataset.Path{1};
            [folderPath, ~, ~] = fileparts(firstImagePath);
            [~, datasetName] = fileparts(folderPath);
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Main GUI update function
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Display dataset on GUI
        function displayDataset(app, dataset)
            firstImage = dataset(1, :);
            lastImage = dataset(end, :);

            % PreviewImage UI Load (Interactive)
            app.PreviewImageData = firstImage;
            app.updatePreview();
            
            % StartImage UI Load
            app.StartImageData = firstImage;
            app.updateStart();

            % EndImage UI Load
            app.EndImageData = lastImage;
            app.updateEnd();   

            % Update Dropdown
            app.DashboardDropdownItems = dataset.Name;
            app.updateDropdown();
            
            % Update Sidebar
            app.updateStats();
            app.updateSlider();
            
            % Update Dashboard Elements
            app.updateFileTree();
            app.updateSidebarDropdown();
            app.updateImageSetInformation();
            
            % Update 
            app.updateSideBySideInterface();

            app.updateTimelapseInterface();

            app.updateSliderDropdowns();

            app.updateDiffHighlightDropdowns();

            app.updateHeatmapDropdowns();

            app.updateFlickerDropdowns();
        end

        % Refresh processed data after processing ends
        function updateDataset(app)
            app.updatePreview();
            app.updateSideBySideInterface();
            app.updateTimelapseInterface();
            app.updateSliderDropdowns();
            app.updateDifferenceHighlight();
            app.updateHeatmapDropdowns();
            app.updateHeatmap();
            app.updateFlickerDropdowns();
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 0. Side Bar Functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Sidebar dataset start and end select
        function updateDatasetFromSidebarSelection(app)
            % Selected start and end names
            startName = app.StartImageDropDown.Value;
            endName = app.EndImageDropDown.Value;
        
            % Locate dataset
            datasetName = app.getDatasetName(app.CurrentLoadedTable);
            dataset = app.ImageDatasets{strcmp(datasetName, cellfun(@(d) d.Name, app.ImageDatasets, 'UniformOutput', false))};
            allNames = dataset.Table.Name;
        
            % Find indices
            idxStart = find(strcmp(allNames, startName), 1);
            idxEnd = find(strcmp(allNames, endName), 1);
            if isempty(idxStart) || isempty(idxEnd) || idxEnd < idxStart, return; end
        
            % Update only if start or end differs
            currentNames = app.CurrentLoadedTable.Name;
            if ~strcmp(currentNames{1}, startName) || ~strcmp(currentNames{end}, endName)
                app.CurrentLoadedTable = dataset.Table(idxStart:idxEnd, :);
                app.CurrentLoadedTable.Properties.UserData = datasetName;
                app.StatusReadyLabel.Text = sprintf("Status: Please wait - loading and preprocessing %s...", app.CurrentLoadedTable.Properties.UserData);drawnow;
                app.displayDataset(app.CurrentLoadedTable);
                app.preproccessImages();
                app.updateDataset();
                app.StatusReadyLabel.Text = sprintf("Status: Image Set %s loaded. Ready", app.CurrentLoadedTable.Properties.UserData);
            end
        end
        
        % Sidebar file tree
        function updateFileTree(app)
            % Clear existing nodes
            app.FileTree.CheckedNodes = [];
            delete(app.FileTree.Children);
        
            % Initialize
            checkedNodes = app.FileTree.empty;
            nodeMap = containers.Map;
        
            % Build tree and node map
            for i = 1:numel(app.ImageDatasets)
                dataset = app.ImageDatasets{i};
                parentNode = uitreenode(app.FileTree, 'Text', dataset.Name);
                for j = 1:height(dataset.Table)
                    imageName = dataset.Table.Name{j};
                    childNode = uitreenode(parentNode, 'Text', imageName);
                    key = [dataset.Name '|' imageName];
                    nodeMap(key) = childNode;
                end
            end
        
            % Find checked nodes
            for i = 1:height(app.CurrentLoadedTable)
                imageName = app.CurrentLoadedTable.Name{i};
                for d = 1:numel(app.ImageDatasets)
                    dataset = app.ImageDatasets{d};
                    if any(strcmp(dataset.Table.Name, imageName))
                        key = [dataset.Name '|' imageName];
                        if nodeMap.isKey(key)
                            checkedNodes(end+1) = nodeMap(key); %#ok<AGROW>
                        end
                        break;
                    end
                end
            end
        
            % Apply checked nodes
            app.FileTree.CheckedNodes = checkedNodes;
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 0: Side bar functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Dashboard Preview Dropdown
        function updateDropdown(app)
            if isvalid(app.ImageSelectDropDown)
                app.ImageSelectDropDown.Items = cellstr(app.DashboardDropdownItems);
                if ~isempty(app.DashboardDropdownItems)
                    app.ImageSelectDropDown.Value = app.DashboardDropdownItems(1);
                end
            end
        end

        % Sidebar Image Selection Dropdown
        function updateSidebarDropdown(app)
            if isvalid(app.StartImageDropDown)
                % Exclude last item for start dropdown
                startItems = app.DashboardDropdownItems(1:end-1);
                app.StartImageDropDown.Items = cellstr(startItems);
                if ~isempty(startItems)
                    app.StartImageDropDown.Value = startItems(1);
                end
            end
            if isvalid(app.EndImageDropDown)
                % Exclude first item for end dropdown
                endItems = app.DashboardDropdownItems(2:end);
                app.EndImageDropDown.Items = cellstr(endItems);
                if ~isempty(endItems)
                    app.EndImageDropDown.Value = endItems(end);
                end
            end
        end

        % Dashboard Preview Image
        function updatePreview(app)
            axis(app.UIAxesImagePreview, 'image');
            app.UIAxesImagePreview.Toolbar.Visible = 'on';
            previewImage = app.imageLoadController(app.PreviewImageData);
            imshow(previewImage, 'Parent', app.UIAxesImagePreview);
            app.PreviewFilenameLabel.Text = app.PreviewImageData.Name{1};
        end

        % Dashboard Start Image
        function updateStart(app)
            if isempty(app.StartImage) || ~isvalid(app.StartImage)
                app.StartImage = uiimage(app.GridLayoutStartImage); 
                app.StartImage.ScaleMethod = 'fit';
            end
            app.StartImage.ImageSource = imread(app.StartImageData.Path{1});
            app.StartImgFilenameLabel.Text = app.StartImageData.Name{1};
        end
        
        % Dashboard End Image
        function updateEnd(app)
            if isempty(app.EndImage) || ~isvalid(app.EndImage)
                app.EndImage = uiimage(app.GridLayoutEndImage); 
                app.EndImage.ScaleMethod = 'fit';
            end
            app.EndImage.ImageSource = imread(app.EndImageData.Path{1});
            app.EndImgFilenameLabel.Text = app.EndImageData.Name{1};
        end

        % Dashboard Stat Cards
        function updateStats(app)
            numImages = height(app.CurrentLoadedTable);
            app.ImagesLabel.Text = string(numImages) + " Images";
            
            timestamps = app.CurrentLoadedTable.Timestamp;
            % Calculate diffference
            startTimestamp = min(timestamps);
            endTimestamp = max(timestamps);
            
            startYear = floor(startTimestamp / 100);
            startMonth = mod(startTimestamp, 100);
            endYear = floor(endTimestamp / 100);
            endMonth = mod(endTimestamp, 100);
            
            yearsDiff = endYear - startYear;
            monthsDiff = endMonth - startMonth;
            
            totalMonths = yearsDiff * 12 + monthsDiff;
            totalYears = totalMonths / 12;

            app.YearsLabel.Text = string(totalYears) + " Years";
            app.MonthLabel.Text = string(totalMonths / numImages) + " Month";
        end
        
        % Dashboard QuickSelect Slider
        function updateSlider(app)
            numImages = height(app.CurrentLoadedTable);
            app.QuickSelectSlider.Limits = [1, numImages];
            app.QuickSelectSlider.MajorTicks = 1:numImages; 
            app.QuickSelectSlider.MajorTickLabels = string(1:numImages);
            app.QuickSelectSlider.Value = 1;
        end

        % Dashboard Image Set Information Board
        function updateImageSetInformation(app)
            app.ImageSetNameLabel.Text = app.CurrentLoadedTable.Properties.UserData;
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 1. Side By Side Functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Update Side-by-Side interface when images are loaded
        function updateSideBySideInterface(app)
            try
                if isempty(app.CroppedImages) || isempty(app.CurrentLoadedTable)
                    app.SideBySide_Img1DropDown.Items = {'Please load images first'};
                    app.SideBySide_Img2DropDown.Items = {'Please load images first'};
                    app.SideBySide_Img1DropDown.Value = app.SideBySide_Img1DropDown.Items{1};
                    app.SideBySide_Img2DropDown.Value = app.SideBySide_Img2DropDown.Items{1};
                    app.SideBySide_SelectedImg1 = 1;
                    app.SideBySide_SelectedImg2 = 2;
                    
                    % Clear displays
                    cla(app.SideBySide_LeftAxes);
                    cla(app.SideBySide_RightAxes);
                    title(app.SideBySide_LeftAxes, 'Load images to compare', 'FontSize', 12, 'Color', [0.5 0.5 0.5]);
                    title(app.SideBySide_RightAxes, 'Load images to compare', 'FontSize', 12, 'Color', [0.5 0.5 0.5]);
                    
                    app.SideBySide_StatusLabel.Text = '❌ No images loaded';
                    app.updateSideBySideInfoText();
                    return;
                end
                
                % Update dropdown items with image names
                app.SideBySide_Img1DropDown.Items = app.CurrentLoadedTable.Name;
                app.SideBySide_Img2DropDown.Items = app.CurrentLoadedTable.Name;
                
                % Set default values
                if height(app.CurrentLoadedTable) >= 1
                    app.SideBySide_Img1DropDown.Value = app.CurrentLoadedTable.Name{1};
                    app.SideBySide_SelectedImg1 = 1;
                else
                    app.SideBySide_SelectedImg1 = 1;
                end
                
                if height(app.CurrentLoadedTable) >= 2
                    app.SideBySide_Img2DropDown.Value = app.CurrentLoadedTable.Name{height(app.CurrentLoadedTable)};
                    app.SideBySide_SelectedImg2 = height(app.CurrentLoadedTable);
                else
                    app.SideBySide_SelectedImg2 = min(1, height(app.CurrentLoadedTable));
                end
                
                % Update display
                app.updateSideBySideDisplay();
                
                % Update status
                syncStatus = 'enabled';
                if ~app.SideBySide_SyncEnabled
                    syncStatus = 'disabled';
                end
                app.SideBySide_StatusLabel.Text = sprintf('🔄 Synchronization %s - %d images loaded', syncStatus, height(app.CurrentLoadedTable));
                
            catch ME
                app.SideBySide_StatusLabel.Text = ['❌ Error: ' ME.message];
                fprintf('SideBySide interface update error: %s\n', ME.message);
            end
        end
        
        % Update Side-by-Side display with selected images
        function updateSideBySideDisplay(app)
            try
                % Clear axes first
                cla(app.SideBySide_LeftAxes);
                cla(app.SideBySide_RightAxes);
                
                if isempty(app.CroppedImages) || isempty(app.CurrentLoadedTable)
                    title(app.SideBySide_LeftAxes, 'Load images to compare', 'FontSize', 12, 'Color', [0.5 0.5 0.5]);
                    title(app.SideBySide_RightAxes, 'Load images to compare', 'FontSize', 12, 'Color', [0.5 0.5 0.5]);
                    app.updateSideBySideInfoText();
                    return;
                end
                
                % Display left image
                if app.SideBySide_SelectedImg1 > 0 && app.SideBySide_SelectedImg1 <= length(app.CroppedImages)
                    app.SideBySide_CurrentImg1 = app.CroppedImages{app.SideBySide_SelectedImg1};
                    app.SideBySide_LeftImageHandle = imshow(app.SideBySide_CurrentImg1, 'Parent', app.SideBySide_LeftAxes);
                    axis(app.SideBySide_LeftAxes, 'image');
                    app.SideBySide_LeftAxes.XLimMode = 'auto';
                    app.SideBySide_LeftAxes.YLimMode = 'auto';
                    title(app.SideBySide_LeftAxes, app.CurrentLoadedTable.Name{app.SideBySide_SelectedImg1}, 'FontSize', 12, 'Interpreter', 'none', 'FontWeight', 'bold');
                end
                
                % Display right image
                if app.SideBySide_SelectedImg2 > 0 && app.SideBySide_SelectedImg2 <= length(app.CroppedImages)
                    app.SideBySide_CurrentImg2 = app.CroppedImages{app.SideBySide_SelectedImg2};
                    app.SideBySide_RightImageHandle = imshow(app.SideBySide_CurrentImg2, 'Parent', app.SideBySide_RightAxes);
                    axis(app.SideBySide_RightAxes, 'image');
                    app.SideBySide_RightAxes.XLimMode = 'auto';
                    app.SideBySide_RightAxes.YLimMode = 'auto';
                    title(app.SideBySide_RightAxes, app.CurrentLoadedTable.Name{app.SideBySide_SelectedImg2}, 'FontSize', 12, 'Interpreter', 'none', 'FontWeight', 'bold');
                end
                
                % Calculate scale factors for synchronization
                if ~isempty(app.SideBySide_CurrentImg1) && ~isempty(app.SideBySide_CurrentImg2)
                    app.SideBySide_ScaleX = size(app.SideBySide_CurrentImg2, 2) / size(app.SideBySide_CurrentImg1, 2);
                    app.SideBySide_ScaleY = size(app.SideBySide_CurrentImg2, 1) / size(app.SideBySide_CurrentImg1, 1);
                end
                
                % Setup synchronized view
                app.setupSynchronizedView();
                
                % Update info text
                app.updateSideBySideInfoText();
                
            catch ME
                app.SideBySide_StatusLabel.Text = ['❌ Display error: ' ME.message];
                title(app.SideBySide_LeftAxes, 'Display Error', 'FontSize', 12, 'Color', [0.8, 0, 0]);
                title(app.SideBySide_RightAxes, 'Display Error', 'FontSize', 12, 'Color', [0.8, 0, 0]);
            end
        end
        
        % Setup synchronized zoom and pan for Side-by-Side view
        function setupSynchronizedView(app)
            try
                % Initialize zoom and pan objects if needed
                if isempty(app.SideBySide_ZoomObj)
                    app.SideBySide_ZoomObj = zoom(app.UIFigure);
                    app.SideBySide_PanObj = pan(app.UIFigure);
                end
                
                % Ensure tools are off initially
                zoom(app.UIFigure, 'off');
                pan(app.UIFigure, 'off');
                
                % Add listeners for left axes changes
                if ~isempty(app.SideBySide_LeftAxes) && isvalid(app.SideBySide_LeftAxes)
                    try
                        addlistener(app.SideBySide_LeftAxes, 'XLim', 'PostSet', @(src,evt) app.syncFromLeft('XLim'));
                        addlistener(app.SideBySide_LeftAxes, 'YLim', 'PostSet', @(src,evt) app.syncFromLeft('YLim'));
                    catch
                        % Listeners may already exist
                    end
                end
                
                % Add listeners for right axes changes
                if ~isempty(app.SideBySide_RightAxes) && isvalid(app.SideBySide_RightAxes)
                    try
                        addlistener(app.SideBySide_RightAxes, 'XLim', 'PostSet', @(src,evt) app.syncFromRight('XLim'));
                        addlistener(app.SideBySide_RightAxes, 'YLim', 'PostSet', @(src,evt) app.syncFromRight('YLim'));
                    catch
                        % Listeners may already exist
                    end
                end
                
            catch ME
                fprintf('Setup synchronized view error: %s\n', ME.message);
            end
        end
        
        % Synchronize right axes when left axes changes
        function syncFromLeft(app, property)
            if app.SideBySide_SyncEnabled && ~app.SideBySide_UpdatingView
                app.SideBySide_UpdatingView = true;
                
                try
                    if strcmp(property, 'XLim')
                        left_xlim = get(app.SideBySide_LeftAxes, 'XLim');
                        right_xlim = left_xlim * app.SideBySide_ScaleX;
                        set(app.SideBySide_RightAxes, 'XLim', right_xlim);
                    elseif strcmp(property, 'YLim')
                        left_ylim = get(app.SideBySide_LeftAxes, 'YLim');
                        right_ylim = left_ylim * app.SideBySide_ScaleY;
                        set(app.SideBySide_RightAxes, 'YLim', right_ylim);
                    end
                catch
                    % Sync may fail during updates
                end
                
                app.SideBySide_UpdatingView = false;
            end
        end
        
        % Synchronize left axes when right axes changes
        function syncFromRight(app, property)
            if app.SideBySide_SyncEnabled && ~app.SideBySide_UpdatingView
                app.SideBySide_UpdatingView = true;
                
                try
                    if strcmp(property, 'XLim')
                        right_xlim = get(app.SideBySide_RightAxes, 'XLim');
                        left_xlim = right_xlim / app.SideBySide_ScaleX;
                        set(app.SideBySide_LeftAxes, 'XLim', left_xlim);
                    elseif strcmp(property, 'YLim')
                        right_ylim = get(app.SideBySide_RightAxes, 'YLim');
                        left_ylim = right_ylim / app.SideBySide_ScaleY;
                        set(app.SideBySide_LeftAxes, 'YLim', left_ylim);
                    end
                catch
                    % Sync may fail during updates
                end
                
                app.SideBySide_UpdatingView = false;
            end
        end
        
        % Update info text area
        function updateSideBySideInfoText(app)
            try
                if isempty(app.SideBySide_CurrentImg1) || isempty(app.SideBySide_CurrentImg2)
                    info_text = {
                        '🔀 Synchronized Side-by-Side Comparison - Load images from Dashboard to begin', 
                        '', 
                        '• Select different images for left and right panels', 
                        '• Use Zoom tool to magnify regions of interest (both sides sync)', 
                        '• Use Pan tool to navigate around images (both sides sync)', 
                        '• Toggle synchronization ON/OFF as needed', 
                        '• Export combined view as JPG image'
                    };
                else
                    sync_status = 'ON';
                    if ~app.SideBySide_SyncEnabled
                        sync_status = 'OFF';
                    end
                    
                    left_size = sprintf('%dx%d', size(app.SideBySide_CurrentImg1, 2), size(app.SideBySide_CurrentImg1, 1));
                    right_size = sprintf('%dx%d', size(app.SideBySide_CurrentImg2, 2), size(app.SideBySide_CurrentImg2, 1));
                    scale_info = sprintf('Scale: %.3fx, %.3fy', app.SideBySide_ScaleX, app.SideBySide_ScaleY);
                    
                    info_text = {
                        sprintf('📊 Left: %s  |  Right: %s', left_size, right_size),
                        sprintf('🔄 %s  |  ⚖️ %s', ['Sync: ' sync_status], scale_info),
                        '',
                        '✨ Images preprocessed (registered & cropped for comparison)',
                        '🔍 Use Zoom/Pan tools for detailed inspection',
                        '💾 Save current comparison as JPG file'
                    };
                end
                
                app.SideBySide_InfoTextArea.Value = info_text;
                
            catch ME
                fprintf('Update SideBySide info text error: %s\n', ME.message);
            end
        end
        
        % Reset Side-by-Side view to default state
        function resetSideBySideView(app)
            try
                app.SideBySide_UpdatingView = true;
                
                % Turn off zoom and pan modes
                zoom(app.UIFigure, 'off');
                pan(app.UIFigure, 'off');
                
                % Reset both axes to default view
                if ~isempty(app.SideBySide_LeftAxes) && isvalid(app.SideBySide_LeftAxes)
                    axis(app.SideBySide_LeftAxes, 'image');
                end
                
                if ~isempty(app.SideBySide_RightAxes) && isvalid(app.SideBySide_RightAxes)
                    axis(app.SideBySide_RightAxes, 'image');
                end
                
                app.SideBySide_UpdatingView = false;
                
                % Refresh display
                app.updateSideBySideDisplay();
                
            catch ME
                app.SideBySide_StatusLabel.Text = ['❌ Reset error: ' ME.message];
                app.SideBySide_UpdatingView = false;
            end
        end
        
        % Save Side-by-Side comparison as JPG
        function saveSideBySideComparison(app)
            try
                if isempty(app.SideBySide_CurrentImg1) || isempty(app.SideBySide_CurrentImg2)
                    uialert(app.UIFigure, 'Please load and select images first.', 'No Images', 'Icon', 'warning');
                    return;
                end
                
                % Create combined image
                img1_display = im2uint8(app.SideBySide_CurrentImg1);
                img2_display = im2uint8(app.SideBySide_CurrentImg2);
                combined_img = [img1_display, img2_display];
                
                % File save dialog
                [filename, pathname] = uiputfile('*.jpg', 'Save Side-by-Side Comparison');
                if filename ~= 0
                    % Ensure .jpg extension
                    [~, name, ~] = fileparts(filename);
                    filename = [name, '.jpg'];
                    
                    % Save image
                    imwrite(combined_img, fullfile(pathname, filename), 'jpg');
                    uialert(app.UIFigure, ['Image saved successfully as: ' filename], 'Success', 'Icon', 'success');
                end
                
            catch ME
                uialert(app.UIFigure, ['Save failed: ' ME.message], 'Error', 'Icon', 'error');
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 2. Timelapse Functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Update timelapse dropdowns when images are loaded
        function updateTimelapseInterface(app)
            try
                if ~isempty(app.CroppedImages) && ~isempty(app.CurrentLoadedTable)
                    % Update total frames
                    app.Timelapse_TotalFrames = length(app.CroppedImages);
                    app.Timelapse_CurrentFrame = min(app.Timelapse_CurrentFrame, app.Timelapse_TotalFrames);
                    
                    % Update slider limits
                    if app.Timelapse_TotalFrames > 1
                        set(app.Timelapse_FrameSlider, 'Limits', [1, app.Timelapse_TotalFrames]);
                        set(app.Timelapse_FrameSlider, 'Value', app.Timelapse_CurrentFrame);
                        
                        % Calculate slider step
                        step = 1 / (app.Timelapse_TotalFrames - 1);
                        set(app.Timelapse_FrameSlider, 'MajorTicks', 1:app.Timelapse_TotalFrames);
                        set(app.Timelapse_FrameSlider, 'MinorTicks', []);
                    else
                        set(app.Timelapse_FrameSlider, 'Limits', [1, 2]);
                        set(app.Timelapse_FrameSlider, 'Value', 1);
                    end
                    
                    % Update display
                    app.updateTimelapseDisplay();
                    
                    % Update status
                    app.Timelapse_StatusLabel.Text = sprintf('📽️ %d frames loaded - Ready for playback', app.Timelapse_TotalFrames);
                    app.Timelapse_StatusLabel.FontColor = [0.9 0.9 0.9];
                    
                else
                    % No images loaded
                    app.Timelapse_TotalFrames = 0;
                    app.Timelapse_CurrentFrame = 1;
                    set(app.Timelapse_FrameSlider, 'Limits', [1, 2]);
                    set(app.Timelapse_FrameSlider, 'Value', 1);
                    
                    % Clear display
                    cla(app.Timelapse_MainAxes);
                    title(app.Timelapse_MainAxes, 'Time-Lapse Display - Load images to begin', 'FontSize', 14, 'Color', [0.5 0.5 0.5]);
                    
                    app.Timelapse_StatusLabel.Text = '📽️ Ready for animation playback - Load images first';
                    app.Timelapse_StatusLabel.FontColor = [0.9 0.9 0.9];
                end
                
                % Update frame label
                app.updateTimelapseFrameLabel();
                
            catch ME
                app.Timelapse_StatusLabel.Text = ['Status: Update error - ' ME.message];
                app.Timelapse_StatusLabel.FontColor = [0.8 0, 0];
                fprintf('Timelapse interface update error: %s\n', ME.message);
            end
        end
        
        % Update timelapse display with current frame
        function updateTimelapseDisplay(app)
            try
                if ~isempty(app.CroppedImages) && app.Timelapse_CurrentFrame <= length(app.CroppedImages)
                    % Display current frame
                    currentImg = app.CroppedImages{app.Timelapse_CurrentFrame};
                    
                    % Clear axes and display image
                    cla(app.Timelapse_MainAxes);
                    app.Timelapse_ImageHandle = imshow(currentImg, 'Parent', app.Timelapse_MainAxes);
                    axis(app.Timelapse_MainAxes, 'image');
                    app.Timelapse_MainAxes.XLimMode = 'auto';
                    app.Timelapse_MainAxes.YLimMode = 'auto';
                    
                    % Update title with frame info
                    if ~isempty(app.CurrentLoadedTable) && app.Timelapse_CurrentFrame <= height(app.CurrentLoadedTable)
                        imageName = strrep(app.CurrentLoadedTable.Name{app.Timelapse_CurrentFrame}, '_', '\_');
                        titleStr = sprintf('Frame %d/%d: %s', app.Timelapse_CurrentFrame, app.Timelapse_TotalFrames, imageName);
                    else
                        titleStr = sprintf('Frame %d/%d', app.Timelapse_CurrentFrame, app.Timelapse_TotalFrames);
                    end
                    title(app.Timelapse_MainAxes, titleStr, 'FontSize', 12, 'Color', [0.2 0.2 0.2]);
                    
                    % Update slider position
                    set(app.Timelapse_FrameSlider, 'Value', app.Timelapse_CurrentFrame);
                    
                    % Update frame label
                    app.updateTimelapseFrameLabel();
                    
                else
                    % No valid frame to display
                    cla(app.Timelapse_MainAxes);
                    title(app.Timelapse_MainAxes, 'No images available', 'FontSize', 14, 'Color', [0.8 0.3 0.3]);
                end
                
            catch ME
                app.Timelapse_StatusLabel.Text = ['Status: Display error - ' ME.message];
                app.Timelapse_StatusLabel.FontColor = [0.8 0, 0];
                fprintf('Timelapse display error: %s\n', ME.message);
            end
        end
        
        % Update frame label with current frame info
        function updateTimelapseFrameLabel(app)
            if app.Timelapse_TotalFrames > 0
                app.Timelapse_FrameLabel.Text = sprintf('📊 Frame: %d / %d', app.Timelapse_CurrentFrame, app.Timelapse_TotalFrames);
                app.Timelapse_FrameLabel.FontColor = [0, 0.5, 0];
            else
                app.Timelapse_FrameLabel.Text = '📊 Frame: 1 / 0 - Load images from Dashboard';
                app.Timelapse_FrameLabel.FontColor = [0.5, 0.5, 0.5];
            end
        end
        
        % Navigate to specific frame
        function gotoTimelapseFrame(app, frameNum)
            if frameNum >= 1 && frameNum <= app.Timelapse_TotalFrames
                app.Timelapse_CurrentFrame = frameNum;
                app.updateTimelapseDisplay();
            end
        end
        
        % Start timelapse playback
        function startTimelapsePlayback(app)
            try
                if app.Timelapse_TotalFrames <= 1
                    app.Timelapse_StatusLabel.Text = '⚠️ Need at least 2 frames for playback';
                    app.Timelapse_StatusLabel.FontColor = [0.8, 0.5, 0];
                    return;
                end
                
                % Stop any existing timer
                app.stopTimelapsePlayback();
                
                % Update playback state
                app.Timelapse_IsPlaying = true;
                app.updateTimelapseButtons();
                
                % Create and start timer
                interval = 1 / app.Timelapse_PlaybackSpeed;
                app.Timelapse_PlaybackTimer = timer('Period', interval, ...
                    'ExecutionMode', 'fixedRate', ...
                    'TimerFcn', @(~,~) app.timelapseTimerCallback());
                start(app.Timelapse_PlaybackTimer);
                
                app.Timelapse_StatusLabel.Text = sprintf('▶️ Playing at %.1f fps', app.Timelapse_PlaybackSpeed);
                app.Timelapse_StatusLabel.FontColor = [0, 0.6, 0];
                
            catch ME
                app.Timelapse_StatusLabel.Text = ['Status: Playback error - ' ME.message];
                app.Timelapse_StatusLabel.FontColor = [0.8, 0, 0];
                fprintf('Timelapse playback error: %s\n', ME.message);
            end
        end
        
        % Stop timelapse playback
        function stopTimelapsePlayback(app)
            try
                if isvalid(app.Timelapse_PlaybackTimer)
                    stop(app.Timelapse_PlaybackTimer);
                    delete(app.Timelapse_PlaybackTimer);
                    app.Timelapse_PlaybackTimer = [];
                end
                
                app.Timelapse_IsPlaying = false;
                app.updateTimelapseButtons();
                
                if app.Timelapse_TotalFrames > 0
                    app.Timelapse_StatusLabel.Text = '⏸️ Playback stopped';
                    app.Timelapse_StatusLabel.FontColor = [0.6, 0.6, 0.6];
                else
                    app.Timelapse_StatusLabel.Text = '📽️ Ready for animation playback - Load images first';
                    app.Timelapse_StatusLabel.FontColor = [0.9, 0.9, 0.9];
                end
                
            catch ME
                fprintf('Timelapse stop error: %s\n', ME.message);
            end
        end
        
        % Timer callback for playback
        function timelapseTimerCallback(app)
            try
                % Check if still valid
                if ~isvalid(app.UIFigure) || ~app.Timelapse_IsPlaying
                    app.stopTimelapsePlayback();
                    return;
                end
                
                % Move to next frame
                nextFrame = app.Timelapse_CurrentFrame + 1;
                
                if nextFrame > app.Timelapse_TotalFrames
                    if app.Timelapse_LoopEnabled
                        nextFrame = 1; % Loop back to start
                    else
                        app.stopTimelapsePlayback(); % Stop at end
                        return;
                    end
                end
                
                app.gotoTimelapseFrame(nextFrame);
                
            catch
                app.stopTimelapsePlayback();
            end
        end
        
        % Update button states based on playback status
        function updateTimelapseButtons(app)
            if app.Timelapse_IsPlaying
                set(app.Timelapse_PlayButton, 'Enable', 'off');
                set(app.Timelapse_PauseButton, 'Enable', 'on');
                set(app.Timelapse_StopButton, 'Enable', 'on');
            else
                set(app.Timelapse_PlayButton, 'Enable', 'on');
                set(app.Timelapse_PauseButton, 'Enable', 'off');
                set(app.Timelapse_StopButton, 'Enable', 'off');
            end
        end
        
        % Reset to first frame
        function resetTimelapseToStart(app)
            app.stopTimelapsePlayback();
            app.gotoTimelapseFrame(1);
            app.Timelapse_StatusLabel.Text = '⏮️ Reset to first frame';
            app.Timelapse_StatusLabel.FontColor = [0, 0.5, 0.8];
        end
        
        % Update playback speed
        function updateTimelapseSpeed(app, newSpeed)
            app.Timelapse_PlaybackSpeed = newSpeed;
            app.Timelapse_SpeedLabel.Text = sprintf('🏃 Speed: %.1f fps', newSpeed);
            
            % If playing, restart timer with new interval
            if app.Timelapse_IsPlaying
                app.startTimelapsePlayback();
            end
        end
        
        % Export timelapse as GIF
        function exportTimelapseGIF(app)
            try
                if app.Timelapse_TotalFrames < 2
                    uialert(app.UIFigure, 'Need at least 2 frames to export GIF.', 'Export Error');
                    return;
                end
                
                [filename, pathname] = uiputfile({'*.gif', 'GIF Animation (*.gif)'}, 'Export Timelapse as GIF');
                if filename == 0
                    return;
                end
                
                [~, name, ~] = fileparts(filename);
                filename = [name, '.gif'];
                fullPath = fullfile(pathname, filename);
                
                % Create progress dialog
                progressDlg = uiprogressdlg(app.UIFigure, 'Title', 'Exporting GIF', ...
                    'Message', 'Processing frames...', 'Cancelable', 'on');
                
                delayTime = 1 / app.Timelapse_PlaybackSpeed;
                
                for i = 1:app.Timelapse_TotalFrames
                    % Check if user cancelled
                    if progressDlg.CancelRequested
                        close(progressDlg);
                        return;
                    end
                    
                    % Update progress
                    progressDlg.Value = i / app.Timelapse_TotalFrames;
                    progressDlg.Message = sprintf('Processing frame %d/%d...', i, app.Timelapse_TotalFrames);
                    
                    % Get current frame
                    img = app.CroppedImages{i};
                    
                    % Convert to indexed color
                    [indexedImg, colormap] = rgb2ind(img, 256);
                    
                    if i == 1
                        % Create GIF file
                        imwrite(indexedImg, colormap, fullPath, 'gif', ...
                               'LoopCount', inf, 'DelayTime', delayTime);
                    else
                        % Append frame
                        imwrite(indexedImg, colormap, fullPath, 'gif', ...
                               'WriteMode', 'append', 'DelayTime', delayTime);
                    end
                end
                
                close(progressDlg);
                
                % Show success message
                uialert(app.UIFigure, sprintf('GIF exported successfully!\n\nFile: %s', fullPath), ...
                       'Export Complete', 'Icon', 'success');
                
                app.Timelapse_StatusLabel.Text = '💾 GIF exported successfully';
                app.Timelapse_StatusLabel.FontColor = [0, 0.6, 0];
                
            catch ME
                if exist('progressDlg', 'var') && isvalid(progressDlg)
                    close(progressDlg);
                end
                uialert(app.UIFigure, ['Export failed: ' ME.message], 'Export Error');
                app.Timelapse_StatusLabel.Text = ['Status: Export error - ' ME.message];
                app.Timelapse_StatusLabel.FontColor = [0.8, 0, 0];
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 3. Slider View Functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Update Slider dropdowns based on loaded images
        function updateSliderDropdowns(app)
            try
                if isempty(app.CroppedImages) || isempty(app.CurrentLoadedTable)
                    app.Slider_Img1DropDown.Items = {'🔄 Load images from Dashboard first...'};
                    app.Slider_Img2DropDown.Items = {'🔄 Load images from Dashboard first...'};
                    app.Slider_Img1DropDown.Value = app.Slider_Img1DropDown.Items{1};
                    app.Slider_Img2DropDown.Value = app.Slider_Img2DropDown.Items{1};
                    app.Slider_SelectedImg1 = 0;
                    app.Slider_SelectedImg2 = 0;
                    
                    % Clear display and show instruction
                    app.updateSliderDisplay();
                    app.Slider_StatusLabel.Text = '📂 Please load images from Dashboard tab first';
                    app.Slider_StatusLabel.FontColor = [0.7, 0.4, 0.1];
                    return;
                end
                
                % Use unified image management - get from globally loaded images
                imageNames = app.CurrentLoadedTable.Name;
                app.Slider_Img1DropDown.Items = imageNames;

                app.Slider_Img2DropDown.Items = imageNames;
                
                % Intelligent default selection
                numImages = height(app.CurrentLoadedTable);
                if numImages >= 1
                    app.Slider_Img1DropDown.Value = imageNames{1};
                    app.Slider_SelectedImg1 = 1;
                    
                    % Select the last image as default for comparison if available
                    if numImages >= 2
                        app.Slider_Img2DropDown.Value = imageNames{end};
                        app.Slider_SelectedImg2 = numImages;
                    else
                        app.Slider_Img2DropDown.Value = imageNames{1};
                        app.Slider_SelectedImg2 = 1;
                    end
                else
                    app.Slider_SelectedImg1 = 0;
                    app.Slider_SelectedImg2 = 0;
                end
                
                % Initialize image data using shared processed images
                app.initializeSliderImages();
                
                % Update display with loaded images
                app.updateSliderDisplay();
                app.Slider_StatusLabel.Text = sprintf('✅ %d images ready • Select for comparison', numImages);
                app.Slider_StatusLabel.FontColor = [0, 0.6, 0.2];
                
            catch ME
                app.Slider_StatusLabel.Text = ['❌ Error: ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
                fprintf('Slider dropdown update error: %s\n', ME.message);
            end
        end
        
        % Initialize slider images and dimensions
        function initializeSliderImages(app)
            try
                if app.Slider_SelectedImg1 > 0 && app.Slider_SelectedImg1 <= length(app.CroppedImages)
                    app.Slider_CurrentImg1 = app.CroppedImages{app.Slider_SelectedImg1};
                else
                    app.Slider_CurrentImg1 = [];
                end
                
                if app.Slider_SelectedImg2 > 0 && app.Slider_SelectedImg2 <= length(app.CroppedImages)
                    app.Slider_CurrentImg2 = app.CroppedImages{app.Slider_SelectedImg2};
                else
                    app.Slider_CurrentImg2 = [];
                end
                
                % Normalize image sizes if both images exist
                if ~isempty(app.Slider_CurrentImg1) && ~isempty(app.Slider_CurrentImg2)
                    [app.Slider_CurrentImg1, app.Slider_CurrentImg2] = app.normalizeSliderImageSizes(app.Slider_CurrentImg1, app.Slider_CurrentImg2);
                    app.Slider_ImageWidth = size(app.Slider_CurrentImg1, 2);
                    app.Slider_ImageHeight = size(app.Slider_CurrentImg1, 1);
                    app.Slider_CurrentCurtainPos = round(app.Slider_ImageWidth / 2);
                end
                
            catch ME
                app.Slider_StatusLabel.Text = ['Status: Image initialization error - ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
            end
        end
        
        % Normalize image sizes to ensure consistency
        function [img1_norm, img2_norm] = normalizeSliderImageSizes(~, img1, img2)
            [h1, w1, ~] = size(img1);
            [h2, w2, ~] = size(img2);
            
            if h1 ~= h2 || w1 ~= w2
                % Resize to smaller dimensions to ensure full visibility
                target_h = min(h1, h2);
                target_w = min(w1, w2);
                img1_norm = imresize(img1, [target_h, target_w]);
                img2_norm = imresize(img2, [target_h, target_w]);
            else
                img1_norm = img1;
                img2_norm = img2;
            end
        end
        
        % Update slider display
        function updateSliderDisplay(app)
            try
                % Clear axes first
                cla(app.Slider_MainAxes);
                
                if isempty(app.Slider_CurrentImg1) || isempty(app.Slider_CurrentImg2)
                    title(app.Slider_MainAxes, '🎚️ Curtain Slider Display', 'FontSize', 14, 'FontWeight', 'bold');
                    return;
                end
                
                % Create curtain effect image
                curtainImg = app.createCurtainImage(app.Slider_CurrentImg1, app.Slider_CurrentImg2, app.Slider_CurrentCurtainPos);
                
                % Display the curtain image
                app.Slider_MainImageHandle = imshow(curtainImg, 'Parent', app.Slider_MainAxes);
                axis(app.Slider_MainAxes, 'image');
                app.Slider_MainAxes.XLimMode = 'auto';
                app.Slider_MainAxes.YLimMode = 'auto';
                title(app.Slider_MainAxes, '🎚️ Curtain Slider Display (Click and drag to control)', 'FontSize', 14, 'FontWeight', 'bold');
                
                % Setup mouse interaction
                app.setupSliderMouseInteraction();
                
            catch ME
                app.Slider_StatusLabel.Text = ['Status: Display error - ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
                title(app.Slider_MainAxes, 'Display Error', 'FontSize', 12, 'Color', [0.8, 0, 0]);
            end
        end
        
        % Create curtain effect image (core algorithm from curtainSliderDisplay.m)
        function curtain_img = createCurtainImage(app, img1, img2, curtain_pos)
            [h, w, c] = size(img1);
            curtain_img = zeros(h, w, c, 'like', img1);
            
            % Ensure curtain position is within valid range
            curtain_pos = max(1, min(w, round(curtain_pos)));
            
            % Left side shows img1, right side shows img2
            if curtain_pos > 1
                curtain_img(:, 1:curtain_pos-1, :) = img1(:, 1:curtain_pos-1, :);
            end
            if curtain_pos <= w
                curtain_img(:, curtain_pos:end, :) = img2(:, curtain_pos:end, :);
            end
            
            % Add red curtain dividing line
            if curtain_pos > 1 && curtain_pos <= w
                curtain_img(:, curtain_pos, 1) = 255;
                curtain_img(:, curtain_pos, 2) = 0;
                curtain_img(:, curtain_pos, 3) = 0;
            end
        end
        
        % Setup mouse interaction for slider
        function setupSliderMouseInteraction(app)
            % Set up mouse event handlers for interactive curtain control
            set(app.UIFigure, 'WindowButtonDownFcn', @(src, evt) app.sliderMouseDown(src, evt));
            set(app.UIFigure, 'WindowButtonMotionFcn', @(src, evt) app.sliderMouseMove(src, evt));
            set(app.UIFigure, 'WindowButtonUpFcn', @(src, evt) app.sliderMouseUp(src, evt));
        end
        
        % Mouse down event handler
        function sliderMouseDown(app, src, evt)
            try
                % Check if click is within slider axes
                currentPoint = get(app.Slider_MainAxes, 'CurrentPoint');
                if app.isPointInSliderAxes(currentPoint)
                    app.Slider_IsDragging = true;
                    curtain_pos = app.convertAxesToImageCoords(currentPoint);
                    app.updateSliderCurtainPosition(curtain_pos);
                end
            catch
                % Ignore errors during mouse interaction
            end
        end
        
        % Mouse move event handler
        function sliderMouseMove(app, src, evt)
            try
                if app.Slider_IsDragging
                    currentPoint = get(app.Slider_MainAxes, 'CurrentPoint');
                    if app.isPointInSliderAxes(currentPoint)
                        curtain_pos = app.convertAxesToImageCoords(currentPoint);
                        app.updateSliderCurtainPosition(curtain_pos);
                    end
                end
            catch
                % Ignore errors during mouse interaction
            end
        end
        
        % Mouse up event handler
        function sliderMouseUp(app, src, evt)
            app.Slider_IsDragging = false;
        end
        
        % Check if point is within slider axes
        function is_valid = isPointInSliderAxes(app, current_point)
            try
                x = current_point(1, 1);
                y = current_point(1, 2);
                
                % Get axes limits
                xlim = get(app.Slider_MainAxes, 'XLim');
                ylim = get(app.Slider_MainAxes, 'YLim');
                
                is_valid = (x >= xlim(1) && x <= xlim(2) && y >= ylim(1) && y <= ylim(2));
            catch
                is_valid = false;
            end
        end
        
        % Convert axes coordinates to image pixel coordinates
        function pixel_x = convertAxesToImageCoords(app, current_point)
            try
                x = current_point(1, 1);
                
                % Get current axes limits
                xlim = get(app.Slider_MainAxes, 'XLim');
                
                % Convert to pixel coordinate (1-based indexing)
                pixel_x = round(((x - xlim(1)) / (xlim(2) - xlim(1))) * app.Slider_ImageWidth + 0.5);
                
                % Clamp to valid range
                pixel_x = max(1, min(app.Slider_ImageWidth, pixel_x));
            catch
                pixel_x = round(app.Slider_ImageWidth / 2);
            end
        end
        
        % Update curtain position
        function updateSliderCurtainPosition(app, curtain_pos)
            try
                app.Slider_CurrentCurtainPos = curtain_pos;
                
                if ~isempty(app.Slider_CurrentImg1) && ~isempty(app.Slider_CurrentImg2)
                    % Create new curtain effect image
                    new_img = app.createCurtainImage(app.Slider_CurrentImg1, app.Slider_CurrentImg2, curtain_pos);
                    
                    % Update image display
                    if ishandle(app.Slider_MainImageHandle)
                        set(app.Slider_MainImageHandle, 'CData', new_img);
                    end
                    
                    drawnow;
                end
            catch ME
                app.Slider_StatusLabel.Text = ['Status: Update error - ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
            end
        end
        
        % Reset curtain position with modern feedback
        function resetSliderPosition(app, pos_type)
            try
                if isempty(app.Slider_CurrentImg1) || isempty(app.Slider_CurrentImg2)
                    app.Slider_StatusLabel.Text = '⚠️ Please select two images first';
                    app.Slider_StatusLabel.FontColor = [0.8, 0.5, 0];
                    return;
                end
                
                switch pos_type
                    case 'left'
                        new_pos = 1; % Fully show left image
                        statusMsg = 'Showing left image';
                    case 'center'
                        new_pos = round(app.Slider_ImageWidth/2); % Center curtain
                        statusMsg = 'Centered view';
                    case 'right'
                        new_pos = app.Slider_ImageWidth; % Fully show right image
                        statusMsg = 'Showing right image';
                    otherwise
                        new_pos = round(app.Slider_ImageWidth/2);
                        statusMsg = 'Centered view';
                end
                
                app.updateSliderCurtainPosition(new_pos);
                app.Slider_StatusLabel.Text = ['🎚️ ' statusMsg];
                app.Slider_StatusLabel.FontColor = [0.2, 0.6, 0.2];
            catch ME
                app.Slider_StatusLabel.Text = ['❌ Reset error: ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
            end
        end
        
        % Auto scan animation
        function performSliderAutoScan(app)
            try
                if app.Slider_IsScanning
                    app.Slider_IsScanning = false;
                    app.Slider_AutoScanButton.Text = '🔄 Auto Scan';
                    app.Slider_StatusLabel.Text = 'Status: Auto scan stopped';
                    app.Slider_StatusLabel.FontColor = [0, 0.4, 0];
                    return;
                end
                
                app.Slider_IsScanning = true;
                app.Slider_AutoScanButton.Text = '⏹️ Stop Scan';
                app.Slider_StatusLabel.Text = 'Status: Auto scanning...';
                app.Slider_StatusLabel.FontColor = [0, 0.4, 0];
                
                step_size = max(1, round(app.Slider_ImageWidth/50));
                
                % Scan from left to right
                for pos = 1:step_size:app.Slider_ImageWidth
                    if ~app.Slider_IsScanning
                        break;
                    end
                    app.updateSliderCurtainPosition(pos);
                    pause(0.05);
                end
                
                % Scan from right to left
                for pos = app.Slider_ImageWidth:-step_size:1
                    if ~app.Slider_IsScanning
                        break;
                    end
                    app.updateSliderCurtainPosition(pos);
                    pause(0.05);
                end
                
                % Reset state
                app.Slider_IsScanning = false;
                app.Slider_AutoScanButton.Text = '🔄 Auto Scan';
                app.Slider_StatusLabel.Text = 'Status: Auto scan completed';
                app.Slider_StatusLabel.FontColor = [0, 0.4, 0];
                
            catch ME
                app.Slider_IsScanning = false;
                app.Slider_AutoScanButton.Text = '🔄 Auto Scan';
                app.Slider_StatusLabel.Text = ['Status: Scan error - ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
            end
        end
        
        % Reset slider interface
        function resetSliderInterface(app)
            try
                % Stop auto scan if running
                if app.Slider_IsScanning
                    app.performSliderAutoScan(); % This will stop the scan
                end
                
                % Reset image selections based on what's available
                if ~isempty(app.CroppedImages) && ~isempty(app.CurrentLoadedTable)
                    % Images are loaded - reset to first two images
                    app.Slider_SelectedImg1 = 1;
                    app.Slider_SelectedImg2 = min(2, height(app.CurrentLoadedTable));
                    
                    if height(app.CurrentLoadedTable) >= 1
                        app.Slider_Img1DropDown.Value = app.CurrentLoadedTable.Name{1};
                    end
                    if height(app.CurrentLoadedTable) >= 2
                        app.Slider_Img2DropDown.Value = app.CurrentLoadedTable.Name{2};
                    end
                    
                    % Reinitialize images and display
                    app.initializeSliderImages();
                    app.updateSliderDisplay();
                else
                    % No images loaded - reset to empty state
                    app.Slider_SelectedImg1 = 0;
                    app.Slider_SelectedImg2 = 0;
                    app.Slider_Img1DropDown.Items = {'Please load images first'};
                    app.Slider_Img2DropDown.Items = {'Please load images first'};
                    app.Slider_Img1DropDown.Value = app.Slider_Img1DropDown.Items{1};
                    app.Slider_Img2DropDown.Value = app.Slider_Img2DropDown.Items{1};
                    
                    % Clear display
                    cla(app.Slider_MainAxes);
                    title(app.Slider_MainAxes, '🎚️ Curtain Slider Display', 'FontSize', 14, 'FontWeight', 'bold');
                end
                
                % Reset cursor state
                app.Slider_IsDragging = false;
                
                % Set appropriate status message
                if ~isempty(app.CroppedImages) && ~isempty(app.CurrentLoadedTable)
                    app.Slider_StatusLabel.Text = 'Status: Interface reset - ready for comparison';
                    app.Slider_StatusLabel.FontColor = [0, 0.4, 0];
                else
                    app.Slider_StatusLabel.Text = 'Status: Interface reset - please load images first';
                    app.Slider_StatusLabel.FontColor = [0.5, 0.5, 0.5];
                end
                
            catch ME
                app.Slider_StatusLabel.Text = ['Status: Reset error - ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
                fprintf('Slider reset error: %s\n', ME.message);
            end
        end

        % Helper function to extract short image name for clean display
        function shortName = extractShortImageName(app, fullName)
            % Extract just the year or short identifier from full name
            if contains(fullName, '(')
                parts = strsplit(fullName, '(');
                shortName = strtrim(parts{1});
            else
                shortName = fullName;
            end
            
            % Limit length for clean display
            if length(shortName) > 15
                shortName = [shortName(1:12) '...'];
            end
        end
        
        % Create auto scan GIF
        function createSliderAutoScanGIF(app, filename)
            try
                delay_time = 0.1;  % GIF frame delay
                step_size = max(1, round(app.Slider_ImageWidth/30));
                colormap_created = false;
                gif_colormap = [];
                
                % Left to right scan frames
                for pos = 1:step_size:app.Slider_ImageWidth
                    app.updateSliderCurtainPosition(pos);
                    
                    % Get current image data
                    current_img = get(app.Slider_MainImageHandle, 'CData');
                    
                    if ~colormap_created
                        % Create first frame and establish color mapping
                        [indexed_img, gif_colormap] = rgb2ind(current_img, 256, 'dither');
                        colormap_created = true;
                        imwrite(indexed_img, gif_colormap, filename, 'gif', ...
                               'Loopcount', inf, 'DelayTime', delay_time);
                    else
                        % Add subsequent frames
                        indexed_img = rgb2ind(current_img, gif_colormap, 'dither');
                        imwrite(indexed_img, gif_colormap, filename, 'gif', ...
                               'WriteMode', 'append', 'DelayTime', delay_time);
                    end
                    pause(0.02);
                end
                
                % Right to left scan frames
                for pos = app.Slider_ImageWidth:-step_size:1
                    app.updateSliderCurtainPosition(pos);
                    
                    current_img = get(app.Slider_MainImageHandle, 'CData');
                    indexed_img = rgb2ind(current_img, gif_colormap, 'dither');
                    imwrite(indexed_img, gif_colormap, filename, 'gif', ...
                           'WriteMode', 'append', 'DelayTime', delay_time);
                    pause(0.02);
                end
                
            catch ME
                error('GIF creation failed: %s', ME.message);
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 4. Difference Highlight Functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Update Difference Highlight UI elements
        function updateDiffHighlightDropdowns(app)
            if isempty(app.CurrentLoadedTable)
                return;
            end
            
            % Update dropdown items
            imageNames = app.CurrentLoadedTable.Name;
            app.DiffHighlight_Img1DropDown.Items = imageNames;
            app.DiffHighlight_Img2DropDown.Items = imageNames;
            
            % Set default selections
            if length(imageNames) >= 2
                app.DiffHighlight_Img1DropDown.Value = imageNames{1};
                app.DiffHighlight_Img2DropDown.Value = imageNames{length(imageNames)};
                app.DiffHighlight_SelectedImg1 = 1;
                app.DiffHighlight_SelectedImg2 = length(imageNames);
            end
        end
        
        function updateDifferenceHighlight(app)
            if ~isempty(app.CroppedImages)
                % Change view mode to difference highlight
                app.DiffHighlight_HighlightToggle.Value = true;
                app.DiffHighlight_IsHighlightOn = true;
                app.DiffHighlight_IsIncreaseOnlyOn = false;
                app.DiffHighlight_IsDecreaseOnlyOn = false;
                app.DiffHighlight_HighlightToggle.Text = '✨ Hide Highlight Differences';
                app.DiffHighlight_HighlightToggle.BackgroundColor = [0.8 0.2 0.2];
                app.showDiffHighlightHighlight();
                
                % Also update selected image preview
                idx1 = app.DiffHighlight_SelectedImg1;
                idx2 = app.DiffHighlight_SelectedImg2;
                
                if idx1 < 1 || idx2 < 1 || idx1 > numel(app.CroppedImages) || idx2 > numel(app.CroppedImages)
                    return;
                end
            
                % Create image components only once
                if isempty(app.DiffHighlightStartImage) || ~isvalid(app.DiffHighlightStartImage)
                    app.DiffHighlightStartImage = uiimage(app.DiffHighlight_LeftImage_Grid);
                    app.DiffHighlightStartImage.ScaleMethod = 'fit';
                end
                if isempty(app.DiffHighlightEndImage) || ~isvalid(app.DiffHighlightEndImage)
                    app.DiffHighlightEndImage = uiimage(app.DiffHighlight_RightImage_Grid);
                    app.DiffHighlightEndImage.ScaleMethod = 'fit';
                end
            
                % Update image content and labels
                app.DiffHighlightStartImage.ImageSource = app.CroppedImages{idx1};
                app.DiffHighlightEndImage.ImageSource = app.CroppedImages{idx2};
                app.DiffHighlight_LeftImage_Label.Text = app.CurrentLoadedTable.Name{idx1};
                app.DiffHighlight_RightImage_Label.Text = app.CurrentLoadedTable.Name{idx2};
            end
        end

        % Update analysis based on current mode
        function updateDiffHighlightAnalysis(app)
            if app.DiffHighlight_IsHighlightOn
                app.showDiffHighlightHighlight();
            elseif app.DiffHighlight_IsIncreaseOnlyOn
                app.showDiffHighlightIncreaseOnly();
            elseif app.DiffHighlight_IsDecreaseOnlyOn
                app.showDiffHighlightDecreaseOnly();
            end
        end
        
        % Show highlight analysis
        function showDiffHighlightHighlight(app)
            if isempty(app.CroppedImages) || app.DiffHighlight_SelectedImg1 == app.DiffHighlight_SelectedImg2
                uialert(app.UIFigure, 'Please load images and select two different images', 'Warning');
                app.DiffHighlight_HighlightToggle.Value = false;
                app.DiffHighlight_IsHighlightOn = false;
                return;
            end
            
            try
                app.updateDiffHighlightStatus('Calculating highlights...');
                
                img1 = app.CroppedImages{app.DiffHighlight_SelectedImg1};
                img2 = app.CroppedImages{app.DiffHighlight_SelectedImg2};
                
                gray1 = rgb2gray(img1);
                gray2 = rgb2gray(img2);
                diff = abs(gray2 - gray1);
                
                increase = gray2 > gray1;
                decrease = gray2 < gray1;
                significantChange = diff > app.DiffHighlight_CurrentThreshold;
                
                highlightImg = zeros(size(img1));
                highlightImg(:,:,1) = double(increase & significantChange);
                highlightImg(:,:,1) = highlightImg(:,:,1) + double(decrease & significantChange);
                highlightImg(:,:,2) = double(decrease & significantChange);
                
                app.DiffHighlight_CurrentHighlightImg = highlightImg;

                cla(app.DiffHighlight_Analysis_Axes);
                baseImg = 0.5 * gray1;
                baseImg = cat(3, baseImg, baseImg, baseImg);
                resultImg = baseImg + 0.8 * highlightImg;
                resultImg = min(resultImg, 1);
                imshow(resultImg, 'Parent', app.DiffHighlight_Analysis_Axes);
                axis(app.DiffHighlight_Analysis_Axes, 'image');
                app.DiffHighlight_Analysis_Axes.XLimMode = 'auto';
                app.DiffHighlight_Analysis_Axes.YLimMode = 'auto';
                title(app.DiffHighlight_Analysis_Axes, sprintf('Highlight (Red=Increase, Yellow=Decrease, Threshold: %.2f)', app.DiffHighlight_CurrentThreshold), 'FontSize', 11);
                
                app.updateDiffHighlightStatus('Highlight calculation completed!');
                
            catch ME
                app.updateDiffHighlightStatus('Highlight calculation failed');
                uialert(app.UIFigure, ['Highlight calculation failed: ' ME.message], 'Error');
            end
        end
        
        % Show increase only analysis
        function showDiffHighlightIncreaseOnly(app)
            if isempty(app.CroppedImages) || app.DiffHighlight_SelectedImg1 == app.DiffHighlight_SelectedImg2
                uialert(app.UIFigure, 'Please load images and select two different images', 'Warning');
                return;
            end
            
            try
                app.DiffHighlight_IsHighlightOn = false;
                app.DiffHighlight_IsIncreaseOnlyOn = true;
                app.DiffHighlight_IsDecreaseOnlyOn = false;
                
                app.DiffHighlight_HighlightToggle.Value = false;
                app.DiffHighlight_HighlightToggle.Text = '✨ Show Highlight Differences';
                app.DiffHighlight_HighlightToggle.BackgroundColor = [0.2157 0.4941 0.7216];
                
                img1 = app.CroppedImages{app.DiffHighlight_SelectedImg1};
                img2 = app.CroppedImages{app.DiffHighlight_SelectedImg2};
                
                gray1 = rgb2gray(img1);
                gray2 = rgb2gray(img2);
                diff = abs(gray2 - gray1);
                
                increase = gray2 > gray1;
                significantChange = diff > app.DiffHighlight_CurrentThreshold;
                
                highlightImg = zeros(size(img1));
                highlightImg(:,:,1) = double(increase & significantChange);
                
                axes(app.DiffHighlight_Analysis_Axes);
                cla(app.DiffHighlight_Analysis_Axes);
                baseImg = 0.5 * gray1;
                baseImg = cat(3, baseImg, baseImg, baseImg);
                resultImg = baseImg + 0.8 * highlightImg;
                resultImg = min(resultImg, 1);
                imshow(resultImg, 'Parent', app.DiffHighlight_Analysis_Axes);
                title(app.DiffHighlight_Analysis_Axes, sprintf('Increase Only (Red, Threshold: %.2f)', app.DiffHighlight_CurrentThreshold), 'FontSize', 11);
                
                app.updateDiffHighlightStatus('Increase only analysis completed!');
                
            catch ME
                uialert(app.UIFigure, ['Increase only analysis failed: ' ME.message], 'Error');
            end
        end
        

        % Show decrease only analysis
        function showDiffHighlightDecreaseOnly(app)
            if isempty(app.CroppedImages) || app.DiffHighlight_SelectedImg1 == app.DiffHighlight_SelectedImg2
                uialert(app.UIFigure, 'Please load images and select two different images', 'Warning');
                return;
            end
            
            try
                app.DiffHighlight_IsHighlightOn = false;
                app.DiffHighlight_IsIncreaseOnlyOn = false;
                app.DiffHighlight_IsDecreaseOnlyOn = true;
                
                app.DiffHighlight_HighlightToggle.Value = false;
                app.DiffHighlight_HighlightToggle.Text = '✨ Show Highlight Differences';
                app.DiffHighlight_HighlightToggle.BackgroundColor = [0.2157 0.4941 0.7216];
                
                img1 = app.CroppedImages{app.DiffHighlight_SelectedImg1};
                img2 = app.CroppedImages{app.DiffHighlight_SelectedImg2};
                
                gray1 = rgb2gray(img1);
                gray2 = rgb2gray(img2);
                diff = abs(gray2 - gray1);
                
                decrease = gray2 < gray1;
                significantChange = diff > app.DiffHighlight_CurrentThreshold;
              
                highlightImg = zeros(size(img1));
                highlightImg(:,:,1) = double(decrease & significantChange);
                highlightImg(:,:,2) = double(decrease & significantChange);
                
                cla(app.DiffHighlight_Analysis_Axes);
                baseImg = 0.5 * gray1;
                baseImg = cat(3, baseImg, baseImg, baseImg);
                resultImg = baseImg + 0.8 * highlightImg;
                resultImg = min(resultImg, 1);
                imshow(resultImg, 'Parent', app.DiffHighlight_Analysis_Axes);
                title(app.DiffHighlight_Analysis_Axes, sprintf('Decrease Only (Yellow, Threshold: %.2f)', app.DiffHighlight_CurrentThreshold), 'FontSize', 11);
                
                app.updateDiffHighlightStatus('Decrease only analysis completed!');
                
            catch ME
                uialert(app.UIFigure, ['Decrease only analysis failed: ' ME.message], 'Error');
            end
        end
        
        % Update status display
        function updateDiffHighlightStatus(app, message)
            if isvalid(app.DiffHighlight_StatusLabel)
                app.DiffHighlight_StatusLabel.Text = ['Status: ' message];
                drawnow;
            end
        end
        
        % Reset difference highlight display
        function resetDiffHighlightDisplay(app)
            try                
                % Clear all axes
                if isvalid(app.DiffHighlight_Analysis_Axes), cla(app.DiffHighlight_Analysis_Axes); end
                
                % Reset titles
                if isvalid(app.DiffHighlight_Analysis_Axes), title(app.DiffHighlight_Analysis_Axes, 'Difference Analysis Results', 'FontSize', 13, 'FontWeight', 'bold'); end
                
                % Reset variables
                app.DiffHighlight_CurrentHighlightImg = [];
                app.DiffHighlight_IsHighlightOn = false;
                app.DiffHighlight_IsIncreaseOnlyOn = false;
                app.DiffHighlight_IsDecreaseOnlyOn = false;
                
                % Reset UI controls
                if isvalid(app.DiffHighlight_HighlightToggle)
                    app.DiffHighlight_HighlightToggle.Value = false;
                    app.DiffHighlight_HighlightToggle.Text = '✨ Show Highlight Differences';
                    app.DiffHighlight_HighlightToggle.BackgroundColor = [0.2157 0.4941 0.7216];
                end
                
                app.updateDiffHighlightStatus('Display reset completed');
                
            catch ME
                fprintf('Reset error: %s\n', ME.message);
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 5. Heatmap Functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function updateHeatmap(app)
            if ~isempty(app.CroppedImages)
                % Change view mode to difference highlight
                app.Heatmap_HeatmapToggle.Value = true;
                app.Heatmap_IsHeatmapOn = true;
                app.Heatmap_IsIncreaseOnlyOn = false;
                app.Heatmap_IsDecreaseOnlyOn = false;
                app.Heatmap_HeatmapToggle.Text = '🌡️ Hide Heatmap';
                app.Heatmap_HeatmapToggle.BackgroundColor = [0.8 0.2 0.2];
                app.showHeatmapHeatmap();
            end
        end
        % Update Heatmap UI elements
        function updateHeatmapDropdowns(app)
            if isempty(app.CurrentLoadedTable)
                return;
            end
            
            % Update dropdown items
            imageNames = app.CurrentLoadedTable.Name;
            app.Heatmap_Img1DropDown.Items = imageNames;
            app.Heatmap_Img2DropDown.Items = imageNames;
            
            % Set default selections
            if length(imageNames) >= 2
                app.Heatmap_Img1DropDown.Value = imageNames{1};
                app.Heatmap_Img2DropDown.Value = imageNames{length(imageNames)};
                app.Heatmap_SelectedImg1 = 1;
                app.Heatmap_SelectedImg2 = length(imageNames);
            end
        end
        
        % Update analysis based on current mode
        function updateHeatmapAnalysis(app)
            if app.Heatmap_IsComprehensiveMode
                app.Heatmap_showComprehensiveMap();
            elseif app.Heatmap_IsHeatmapOn
                app.showHeatmapHeatmap();
            elseif app.Heatmap_IsIncreaseOnlyOn
                app.showHeatmapIncreaseOnly();
            elseif app.Heatmap_IsDecreaseOnlyOn
                app.showHeatmapDecreaseOnly();
            end
        end
        
        % Show heatmap analysis
        function showHeatmapHeatmap(app)
            if isempty(app.CroppedImages) || app.Heatmap_SelectedImg1 == app.Heatmap_SelectedImg2
                uialert(app.UIFigure, 'Please load images and select two different images', 'Warning');
                app.Heatmap_HeatmapToggle.Value = false;
                app.Heatmap_IsHeatmapOn = false;
                return;
            end
            
            try
                app.updateHeatmapStatus('Calculating heatmap...');
                
                img1 = app.CroppedImages{app.Heatmap_SelectedImg1};
                img2 = app.CroppedImages{app.Heatmap_SelectedImg2};
                
                gray1 = rgb2gray(img1);
                gray2 = rgb2gray(img2);
                diff = abs(gray2 - gray1);
                
                increase = gray2 > gray1;
                decrease = gray2 < gray1;
                significantChange = diff > app.Heatmap_CurrentThreshold;
                
                hasIncrease = any(increase(:) & significantChange(:));
                hasDecrease = any(decrease(:) & significantChange(:));
                
                app.updateHeatmapLamps(hasIncrease, hasDecrease);
                
                % Create color heatmap
                heatmapImg = zeros(size(img1));
                
                % Increase regions - Yellow
                increaseRegion = increase & significantChange;
                if any(increaseRegion(:))
                    intensityMap = zeros(size(gray1));
                    intensityMap(increaseRegion) = diff(increaseRegion);
                    if max(intensityMap(:)) > 0
                        normalizedIntensity = intensityMap / max(intensityMap(:));
                        heatmapImg(:,:,1) = heatmapImg(:,:,1) + normalizedIntensity;
                        heatmapImg(:,:,2) = heatmapImg(:,:,2) + normalizedIntensity;
                    end
                end
                
                % Decrease regions - Blue
                decreaseRegion = decrease & significantChange;
                if any(decreaseRegion(:))
                    intensityMap = zeros(size(gray1));
                    intensityMap(decreaseRegion) = diff(decreaseRegion);
                    if max(intensityMap(:)) > 0
                        normalizedIntensity = intensityMap / max(intensityMap(:));
                        heatmapImg(:,:,3) = heatmapImg(:,:,3) + normalizedIntensity;
                    end
                end
                
                app.Heatmap_CurrentHeatmapImg = heatmapImg;
                
                cla(app.Heatmap_Analysis_Axes);
                imshow(heatmapImg, 'Parent', app.Heatmap_Analysis_Axes);
                axis(app.Heatmap_Analysis_Axes, 'image');
                app.Heatmap_Analysis_Axes.XLimMode = 'auto';
                app.Heatmap_Analysis_Axes.YLimMode = 'auto';
                title(app.Heatmap_Analysis_Axes, sprintf('Heatmap (Yellow=Increase, Blue=Decrease, Threshold: %.2f)', app.Heatmap_CurrentThreshold), 'FontSize', 11);
                
                app.updateHeatmapStatus('Heatmap calculation completed!');
                
            catch ME
                app.updateHeatmapStatus('Heatmap calculation failed');
                uialert(app.UIFigure, ['Heatmap calculation failed: ' ME.message], 'Error');
                app.updateHeatmapLamps(false, false);
            end
        end
        
        % Show increase only heatmap analysis
        function showHeatmapIncreaseOnly(app)
            if isempty(app.CroppedImages) || app.Heatmap_SelectedImg1 == app.Heatmap_SelectedImg2
                uialert(app.UIFigure, 'Please load images and select two different images', 'Warning');
                return;
            end
            
            try
                app.Heatmap_IsHeatmapOn = false;
                app.Heatmap_IsIncreaseOnlyOn = true;
                app.Heatmap_IsDecreaseOnlyOn = false;
                
                app.Heatmap_HeatmapToggle.Value = false;
                app.Heatmap_HeatmapToggle.Text = '🌡️ Show Heatmap';
                app.Heatmap_HeatmapToggle.BackgroundColor = [0.2157 0.4941 0.7216];
                
                img1 = app.CroppedImages{app.Heatmap_SelectedImg1};
                img2 = app.CroppedImages{app.Heatmap_SelectedImg2};
                
                gray1 = rgb2gray(img1);
                gray2 = rgb2gray(img2);
                diff = abs(gray2 - gray1);
                
                increase = gray2 > gray1;
                significantChange = diff > app.Heatmap_CurrentThreshold;
                
                hasIncrease = any(increase(:) & significantChange(:));
                app.updateHeatmapLamps(hasIncrease, false);
                
                % Create heatmap for increase only
                heatmapImg = zeros(size(img1));
                increaseRegion = increase & significantChange;
                if any(increaseRegion(:))
                    intensityMap = zeros(size(gray1));
                    intensityMap(increaseRegion) = diff(increaseRegion);
                    if max(intensityMap(:)) > 0
                        normalizedIntensity = intensityMap / max(intensityMap(:));
                        heatmapImg(:,:,1) = normalizedIntensity;
                        heatmapImg(:,:,2) = normalizedIntensity;
                    end
                end
                
                axes(app.Heatmap_Analysis_Axes);
                cla(app.Heatmap_Analysis_Axes);
                imshow(heatmapImg, 'Parent', app.Heatmap_Analysis_Axes);
                title(app.Heatmap_Analysis_Axes, sprintf('Increase Only Heatmap (Yellow, Threshold: %.2f)', app.Heatmap_CurrentThreshold), 'FontSize', 11);
                
                app.updateHeatmapStatus('Increase only heatmap completed!');
                
            catch ME
                uialert(app.UIFigure, ['Increase only heatmap failed: ' ME.message], 'Error');
            end
        end
        
        % Show decrease only heatmap analysis
        function showHeatmapDecreaseOnly(app)
            if isempty(app.CroppedImages) || app.Heatmap_SelectedImg1 == app.Heatmap_SelectedImg2
                uialert(app.UIFigure, 'Please load images and select two different images', 'Warning');
                return;
            end
            
            try
                app.Heatmap_IsHeatmapOn = false;
                app.Heatmap_IsIncreaseOnlyOn = false;
                app.Heatmap_IsDecreaseOnlyOn = true;
                
                app.Heatmap_HeatmapToggle.Value = false;
                app.Heatmap_HeatmapToggle.Text = '🌡️ Show Heatmap';
                app.Heatmap_HeatmapToggle.BackgroundColor = [0.2157 0.4941 0.7216];
                
                img1 = app.CroppedImages{app.Heatmap_SelectedImg1};
                img2 = app.CroppedImages{app.Heatmap_SelectedImg2};
                
                gray1 = rgb2gray(img1);
                gray2 = rgb2gray(img2);
                diff = abs(gray2 - gray1);
                
                decrease = gray2 < gray1;
                significantChange = diff > app.Heatmap_CurrentThreshold;
                
                hasDecrease = any(decrease(:) & significantChange(:));
                app.updateHeatmapLamps(false, hasDecrease);
                
                % Create heatmap for decrease only
                heatmapImg = zeros(size(img1));
                decreaseRegion = decrease & significantChange;
                if any(decreaseRegion(:))
                    intensityMap = zeros(size(gray1));
                    intensityMap(decreaseRegion) = diff(decreaseRegion);
                    if max(intensityMap(:)) > 0
                        normalizedIntensity = intensityMap / max(intensityMap(:));
                        heatmapImg(:,:,3) = normalizedIntensity;
                    end
                end
                
                axes(app.Heatmap_Analysis_Axes);
                cla(app.Heatmap_Analysis_Axes);
                imshow(heatmapImg, 'Parent', app.Heatmap_Analysis_Axes);
                title(app.Heatmap_Analysis_Axes, sprintf('Decrease Only Heatmap (Blue, Threshold: %.2f)', app.Heatmap_CurrentThreshold), 'FontSize', 11);
                
                app.updateHeatmapStatus('Decrease only heatmap completed!');
                
            catch ME
                uialert(app.UIFigure, ['Decrease only heatmap failed: ' ME.message], 'Error');
            end
        end
        
        % Update status display
        function updateHeatmapStatus(app, message)
            if isvalid(app.Heatmap_StatusLabel)
                app.Heatmap_StatusLabel.Text = ['Status: ' message];
                drawnow;
            end
        end
        
        % Update indicator lamps
        function updateHeatmapLamps(app, hasIncrease, hasDecrease)
            if hasIncrease
                app.Heatmap_IncreaseLamp.FontColor = [1, 1, 0]; % Yellow
            else
                app.Heatmap_IncreaseLamp.FontColor = [0.5, 0.5, 0.5]; % Gray
            end
            
            if hasDecrease
                app.Heatmap_DecreaseLamp.FontColor = [0, 0, 1]; % Blue
            else
                app.Heatmap_DecreaseLamp.FontColor = [0.5, 0.5, 0.5]; % Gray
            end
        end
        
        % Reset heatmap display
        function resetHeatmapDisplay(app)
            try                
                % Clear all axes
                if isvalid(app.Heatmap_OriginalA_Axes), cla(app.Heatmap_OriginalA_Axes); end
                if isvalid(app.Heatmap_OriginalB_Axes), cla(app.Heatmap_OriginalB_Axes); end
                if isvalid(app.Heatmap_Overlay_Axes), cla(app.Heatmap_Overlay_Axes); end
                if isvalid(app.Heatmap_Analysis_Axes), cla(app.Heatmap_Analysis_Axes); end
                
                % Reset titles
                if isvalid(app.Heatmap_OriginalA_Axes), title(app.Heatmap_OriginalA_Axes, 'Original Image A', 'FontSize', 13, 'FontWeight', 'bold'); end
                if isvalid(app.Heatmap_OriginalB_Axes), title(app.Heatmap_OriginalB_Axes, 'Original Image B', 'FontSize', 13, 'FontWeight', 'bold'); end
                if isvalid(app.Heatmap_Overlay_Axes), title(app.Heatmap_Overlay_Axes, 'Preprocessed Overlay', 'FontSize', 13, 'FontWeight', 'bold'); end
                if isvalid(app.Heatmap_Analysis_Axes), title(app.Heatmap_Analysis_Axes, 'Heatmap Analysis Results', 'FontSize', 13, 'FontWeight', 'bold'); end
                
                % Reset variables
                app.Heatmap_CurrentHeatmapImg = [];
                app.Heatmap_IsHeatmapOn = false;
                app.Heatmap_IsIncreaseOnlyOn = false;
                app.Heatmap_IsDecreaseOnlyOn = false;
                
                % Reset UI controls
                if isvalid(app.Heatmap_HeatmapToggle)
                    app.Heatmap_HeatmapToggle.Value = false;
                    app.Heatmap_HeatmapToggle.Text = '🌡️ Show Heatmap';
                    app.Heatmap_HeatmapToggle.BackgroundColor = [0.2157 0.4941 0.7216];
                end
                
                app.updateHeatmapLamps(false, false);
                app.updateHeatmapStatus('Display reset completed');
                
            catch ME
                fprintf('Heatmap reset error: %s\n', ME.message);
            end
        end
        
        % Superimposed Heatmap
        function Heatmap_showComprehensiveMap(app)
            if isempty(app.CroppedImages) || app.Heatmap_SelectedImg1 == app.Heatmap_SelectedImg2
                msgbox('Please load images and select two different images', 'Warning');
                app.Heatmap_ComprehensiveToggle.Value = false;
                app.Heatmap_ComprehensiveToggle.Text = 'superimposed Map';
                app.Heatmap_isComprehensiveMode = false;
                return;
            end
            
            try
                % Set current mode
                app.Heatmap_IsComprehensiveMode = true;
                app.Heatmap_IsHeatmapOn = false;
                app.Heatmap_IsHighlightOn = false;
                app.Heatmap_IsIncreaseOnlyOn = false;
                app.Heatmap_IsDecreaseOnlyOn = false;
                
                app.updateHeatmapStatus('Calculating superimposed map...');
                
                % 1. Calculate basic change magnitude
                img1 = app.CroppedImages{app.Heatmap_SelectedImg1};
                img2 = app.CroppedImages{app.Heatmap_SelectedImg2};
                
                gray1 = rgb2gray(img1);
                gray2 = rgb2gray(img2);
                diff = abs(gray2 - gray1);
                
                increase = gray2 > gray1;
                decrease = gray2 < gray1;
                significantChange = diff > app.Heatmap_CurrentThreshold;
                
                % 2. Calculate change rate analysis
                [rateData, hasIncrease, hasDecrease] = app.calculateChangeRateAnalysis(app.Heatmap_SelectedImg1, app.Heatmap_SelectedImg2);
                
                app.updateHeatmapLamps(hasIncrease, hasDecrease);
                
                % 3. Create comprehensive color map
                [h, w, ~] = size(img1);
                comprehensiveImg = zeros(h, w, 3);
                
                % Normalize magnitude for intensity scaling
                maxDiff = max(diff(:));
                if maxDiff > 0
                    normalizedMagnitude = diff / maxDiff;
                else
                    normalizedMagnitude = zeros(size(diff));
                end
                
                % Get rate maps
                increaseRate = rateData.increaseRate;
                decreaseRate = rateData.decreaseRate;
                
                % Color encoding scheme:
                % - Hue: Direction and Speed combined
                % - Saturation: Change magnitude 
                % - Value: Overall intensity
                
                for i = 1:h
                    for j = 1:w
                        if significantChange(i, j)
                            magnitude = normalizedMagnitude(i, j);
                            
                            if increase(i, j)
                                % Increase regions: Red to Orange spectrum
                                rate = increaseRate(i, j);
                                
                                if rate > 0.7  % Very fast increase - Pure Red
                                    comprehensiveImg(i, j, 1) = 1.0 * magnitude;
                                    comprehensiveImg(i, j, 2) = 0.0;
                                    comprehensiveImg(i, j, 3) = 0.0;
                                elseif rate > 0.4  % Medium fast increase - Red-Orange
                                    comprehensiveImg(i, j, 1) = 1.0 * magnitude;
                                    comprehensiveImg(i, j, 2) = 0.3 * magnitude;
                                    comprehensiveImg(i, j, 3) = 0.0;
                                elseif rate > 0.1  % Slow increase - Orange
                                    comprehensiveImg(i, j, 1) = 1.0 * magnitude;
                                    comprehensiveImg(i, j, 2) = 0.6 * magnitude;
                                    comprehensiveImg(i, j, 3) = 0.0;
                                else  % Very slow increase - Yellow-Orange
                                    comprehensiveImg(i, j, 1) = 1.0 * magnitude;
                                    comprehensiveImg(i, j, 2) = 0.8 * magnitude;
                                    comprehensiveImg(i, j, 3) = 0.2 * magnitude;
                                end
                                
                            elseif decrease(i, j)
                                % Decrease regions: Blue to Cyan spectrum
                                rate = decreaseRate(i, j);
                                
                                if rate > 0.7  % Very fast decrease - Pure Blue
                                    comprehensiveImg(i, j, 1) = 0.0;
                                    comprehensiveImg(i, j, 2) = 0.0;
                                    comprehensiveImg(i, j, 3) = 1.0 * magnitude;
                                elseif rate > 0.4  % Medium fast decrease - Blue-Cyan
                                    comprehensiveImg(i, j, 1) = 0.0;
                                    comprehensiveImg(i, j, 2) = 0.3 * magnitude;
                                    comprehensiveImg(i, j, 3) = 1.0 * magnitude;
                                elseif rate > 0.1  % Slow decrease - Cyan
                                    comprehensiveImg(i, j, 1) = 0.0;
                                    comprehensiveImg(i, j, 2) = 0.6 * magnitude;
                                    comprehensiveImg(i, j, 3) = 1.0 * magnitude;
                                else  % Very slow decrease - Light Cyan
                                    comprehensiveImg(i, j, 1) = 0.2 * magnitude;
                                    comprehensiveImg(i, j, 2) = 0.8 * magnitude;
                                    comprehensiveImg(i, j, 3) = 1.0 * magnitude;
                                end
                            end
                        end
                    end
                end
                
                % 4. Display the comprehensive map
                cla(app.Heatmap_Analysis_Axes);
                imshow(comprehensiveImg, 'Parent', app.Heatmap_Analysis_Axes);
                title(app.Heatmap_Analysis_Axes, sprintf('Superimposed Heatmap (Th: %.2f)', app.Heatmap_CurrentThreshold), 'FontSize', 10);
                
                % 5. Add color legend
                app.addComprehensiveColorLegend();
                
                % 6. Display statistics
                app.updateHeatmapStatus('superimposed map completed! Check color legend for interpretation.');
                
            catch ME
                app.updateHeatmapStatus('superimposed map failed');
                errordlg(['superimposed map failed: ' ME.message], 'Error');
                app.updateHeatmapLamps(false, false);
            end
        end

        % Function to calculate time-weighted change rate analysis between two time points
        function [changeRateMap, hasIncrease, hasDecrease] = calculateChangeRateAnalysis(app,startIdx, endIdx)
            % Get all images between start and end indices (inclusive)
            timestamps = app.CurrentLoadedTable.Timestamp;
            startTime = timestamps(startIdx);
            endTime = timestamps(endIdx);
            
            % Find all indices in the time range and sort them
            timeRangeIndices = find(timestamps >= startTime & timestamps <= endTime);
            [sortedTimestamps, sortOrder] = sort(timestamps(timeRangeIndices));
            timeRangeIndices = timeRangeIndices(sortOrder);
            
            app.updateHeatmapStatus(sprintf('Analyzing time-weighted change rate across %d images...', length(timeRangeIndices)));
            
            if length(timeRangeIndices) < 2
                % Not enough images for rate analysis
                changeRateMap = zeros(size(rgb2gray(app.CroppedImages{startIdx})));
                hasIncrease = false;
                hasDecrease = false;
                return;
            end
            
            % Initialize weighted accumulation maps
            [h, w] = size(rgb2gray(app.CroppedImages{timeRangeIndices(1)}));
            increaseRateMap = zeros(h, w);
            decreaseRateMap = zeros(h, w);
            totalTimeSpan = sortedTimestamps(end) - sortedTimestamps(1);
            
            if totalTimeSpan == 0
                totalTimeSpan = 1; % Prevent division by zero
            end
            
            % Use a fixed change rate threshold based on the basic threshold
            changeRateThreshold = app.Heatmap_CurrentThreshold * 3; % 3x the basic threshold for rate filtering
            
            % Calculate time-weighted change rates between consecutive images
            for i = 1:(length(timeRangeIndices) - 1)
                idx1 = timeRangeIndices(i);
                idx2 = timeRangeIndices(i + 1);
                
                img1 = app.CroppedImages{idx1};
                img2 = app.CroppedImages{idx2};
                
                gray1 = rgb2gray(img1);
                gray2 = rgb2gray(img2);
                
                diff = abs(gray2 - gray1);
                increase = gray2 > gray1;
                decrease = gray2 < gray1;
                significantChange = diff > app.Heatmap_CurrentThreshold;
                
                % Calculate time information for this interval
                intervalStartTime = sortedTimestamps(i);
                intervalEndTime = sortedTimestamps(i + 1);
                intervalDuration = intervalEndTime - intervalStartTime;
                if intervalDuration == 0
                    intervalDuration = 1; % Prevent division by zero
                end
                
                % Calculate temporal position: how early this change occurs
                % Earlier changes get higher weights (faster change rate)
                normalizedStartTime = (intervalStartTime - sortedTimestamps(1)) / totalTimeSpan;
                
                % Time weight: earlier changes (smaller normalizedStartTime) get higher weights
                % Using exponential decay: earlier = higher weight
                timeWeight = exp(-3 * normalizedStartTime); % Exponential decay factor
                
                % Calculate change magnitude weighted by time
                changeMagnitude = diff .* double(significantChange);
                
                % Change rate = (change magnitude × time weight) / interval duration
                intervalChangeRate = (changeMagnitude * timeWeight) / intervalDuration;
                
                % Accumulate weighted change rates
                increaseRegion = increase & significantChange;
                decreaseRegion = decrease & significantChange;
                
                increaseRateMap = increaseRateMap + intervalChangeRate .* double(increaseRegion);
                decreaseRateMap = decreaseRateMap + intervalChangeRate .* double(decreaseRegion);
            end
            
            % Normalize change rates to [0, 1] range for consistent thresholding
            maxIncreaseRate = max(increaseRateMap(:));
            maxDecreaseRate = max(decreaseRateMap(:));
            
            if maxIncreaseRate > 0
                increaseRateMap = increaseRateMap / maxIncreaseRate;
            end
            if maxDecreaseRate > 0
                decreaseRateMap = decreaseRateMap / maxDecreaseRate;
            end
            
            % Apply change rate threshold to filter fast vs slow changes
            fastIncreaseRegions = increaseRateMap > (changeRateThreshold / app.Heatmap_CurrentThreshold / 10);
            fastDecreaseRegions = decreaseRateMap > (changeRateThreshold / app.Heatmap_CurrentThreshold / 10);
            
            % Determine if there are significant fast changes
            hasIncrease = any(fastIncreaseRegions(:));
            hasDecrease = any(fastDecreaseRegions(:));
            
            % Create final output maps showing only fast-changing regions
            finalIncreaseRate = increaseRateMap .* double(fastIncreaseRegions);
            finalDecreaseRate = decreaseRateMap .* double(fastDecreaseRegions);
            
            changeRateMap = struct();
            changeRateMap.increaseRate = finalIncreaseRate;
            changeRateMap.decreaseRate = finalDecreaseRate;
            changeRateMap.totalRate = finalIncreaseRate + finalDecreaseRate;
            changeRateMap.numIntervals = length(timeRangeIndices) - 1;
            changeRateMap.totalTimeSpan = totalTimeSpan;
            
            app.updateHeatmapStatus(sprintf('Time-weighted change rate analysis completed for %d intervals (span: %.0f years)', ...
                        changeRateMap.numIntervals, totalTimeSpan/100));
        end
        
        % Add color legend for comprehensive map
        function addComprehensiveColorLegend(app)
            % Create a small legend in the corner of the axes
            hold(app.Heatmap_Analysis_Axes, 'on');
            
            % Get current axes limits
            xlim_curr = get(app.Heatmap_Analysis_Axes, 'XLim');
            ylim_curr = get(app.Heatmap_Analysis_Axes, 'YLim');
            
            % Legend position (top-right corner)
            legendWidth = (xlim_curr(2) - xlim_curr(1)) * 0.15;
            legendHeight = (ylim_curr(2) - ylim_curr(1)) * 0.25;
            legendX = xlim_curr(2) - legendWidth - 10;
            legendY = ylim_curr(1) + 10;
            
            % Background rectangle for legend
            rectangle(app.Heatmap_Analysis_Axes, 'Position', [legendX-5, legendY-5, legendWidth+10, legendHeight+10], ...
                     'FaceColor', [0, 0, 0, 0.8], 'EdgeColor', 'white', 'LineWidth', 1);
            
            % Color samples and labels
            sampleSize = legendHeight / 8;
            
            % Fast increase - Red
            rectangle(app.Heatmap_Analysis_Axes, 'Position', [legendX, legendY, sampleSize, sampleSize], ...
                     'FaceColor', [1, 0, 0], 'EdgeColor', 'none');
            text(app.Heatmap_Analysis_Axes, legendX + sampleSize + 2, legendY + sampleSize/2, 'Fast Inc', ...
                 'Color', 'white', 'FontSize', 7, 'VerticalAlignment', 'middle');
            
            % Slow increase - Orange
            rectangle(app.Heatmap_Analysis_Axes, 'Position', [legendX, legendY + sampleSize*1.5, sampleSize, sampleSize], ...
                     'FaceColor', [1, 0.6, 0], 'EdgeColor', 'none');
            text(app.Heatmap_Analysis_Axes, legendX + sampleSize + 2, legendY + sampleSize*2, 'Slow Inc', ...
                 'Color', 'white', 'FontSize', 7, 'VerticalAlignment', 'middle');
            
            % Fast decrease - Blue
            rectangle(app.Heatmap_Analysis_Axes, 'Position', [legendX, legendY + sampleSize*3, sampleSize, sampleSize], ...
                     'FaceColor', [0, 0, 1], 'EdgeColor', 'none');
            text(app.Heatmap_Analysis_Axes, legendX + sampleSize + 2, legendY + sampleSize*3.5, 'Fast Dec', ...
                 'Color', 'white', 'FontSize', 7, 'VerticalAlignment', 'middle');
            
            % Slow decrease - Cyan
            rectangle(app.Heatmap_Analysis_Axes, 'Position', [legendX, legendY + sampleSize*4.5, sampleSize, sampleSize], ...
                     'FaceColor', [0, 0.6, 1], 'EdgeColor', 'none');
            text(app.Heatmap_Analysis_Axes, legendX + sampleSize + 2, legendY + sampleSize*5, 'Slow Dec', ...
                 'Color', 'white', 'FontSize', 7, 'VerticalAlignment', 'middle');
            
            % Brightness note
            text(app.Heatmap_Analysis_Axes, legendX, legendY + sampleSize*6.5, 'Brightness =', ...
                 'Color', 'white', 'FontSize', 6, 'FontWeight', 'bold');
            text(app.Heatmap_Analysis_Axes, legendX, legendY + sampleSize*7.2, 'Change Size', ...
                 'Color', 'white', 'FontSize', 6, 'FontWeight', 'bold');
            
            hold(app.Heatmap_Analysis_Axes, 'off');
        end

    
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % 6. Flicker Functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % Update Flicker dropdowns based on loaded images
        function updateFlickerDropdowns(app)
            try
                if isempty(app.CroppedImages) || isempty(app.CurrentLoadedTable)
                    app.Flicker_Img1DropDown.Items = {'Please load images first'};
                    app.Flicker_Img2DropDown.Items = {'Please load images first'};
                    app.Flicker_Img1DropDown.Value = app.Flicker_Img1DropDown.Items{1};
                    app.Flicker_Img2DropDown.Value = app.Flicker_Img2DropDown.Items{1};
                    app.Flicker_SelectedImg1 = 0;
                    app.Flicker_SelectedImg2 = 0;
                    
                    % Clear display
                    app.updateFlickerDisplay();
                    app.Flicker_StatusLabel.Text = 'Status: No images loaded';
                    app.Flicker_StatusLabel.FontColor = [0.5, 0.5, 0.5];
                    return;
                end
                
                % Update dropdown items
                app.Flicker_Img1DropDown.Items = app.CurrentLoadedTable.Name;
                app.Flicker_Img2DropDown.Items = app.CurrentLoadedTable.Name;
                
                % Set default values if possible
                if height(app.CurrentLoadedTable) >= 1
                    app.Flicker_Img1DropDown.Value = app.CurrentLoadedTable.Name{1};
                    app.Flicker_SelectedImg1 = 1;
                else
                    app.Flicker_SelectedImg1 = 0;
                end
                
                if height(app.CurrentLoadedTable) >= 2
                    app.Flicker_Img2DropDown.Value = app.CurrentLoadedTable.Name{height(app.CurrentLoadedTable)};
                    app.Flicker_SelectedImg2 = height(app.CurrentLoadedTable);
                else
                    app.Flicker_SelectedImg2 = min(1, height(app.CurrentLoadedTable));
                end
                
                % Update display when images are available
                app.updateFlickerDisplay();
                app.Flicker_StatusLabel.Text = sprintf('Status: %d images loaded - ready for analysis', height(app.CurrentLoadedTable));
                app.Flicker_StatusLabel.FontColor = [0, 0.4, 0];
                
            catch ME
                app.Flicker_StatusLabel.Text = ['Status: Error - ' ME.message];
                app.Flicker_StatusLabel.FontColor = [0.8, 0, 0];
                fprintf('Flicker dropdown update error: %s\n', ME.message);
            end
        end
        
        % Update display for all Flicker axes
        function updateFlickerDisplay(app)
            try
                
                if isempty(app.CroppedImages) || isempty(app.CurrentLoadedTable)
                    % Show placeholder when no images loaded
                    if ~app.Flicker_IsFlickering
                        cla(app.Flicker_Display_Axes);
                        title(app.Flicker_Display_Axes, '⚡ Flicker Display', 'FontSize', 14, 'FontWeight', 'bold');
                    end
                    return;
                end
                
                % Initialize flicker display
                if ~app.Flicker_IsFlickering
                    if app.Flicker_SelectedImg1 > 0 && app.Flicker_SelectedImg1 <= length(app.CroppedImages)
                        imshow(app.CroppedImages{app.Flicker_SelectedImg1}, 'Parent', app.Flicker_Display_Axes);
                        axis(app.Flicker_Display_Axes, 'image');
                        app.Flicker_Display_Axes.XLimMode = 'auto';
                        app.Flicker_Display_Axes.YLimMode = 'auto';
                        title(app.Flicker_Display_Axes, '⚡ Flicker Display (Ready)', 'FontSize', 14, 'FontWeight', 'bold');
                    else
                        cla(app.Flicker_Display_Axes);
                        title(app.Flicker_Display_Axes, '⚡ Flicker Display', 'FontSize', 14, 'FontWeight', 'bold');
                    end
                end
                
            catch ME
                app.Flicker_StatusLabel.Text = ['Status: Display error - ' ME.message];
                app.Flicker_StatusLabel.FontColor = [0.8, 0, 0]; 
            end
        end
        
        % Initialize timer for flicker functionality
        function initializeFlickerTimer(app)
            try
                % Stop and delete existing timer if it exists
                if ~isempty(app.Flicker_Timer) && isvalid(app.Flicker_Timer)
                    stop(app.Flicker_Timer);
                    delete(app.Flicker_Timer);
                end
                
                % Create new timer
                app.Flicker_Timer = timer('TimerFcn', @(~,~) app.flickerTimerCallback(), ...
                                         'Period', app.Flicker_CurrentSpeed, ...
                                         'ExecutionMode', 'fixedRate');
            catch ME
                app.Flicker_StatusLabel.Text = ['Status: Timer error - ' ME.message];
                app.Flicker_StatusLabel.FontColor = [0.8, 0, 0];
            end
        end
        
        % Timer callback for flicker animation
        function flickerTimerCallback(app)
            try
                if ~app.Flicker_IsFlickering || isempty(app.CroppedImages)
                    return;
                end
                
                app.Flicker_State = ~app.Flicker_State;
                
                switch app.Flicker_CurrentMode
                    case "Original"
                        % Flicker between original images
                        if app.Flicker_State
                            imgToShow = app.CroppedImages{app.Flicker_SelectedImg1};
                            titleText = sprintf('⚡ Image A (%.1fs)', app.Flicker_CurrentSpeed);
                        else
                            imgToShow = app.CroppedImages{app.Flicker_SelectedImg2};
                            titleText = sprintf('⚡ Image B (%.1fs)', app.Flicker_CurrentSpeed);
                        end
                        
                    case "Highlight"
                        % Flicker between original and highlight
                        if app.Flicker_State
                            imgToShow = app.CroppedImages{app.Flicker_SelectedImg1};
                            titleText = sprintf('⚡ Original A (%.1fs)', app.Flicker_CurrentSpeed);
                        else
                            % Generate highlight image
                            try
                                highlightImg = app.generateHighlightImage();
                                imgToShow = highlightImg;
                                titleText = sprintf('⚡ Highlight (%.1fs)', app.Flicker_CurrentSpeed);
                            catch
                                imgToShow = app.CroppedImages{app.Flicker_SelectedImg2};
                                titleText = sprintf('⚡ Image B (%.1fs)', app.Flicker_CurrentSpeed);
                            end
                        end
                        
                    case "Heatmap"
                        % Flicker between original and heatmap
                        if app.Flicker_State
                            imgToShow = app.CroppedImages{app.Flicker_SelectedImg1};
                            titleText = sprintf('⚡ Original A (%.1fs)', app.Flicker_CurrentSpeed);
                        else
                            % Generate heatmap image
                            try
                                heatmapImg = app.generateHeatmapImage();
                                imgToShow = heatmapImg;
                                titleText = sprintf('⚡ Heatmap (%.1fs)', app.Flicker_CurrentSpeed);
                            catch
                                imgToShow = app.CroppedImages{app.Flicker_SelectedImg2};
                                titleText = sprintf('⚡ Image B (%.1fs)', app.Flicker_CurrentSpeed);
                            end
                        end
                end
                
                % Display the image
                imshow(imgToShow, [], 'Parent', app.Flicker_Display_Axes);
                title(app.Flicker_Display_Axes, titleText, 'FontSize', 14, 'FontWeight', 'bold');
                
            catch ME
                app.stopFlicker();
                app.Flicker_StatusLabel.Text = ['Status: Flicker error - ' ME.message];
                app.Flicker_StatusLabel.FontColor = [0.8, 0, 0];
            end
        end
        
        % Generate highlight image for flicker
        function highlightImg = generateHighlightImage(app)
            try
                if app.Flicker_SelectedImg1 <= length(app.CroppedImages) && app.Flicker_SelectedImg2 <= length(app.CroppedImages)
                    img1 = app.CroppedImages{app.Flicker_SelectedImg1};
                    img2 = app.CroppedImages{app.Flicker_SelectedImg2};
                    
                    threshold = app.DiffHighlight_CurrentThreshold;
                    
                    % Flicker overlay + boundary version (from new tool)
                    gray1 = rgb2gray(img1);
                    gray2 = rgb2gray(img2);
                    diff = abs(gray2 - gray1);
                    increase = gray2 > gray1;
                    decrease = gray2 < gray1;
                    significantChange = diff > threshold;
                    
                    % Overlay image
                    overlayImg = 0.5 * img1 + 0.5 * img2;
                    
                    % Boundary detection
                    increaseBoundary = bwperim(increase & significantChange);
                    decreaseBoundary = bwperim(decrease & significantChange);
                    
                    if app.Flicker_Thick_Boarder
                        increaseBoundary = imdilate(increaseBoundary, strel('disk', 1));
                        decreaseBoundary = imdilate(decreaseBoundary, strel('disk', 1));
                    end
                    
                    % Boundary coloring: Increase → Yellow, Decrease → Red
                    boundaryImg = zeros(size(img1));
                    boundaryImg(:,:,1) = double(decreaseBoundary) + double(increaseBoundary);  % Red channel
                    boundaryImg(:,:,2) = double(increaseBoundary);                             % Green channel
                    boundaryImg(:,:,3) = 0;                                                    % No Blue
                    
                    % Blend overlay + boundaries
                    boundaryIntensity = 0.9;
                    maskIntensity = max(boundaryImg, [], 3);
                    highlightImg = overlayImg .* (1 - boundaryIntensity * maskIntensity) + ...
                                   boundaryIntensity * boundaryImg;
                    highlightImg = min(max(highlightImg, 0), 1);
                    
                else
                    error('Invalid image indices');
                end
            catch
                % Fallback to simple difference
                img1 = double(app.CroppedImages{app.Flicker_SelectedImg1});
                img2 = double(app.CroppedImages{app.Flicker_SelectedImg2});
                diff = abs(img1 - img2);
                if max(diff(:)) > 0
                    highlightImg = diff / max(diff(:));
                else
                    highlightImg = zeros(size(img1));
                end
            end
        end

        
        % Generate heatmap image for flicker (matching main heatmap tool)
        function heatmapImg = generateHeatmapImage(app)
            try
                if app.Flicker_SelectedImg1 <= length(app.CroppedImages) && app.Flicker_SelectedImg2 <= length(app.CroppedImages)
                    img1 = app.CroppedImages{app.Flicker_SelectedImg1};
                    img2 = app.CroppedImages{app.Flicker_SelectedImg2};
                    
                    threshold = app.Heatmap_CurrentThreshold;
                    
                    % Heatmap calculation (copied from showHeatmapHeatmap)
                    gray1 = rgb2gray(img1);
                    gray2 = rgb2gray(img2);
                    diff = abs(gray2 - gray1);
                    
                    increase = gray2 > gray1;
                    decrease = gray2 < gray1;
                    significantChange = diff > threshold;
                    
                    heatmapImg = zeros(size(img1));
                    
                    % Increase regions - Yellow
                    increaseRegion = increase & significantChange;
                    if any(increaseRegion(:))
                        intensityMap = zeros(size(gray1));
                        intensityMap(increaseRegion) = diff(increaseRegion);
                        if max(intensityMap(:)) > 0
                            normalizedIntensity = intensityMap / max(intensityMap(:));
                            heatmapImg(:,:,1) = normalizedIntensity;
                            heatmapImg(:,:,2) = normalizedIntensity;
                        end
                    end
                    
                    % Decrease regions - Blue
                    decreaseRegion = decrease & significantChange;
                    if any(decreaseRegion(:))
                        intensityMap = zeros(size(gray1));
                        intensityMap(decreaseRegion) = diff(decreaseRegion);
                        if max(intensityMap(:)) > 0
                            normalizedIntensity = intensityMap / max(intensityMap(:));
                            heatmapImg(:,:,3) = normalizedIntensity;
                        end
                    end
                    
                else
                    error('Invalid image indices');
                end
            catch
                % Fallback: Simple grayscale difference heatmap
                img1 = double(app.CroppedImages{app.Flicker_SelectedImg1});
                img2 = double(app.CroppedImages{app.Flicker_SelectedImg2});
                
                % Convert to grayscale if needed
                if size(img1, 3) == 3
                    img1 = rgb2gray(img1);
                end
                if size(img2, 3) == 3
                    img2 = rgb2gray(img2);
                end
                
                diff = img2 - img1;
                heatmapImg = zeros(size(diff, 1), size(diff, 2), 3);
                
                positive_diff = max(diff, 0);
                negative_diff = abs(min(diff, 0));
                
                if max(positive_diff(:)) > 0
                    positive_diff = positive_diff / max(positive_diff(:));
                end
                if max(negative_diff(:)) > 0
                    negative_diff = negative_diff / max(negative_diff(:));
                end
                
                heatmapImg(:,:,1) = positive_diff;
                heatmapImg(:,:,2) = positive_diff;
                heatmapImg(:,:,3) = negative_diff;
            end
        end

        
        % Stop flicker animation
        function stopFlicker(app)
            try
                app.Flicker_IsFlickering = false;
                if ~isempty(app.Flicker_Timer) && isvalid(app.Flicker_Timer)
                    stop(app.Flicker_Timer);
                end
                app.Flicker_FlickerToggle.Value = false;
                app.Flicker_FlickerToggle.Text = '⚡ Start Flicker';
                app.Flicker_FlickerToggle.BackgroundColor = [0.2157 0.4941 0.7216];
                app.Flicker_StatusLabel.Text = 'Status: Flicker stopped';
                app.Flicker_StatusLabel.FontColor = [0, 0.4, 0];
            catch ME
                app.Flicker_StatusLabel.Text = ['Status: Stop error - ' ME.message];
                app.Flicker_StatusLabel.FontColor = [0.8, 0, 0];
            end
        end
        
        % Reset Flicker interface
        function resetFlickerInterface(app)
            try
                % Stop flicker if running
                app.stopFlicker();
                
                % Reset to defaults
                app.Flicker_CurrentSpeed = 0.5;
                app.Flicker_CurrentMode = "Original";
                app.Flicker_State = false;
                
                % Update UI controls
                app.Flicker_SpeedSlider.Value = 0.5;
                app.Flicker_SpeedLabel.Text = 'Speed: 0.5s';
                app.Flicker_ModeDropDown.Value = 'Original ↔ Original';
                
                % Reset image selections based on what's available
                if ~isempty(app.CroppedImages) && ~isempty(app.CurrentLoadedTable)
                    % Images are loaded - reset to first two images
                    app.Flicker_SelectedImg1 = 1;
                    app.Flicker_SelectedImg2 = min(2, height(app.CurrentLoadedTable));
                    
                    if height(app.CurrentLoadedTable) >= 1
                        app.Flicker_Img1DropDown.Value = app.CurrentLoadedTable.Name{1};
                    end
                    if height(app.CurrentLoadedTable) >= 2
                        app.Flicker_Img2DropDown.Value = app.CurrentLoadedTable.Name{2};
                    end
                else
                    % No images loaded - reset to empty state
                    app.Flicker_SelectedImg1 = 0;
                    app.Flicker_SelectedImg2 = 0;
                    app.Flicker_Img1DropDown.Items = {'Please load images first'};
                    app.Flicker_Img2DropDown.Items = {'Please load images first'};
                    app.Flicker_Img1DropDown.Value = app.Flicker_Img1DropDown.Items{1};
                    app.Flicker_Img2DropDown.Value = app.Flicker_Img2DropDown.Items{1};
                end
                
                % Clear display axes
                cla(app.Flicker_Display_Axes);
                
                % Reset titles
                title(app.Flicker_Display_Axes, '⚡ Flicker Display', 'FontSize', 14, 'FontWeight', 'bold');
                
                % Update display
                app.updateFlickerDisplay();
                
                % Set appropriate status message
                if ~isempty(app.CroppedImages) && ~isempty(app.CurrentLoadedTable)
                    app.Flicker_StatusLabel.Text = 'Status: Interface reset - ready for analysis';
                    app.Flicker_StatusLabel.FontColor = [0, 0.4, 0];
                else
                    app.Flicker_StatusLabel.Text = 'Status: Interface reset - please load images first';
                    app.Flicker_StatusLabel.FontColor = [0.5, 0.5, 0.5];
                end
                
            catch ME
                app.Flicker_StatusLabel.Text = ['Status: Reset error - ' ME.message];
                app.Flicker_StatusLabel.FontColor = [0.8, 0, 0];
                fprintf('Flicker reset error: %s\n', ME.message);
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
            
            % Handles path problems
            fullPath = mfilename('fullpath');
            cd(fileparts(fullPath));
            [appDir, ~, ~] = fileparts(fullPath);
            addpath(genpath(appDir));
        end

        % Button pushed function: SelectInputFilesButton
        function SelectInputFilesButtonPushed(app, event)
            % Select folder and load image files
            imgTable = app.loadImageFolder();

            if height(imgTable) < 2, return; end
            
            app.StatusReadyLabel.Text = "Status: Please wait - Image Loaded, Displaying Images...";drawnow;
            
            % Refocus app
            figure(app.UIFigure);

            app.addDataset(imgTable);

            app.displayDataset(imgTable);

            app.StatusReadyLabel.Text = "Status: Please wait - GUI Refreshed, Processing Images...";drawnow;
            
            % Start Preprocess in background
            app.preproccessImages();

            app.updateDataset();

            app.StatusReadyLabel.Text = sprintf("Status: Image Set %s loaded. Ready", app.CurrentLoadedTable.Properties.UserData);
        end

        % Value changed function: ImageSelectDropDown
        function ImageSelectDropDownValueChanged(app, event)
            value = app.ImageSelectDropDown.Value;
            if ~strcmp(app.PreviewImageData.Name, value)
                idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
                if ~isempty(idx)
                    app.PreviewImageData = app.CurrentLoadedTable(idx, :);
                    app.updatePreview();
                end
            end
        end

        % Value changed function: PreviewModeDropDown
        function PreviewModeDropDownValueChanged(app, event)
            app.DashboardPreviewMode = app.PreviewModeDropDown.Value;
            app.updatePreview();
            app.updateStart();
            app.updateEnd();
        end

        % Selection changed function: FileTree
        function FileTreeSelectionChanged(app, event)
            selectedNodes = app.FileTree.SelectedNodes;
            if ~isa(selectedNodes.Parent, 'matlab.ui.container.CheckBoxTree')
                selectedNodesName = selectedNodes(1).Text;
                % Copy Pasta 
                if ~strcmp(app.PreviewImageData.Name, selectedNodesName)
                    idx = find(strcmp(app.CurrentLoadedTable.Name, selectedNodesName), 1);
                    if ~isempty(idx)
                        app.PreviewImageData = app.CurrentLoadedTable(idx, :);
                        app.updatePreview();
                    end
                end
            end
        end

        % Callback function: FileTree
        function FileTreeCheckedNodesChanged(app, event)
            checkedNodes = app.FileTree.CheckedNodes;
            currentDatasetName = app.getDatasetName(app.CurrentLoadedTable);
   
            % Filter for root-level node and exclude current dataset
            rootNodes = checkedNodes([checkedNodes.Parent] == app.FileTree);
            rootNodes = rootNodes(~strcmp({rootNodes.Text}, currentDatasetName));
        
            % If another dataset is checked, switch to it
            if ~isempty(rootNodes)
                datasetName = rootNodes(1).Text;
                idx = find(cellfun(@(d) strcmp(d.Name, datasetName), app.ImageDatasets), 1);
                if ~isempty(idx)
                    dataset = app.ImageDatasets{idx};
                    app.CurrentLoadedTable = dataset.Table;
                    app.CurrentLoadedTable.Properties.UserData = datasetName;
                    app.StatusReadyLabel.Text = sprintf("Status: Please wait - Loading & Preprocessing %s ...", app.CurrentLoadedTable.Properties.UserData);drawnow;
                    app.displayDataset(dataset.Table);
                    app.preproccessImages();
                    app.updateDataset();
                    app.StatusReadyLabel.Text = sprintf("Status: Image Set %s loaded. Ready", app.CurrentLoadedTable.Properties.UserData);
                    return;
                else
                    warning('Dataset "%s" not found in ImageDatasets.', datasetName);
                end
            else
                % No dataset switch — check changes in subnode selection
                parentNode = findobj(app.FileTree.Children, 'Text', currentDatasetName);
                if ~isempty(parentNode)
                    subNodes = checkedNodes([checkedNodes.Parent] == parentNode);
                    selectedNames = {subNodes.Text};
                    currentNames = app.CurrentLoadedTable.Name;
            
                    % Update only if selected images changed
                    if ~isequal(sort(selectedNames), sort(currentNames))
                        dataset = app.ImageDatasets{strcmp(currentDatasetName, cellfun(@(d) d.Name, app.ImageDatasets, 'UniformOutput', false))};
                        newTable = dataset.Table(ismember(dataset.Table.Name, selectedNames), :);
                        newTable.Properties.UserData = currentDatasetName;
                        app.CurrentLoadedTable = newTable;
                        app.CurrentLoadedTable.Properties.UserData = app.getDatasetName(newTable);
                        app.StatusReadyLabel.Text = sprintf("Status: Please wait - Loading & Preprocessing %s ...", app.CurrentLoadedTable.Properties.UserData);drawnow;
                        app.displayDataset(app.CurrentLoadedTable);
                        app.preproccessImages();
                        app.updateDataset();
                        app.StatusReadyLabel.Text = sprintf("Status: Image Set %s loaded. Ready", app.CurrentLoadedTable.Properties.UserData);
                    end
                end
            end
        end

        % Value changed function: StartImageDropDown
        function StartImageDropDownValueChanged(app, event)
            value = app.StartImageDropDown.Value;
            startIdx = find(strcmp(app.DashboardDropdownItems, value));

            % Limit End Image Dropdown to images at or after Start Image
            validEndItems = app.DashboardDropdownItems(startIdx+1:end);
            app.EndImageDropDown.Items = validEndItems;
            
            % Reset End Image selection if it was before the new start index
            if ~ismember(app.EndImageDropDown.Value, validEndItems)
                app.EndImageDropDown.Value = validEndItems(end);
            end
            app.updateDatasetFromSidebarSelection()
        end

        % Value changed function: EndImageDropDown
        function EndImageDropDownValueChanged(app, event)
            value = app.EndImageDropDown.Value;
            endIdx = find(strcmp(app.DashboardDropdownItems, value));

            % Limit First Image Dropdown to images at or after Start Image
            validStartItems = app.DashboardDropdownItems(1:endIdx-1);
            app.StartImageDropDown.Items = validStartItems;
            
            % Reset First Image selection if it was before the new end index
            if ~ismember(app.EndImageDropDown.Value, validStartItems)
                app.StartImageDropDown.Value = validStartItems(1); 
            end
            app.updateDatasetFromSidebarSelection()
        end

        % Value changed function: Slider_Img1DropDown
        function Slider_Img1DropDownValueChanged(app, event)
            value = app.Slider_Img1DropDown.Value;

            % Check if images are loaded
            if isempty(app.CurrentLoadedTable) || contains(value, 'Load images from Dashboard')
                app.Slider_SelectedImg1 = 0;
                app.Slider_StatusLabel.Text = '📂 Navigate to Dashboard → Select Input Files to load images';
                app.Slider_StatusLabel.FontColor = [0.7, 0.4, 0.1];
                return;
            end

            startIdx = find(strcmp(app.DashboardDropdownItems, value));

            % Limit End Image Dropdown to images at or after Start Image
            validEndItems = app.DashboardDropdownItems(startIdx+1:end);
            app.Slider_Img2DropDown.Items = validEndItems;
            
            % Reset End Image selection if it was before the new start index
            if ~ismember(app.Slider_Img2DropDown.Value, validEndItems)
                app.Slider_Img2DropDown.Value = validEndItems(end);
            end
            
            % Find the selected image index
            idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
            if ~isempty(idx)
                app.Slider_SelectedImg1 = idx;
                app.initializeSliderImages();
                app.updateSliderDisplay();
                
                shortName = app.extractShortImageName(value);
                app.Slider_StatusLabel.Text = ['🖼️ Left: ' shortName ' • Ready to compare'];
                app.Slider_StatusLabel.FontColor = [0.2, 0.6, 0.2];
            else
                app.Slider_SelectedImg1 = 0;
                app.Slider_StatusLabel.Text = '❌ Left image selection failed';
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
            end
            
        end

        % Value changed function: Slider_Img2DropDown
        function Slider_Img2DropDownValueChanged(app, event)
            value = app.Slider_Img2DropDown.Value;

            % Check if images are loaded
            if isempty(app.CurrentLoadedTable) || contains(value, 'Load images from Dashboard')
                app.Slider_SelectedImg2 = 0;
                app.Slider_StatusLabel.Text = '📂 Navigate to Dashboard → Select Input Files to load images';
                app.Slider_StatusLabel.FontColor = [0.7, 0.4, 0.1];
                return;
            end

            endIdx = find(strcmp(app.DashboardDropdownItems, value));

            % Limit First Image Dropdown to images at or after Start Image
            validStartItems = app.DashboardDropdownItems(1:endIdx-1);
            app.Slider_Img1DropDown.Items = validStartItems;
            
            % Reset First Image selection if it was before the new end index
            if ~ismember(app.EndImageDropDown.Value, validStartItems)
                app.Slider_Img1DropDown.Value = validStartItems(1); 
            end
            
            % Find the selected image index from globally loaded images
            idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
            if ~isempty(idx)
                app.Slider_SelectedImg2 = idx;
                app.initializeSliderImages();
                app.updateSliderDisplay();
                
                % Modern status with emoji and clean text
                shortName = app.extractShortImageName(value);
                app.Slider_StatusLabel.Text = ['🖼️ Right: ' shortName ' • Ready to compare'];
                app.Slider_StatusLabel.FontColor = [0.2, 0.6, 0.2];
            else
                app.Slider_SelectedImg2 = 0;
                app.Slider_StatusLabel.Text = '❌ Right image selection failed';
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
            end
            
        end

        % Button pushed function: Slider_ResetLeftButton
        function Slider_ResetLeftButtonPushed(app, event)
            app.resetSliderPosition('left');
        end

        % Button pushed function: Slider_ResetCenterButton
        function Slider_ResetCenterButtonPushed(app, event)
            app.resetSliderPosition('center');
        end

        % Button pushed function: Slider_ResetRightButton
        function Slider_ResetRightButtonPushed(app, event)
            app.resetSliderPosition('right');
        end

        % Button pushed function: Slider_AutoScanButton
        function Slider_AutoScanButtonPushed(app, event)
            app.performSliderAutoScan();
        end

        % Button pushed function: Slider_SaveGIFButton
        function Slider_SaveGIFButtonPushed(app, event)
            try
                if isempty(app.Slider_CurrentImg1) || isempty(app.Slider_CurrentImg2)
                    uialert(app.UIFigure, 'No images to create GIF. Please select images first.', 'Warning');
                    return;
                end
                
                % Open save dialog
                [file, path] = uiputfile('*.gif', 'Save Auto Scan GIF');
                if isequal(file, 0)
                    return; % User cancelled
                end
                
                [~, name, ~] = fileparts(file);
                filename = [name, '.gif'];
                fullPath = fullfile(path, filename);
                
                % Disable button during GIF creation
                app.Slider_SaveGIFButton.Enable = 'off';
                app.Slider_SaveGIFButton.Text = '⏳ Creating GIF...';
                app.Slider_StatusLabel.Text = 'Status: Creating auto scan GIF...';
                app.Slider_StatusLabel.FontColor = [0, 0.4, 0];
                
                % Create GIF
                app.createSliderAutoScanGIF(fullPath);
                
                % Re-enable button
                app.Slider_SaveGIFButton.Enable = 'on';
                app.Slider_SaveGIFButton.Text = '💾 Save Scan GIF';
                app.Slider_StatusLabel.Text = 'Status: GIF saved successfully';
                app.Slider_StatusLabel.FontColor = [0, 0.4, 0];
                
                msgbox(['Auto scan GIF saved successfully!' newline 'Location: ' fullPath], 'Success');
                
            catch ME
                app.Slider_SaveGIFButton.Enable = 'on';
                app.Slider_SaveGIFButton.Text = '💾 Save Scan GIF';
                app.Slider_StatusLabel.Text = ['Status: GIF creation error - ' ME.message];
                app.Slider_StatusLabel.FontColor = [0.8, 0, 0];
                uialert(app.UIFigure, ['GIF creation failed: ' ME.message], 'Error');
            end
        end

        % Value changing function: QuickSelectSlider
        function QuickSelectSliderValueChanging(app, event)
            selectedIdx = round(event.Value);
            selectedIdx = min(max(selectedIdx, 1), height(app.CurrentLoadedTable));

            currentIdx = find(strcmp(app.CurrentLoadedTable.Name, app.PreviewImageData.Name), 1);
            
            if isempty(currentIdx) || currentIdx ~= selectedIdx
                app.PreviewImageData = app.CurrentLoadedTable(selectedIdx, :);
                app.updatePreview();
            end
        end

        % Value changed function: DiffHighlight_Img1DropDown
        function DiffHighlight_Img1DropDownValueChanged(app, event)
            value = app.DiffHighlight_Img1DropDown.Value;
            idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
            if ~isempty(idx)
                app.DiffHighlight_SelectedImg1 = idx;
                app.updateDifferenceHighlight();
                app.updateDiffHighlightAnalysis();
            end
        end

        % Value changed function: DiffHighlight_Img2DropDown
        function DiffHighlight_Img2DropDownValueChanged(app, event)
            value = app.DiffHighlight_Img2DropDown.Value;
            idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
            if ~isempty(idx)
                app.DiffHighlight_SelectedImg2 = idx;
                app.updateDifferenceHighlight();
                app.updateDiffHighlightAnalysis();
            end
        end

        % Value changed function: DiffHighlight_ThresholdSlider
        function DiffHighlight_ThresholdSliderValueChanged(app, event)
            app.DiffHighlight_CurrentThreshold = app.DiffHighlight_ThresholdSlider.Value;
            app.DiffHighlight_ThresholdLabel.Text = sprintf('Threshold: %.2f', app.DiffHighlight_CurrentThreshold);
            app.updateDiffHighlightAnalysis();
        end

        % Button pushed function: DiffHighlight_IncreaseOnlyButton
        function DiffHighlight_IncreaseOnlyButtonPushed(app, event)
            app.showDiffHighlightIncreaseOnly();
        end

        % Button pushed function: DiffHighlight_DecreaseOnlyButton
        function DiffHighlight_DecreaseOnlyButtonPushed(app, event)
            app.showDiffHighlightDecreaseOnly();
        end

        % Button pushed function: DiffHighlight_ResetButton
        function DiffHighlight_ResetButtonPushed(app, event)
            app.resetDiffHighlightDisplay();
        end

        % Button pushed function: SaveAnalysisResultsButton
        function SaveAnalysisResultsButtonPushed(app, event)
            if isempty(get(app.DiffHighlight_Analysis_Axes, 'Children'))
                uialert(app.UIFigure, 'No results to save. Please run analysis first.', 'Warning');
                return;
            end
            
            try
                [filename, pathname] = uiputfile({...
                    '*.png', 'PNG Image (*.png)';...
                    '*.jpg', 'JPEG Image (*.jpg)';...
                    '*.fig', 'MATLAB Figure (*.fig)'}, ...
                    'Save Difference Highlight Analysis Results');
                
                if filename ~= 0
                    fullpath = fullfile(pathname, filename);
                    [~, ~, ext] = fileparts(filename);
                    
                    if strcmpi(ext, '.fig')
                        % Save the entire figure
                        tempFig = figure('Visible', 'off');
                        copyobj(app.DiffHighlight_Analysis_Axes, tempFig);
                        savefig(tempFig, fullpath);
                        close(tempFig);
                    else
                        % Export the analysis axes as image
                        exportgraphics(app.DiffHighlight_Analysis_Axes, fullpath, 'Resolution', 300);
                    end
                    
                    app.updateDiffHighlightStatus(sprintf('Results saved to: %s', filename));
                    uialert(app.UIFigure, 'Results saved successfully!', 'Success', 'Icon', 'success');
                end
                
            catch ME
                uialert(app.UIFigure, ['Save failed: ' ME.message], 'Error');
            end
        end

        % Value changed function: DiffHighlight_HighlightToggle
        function DiffHighlight_HighlightToggleValueChanged(app, event)
            if app.DiffHighlight_HighlightToggle.Value
                app.DiffHighlight_IsHighlightOn = true;
                app.DiffHighlight_IsIncreaseOnlyOn = false;
                app.DiffHighlight_IsDecreaseOnlyOn = false;
                app.DiffHighlight_HighlightToggle.Text = '✨ Hide Highlight Differences';
                app.DiffHighlight_HighlightToggle.BackgroundColor = [0.8 0.2 0.2];
                app.showDiffHighlightHighlight();
            else
                app.DiffHighlight_IsHighlightOn = false;
                app.DiffHighlight_HighlightToggle.Text = '✨ Show Highlight Differences';
                app.DiffHighlight_HighlightToggle.BackgroundColor = [0.2157 0.4941 0.7216];
                overlay = 0.5 * app.CroppedImages{app.DiffHighlight_SelectedImg1} + 0.5 * app.CroppedImages{app.DiffHighlight_SelectedImg2};
                imshow(overlay, 'Parent', app.DiffHighlight_Analysis_Axes);
                title(app.DiffHighlight_Analysis_Axes, 'Difference Highlight Overlay Image', 'FontSize', 10, 'FontWeight', 'bold');
                app.DiffHighlight_CurrentHighlightImg = [];
            end
        end

        % Value changed function: Heatmap_Img1DropDown
        function Heatmap_Img1DropDownValueChanged(app, event)
            value = app.Heatmap_Img1DropDown.Value;
            idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
            if ~isempty(idx)
                app.Heatmap_SelectedImg1 = idx;
                app.updateHeatmapAnalysis();
            end
        end

        % Value changed function: Heatmap_Img2DropDown
        function Heatmap_Img2DropDownValueChanged(app, event)
            value = app.Heatmap_Img2DropDown.Value;
            idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
            if ~isempty(idx)
                app.Heatmap_SelectedImg2 = idx;
                app.updateHeatmapAnalysis();
            end
            
        end

        % Value changed function: Heatmap_ThresholdSlider
        function Heatmap_ThresholdSliderValueChanged(app, event)
            app.Heatmap_CurrentThreshold = app.Heatmap_ThresholdSlider.Value;
            app.Heatmap_ThresholdLabel.Text = sprintf('Threshold: %.2f', app.Heatmap_CurrentThreshold);
            app.updateHeatmapAnalysis();
        end

        % Value changed function: Heatmap_HeatmapToggle
        function Heatmap_HeatmapToggleValueChanged(app, event)
            if app.Heatmap_HeatmapToggle.Value
                app.Heatmap_IsHeatmapOn = true;
                app.Heatmap_IsIncreaseOnlyOn = false;
                app.Heatmap_IsDecreaseOnlyOn = false;
                app.Heatmap_HeatmapToggle.Text = '🌡️ Hide Heatmap';
                app.Heatmap_HeatmapToggle.BackgroundColor = [0.8 0.2 0.2];
                app.showHeatmapHeatmap();
            else
                app.Heatmap_IsHeatmapOn = false;
                app.Heatmap_HeatmapToggle.Text = '🌡️ Show Heatmap';
                app.Heatmap_HeatmapToggle.BackgroundColor = [0.2157 0.4941 0.7216];
                %overlay = 0.5 * app.CroppedImages{app.Heatmap_SelectedImg1} + 0.5 * app.CroppedImages{app.Heatmap_SelectedImg2};
                %imshow(overlay, 'Parent', app.Heatmap_Analysis_Axes);
                title(app.Heatmap_Analysis_Axes, '🌡️ Heatmap Analysis Results', 'FontSize', 14, 'FontWeight', 'bold');
                app.Heatmap_CurrentHeatmapImg = [];
                app.updateHeatmapLamps(false, false);
            end
            
        end

        % Button pushed function: Heatmap_IncreaseOnlyButton
        function Heatmap_IncreaseOnlyButtonPushed(app, event)
            app.showHeatmapIncreaseOnly();
        end

        % Button pushed function: Heatmap_DecreaseOnlyButton
        function Heatmap_DecreaseOnlyButtonPushed(app, event)
            app.showHeatmapDecreaseOnly();
        end

        % Button pushed function: Heatmap_ResetButton
        function Heatmap_ResetButtonPushed(app, event)
            app.resetHeatmapDisplay();
        end

        % Button pushed function: Heatmap_SaveButton
        function Heatmap_SaveButtonPushed(app, event)
            if isempty(get(app.Heatmap_Analysis_Axes, 'Children'))
                uialert(app.UIFigure, 'No results to save. Please run analysis first.', 'Warning');
                return;
            end
            
            try
                [filename, pathname] = uiputfile({...
                    '*.png', 'PNG Image (*.png)';...
                    '*.jpg', 'JPEG Image (*.jpg)';...
                    '*.fig', 'MATLAB Figure (*.fig)'}, ...
                    'Save Heatmap Analysis Results');
                
                if filename ~= 0
                    fullpath = fullfile(pathname, filename);
                    [~, ~, ext] = fileparts(filename);
                    
                    if strcmpi(ext, '.fig')
                        % Save the entire figure
                        tempFig = figure('Visible', 'off');
                        copyobj(app.Heatmap_Analysis_Axes, tempFig);
                        savefig(tempFig, fullpath);
                        close(tempFig);
                    else
                        % Export the analysis axes as image
                        exportgraphics(app.Heatmap_Analysis_Axes, fullpath, 'Resolution', 300);
                    end
                    
                    app.updateHeatmapStatus(sprintf('Results saved to: %s', filename));
                    uialert(app.UIFigure, 'Results saved successfully!', 'Success', 'Icon', 'success');
                end
                
            catch ME
                uialert(app.UIFigure, ['Save failed: ' ME.message], 'Error');
            end
        end

        % Value changed function: SideBySide_Img1DropDown
        function SideBySide_Img1DropDownValueChanged(app, event)
            value = app.SideBySide_Img1DropDown.Value;
            
            % Check if images are loaded
            if isempty(app.CurrentLoadedTable) || strcmp(value, 'Please load images first')
                app.SideBySide_SelectedImg1 = 1;
                app.SideBySide_StatusLabel.Text = '❌ No images loaded';
                return;
            end
            
            % Find the selected image index
            idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
            if ~isempty(idx)
                app.SideBySide_SelectedImg1 = idx;
                app.updateSideBySideDisplay();
                
                syncStatus = 'enabled';
                if ~app.SideBySide_SyncEnabled
                    syncStatus = 'disabled';
                end
                app.SideBySide_StatusLabel.Text = sprintf('📷 Left: %s | Sync %s', value, syncStatus);
            else
                app.SideBySide_SelectedImg1 = 1;
                app.SideBySide_StatusLabel.Text = '❌ Left image selection failed';
            end
            
        end

        % Value changed function: SideBySide_Img2DropDown
        function SideBySide_Img2DropDownValueChanged(app, event)
            value = app.SideBySide_Img2DropDown.Value;
            
            % Check if images are loaded
            if isempty(app.CurrentLoadedTable) || strcmp(value, 'Please load images first')
                app.SideBySide_SelectedImg2 = 2;
                app.SideBySide_StatusLabel.Text = '❌ No images loaded';
                return;
            end
            
            % Find the selected image index
            idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
            if ~isempty(idx)
                app.SideBySide_SelectedImg2 = idx;
                app.updateSideBySideDisplay();
                
                syncStatus = 'enabled';
                if ~app.SideBySide_SyncEnabled
                    syncStatus = 'disabled';
                end
                app.SideBySide_StatusLabel.Text = sprintf('📷 Right: %s | Sync %s', value, syncStatus);
            else
                app.SideBySide_SelectedImg2 = 2;
                app.SideBySide_StatusLabel.Text = '❌ Right image selection failed';
            end
            
        end

        % Button pushed function: SideBySide_ZoomButton
        function SideBySide_ZoomButtonPushed(app, event)
            try
                % Turn off pan first
                pan(app.UIFigure, 'off');
                
                % Toggle zoom mode
                if strcmp(get(zoom(app.UIFigure), 'Enable'), 'on')
                    zoom(app.UIFigure, 'off');
                    app.SideBySide_StatusLabel.Text = '🔄 Zoom mode OFF | Navigate freely';
                else
                    zoom(app.UIFigure, 'on');
                    app.SideBySide_StatusLabel.Text = '🔍 Zoom mode ON | Click & drag to zoom';
                end
                
            catch ME
                app.SideBySide_StatusLabel.Text = ['❌ Zoom error: ' ME.message];
            end            
        end

        % Button pushed function: SideBySide_PanButton
        function SideBySide_PanButtonPushed(app, event)
            try
                % Turn off zoom first
                zoom(app.UIFigure, 'off');
                
                % Toggle pan mode
                if strcmp(get(pan(app.UIFigure), 'Enable'), 'on')
                    pan(app.UIFigure, 'off');
                    app.SideBySide_StatusLabel.Text = '🔄 Pan mode OFF | Navigate freely';
                else
                    pan(app.UIFigure, 'on');
                    app.SideBySide_StatusLabel.Text = '✋ Pan mode ON | Click & drag to pan';
                end
                
            catch ME
                app.SideBySide_StatusLabel.Text = ['❌ Pan error: ' ME.message];
            end
        end

        % Button pushed function: SideBySide_ResetButton
        function SideBySide_ResetButtonPushed(app, event)
            try
                app.resetSideBySideView();
                app.SideBySide_StatusLabel.Text = '🔄 View reset to default | Both axes refreshed';
                
            catch ME
                app.SideBySide_StatusLabel.Text = ['❌ Reset error: ' ME.message];
            end   
        end

        % Button pushed function: SideBySide_SaveButton
        function SideBySide_SaveButtonPushed(app, event)
            try
                app.saveSideBySideComparison();
                
            catch ME
                app.SideBySide_StatusLabel.Text = ['❌ Save error: ' ME.message];
                uialert(app.UIFigure, ['Save failed: ' ME.message], 'Error', 'Icon', 'error');
            end
        end

        % Value changed function: Timelapse_FrameSlider
        function Timelapse_FrameSliderValueChanged(app, event)
            frameNum = round(app.Timelapse_FrameSlider.Value);
            app.gotoTimelapseFrame(frameNum);
        end

        % Button pushed function: Timelapse_PrevButton
        function Timelapse_PrevButtonPushed(app, event)
            if app.Timelapse_CurrentFrame > 1
                app.gotoTimelapseFrame(app.Timelapse_CurrentFrame - 1);
            end
        end

        % Button pushed function: Timelapse_NextButton
        function Timelapse_NextButtonPushed(app, event)
            if app.Timelapse_CurrentFrame < app.Timelapse_TotalFrames
                app.gotoTimelapseFrame(app.Timelapse_CurrentFrame + 1);
            end
        end

        % Button pushed function: Timelapse_PlayButton
        function Timelapse_PlayButtonPushed(app, event)
            app.startTimelapsePlayback();  
        end

        % Button pushed function: Timelapse_PauseButton
        function Timelapse_PauseButtonPushed(app, event)
            app.stopTimelapsePlayback();
        end

        % Button pushed function: Timelapse_StopButton
        function Timelapse_StopButtonPushed(app, event)
            app.resetTimelapseToStart();
        end

        % Value changed function: Timelapse_SpeedSlider
        function Timelapse_SpeedSliderValueChanged(app, event)
            newSpeed = app.Timelapse_SpeedSlider.Value;
            app.updateTimelapseSpeed(newSpeed);
        end

        % Button pushed function: Timelapse_SaveGIFButton
        function Timelapse_SaveGIFButtonPushed(app, event)
            app.exportTimelapseGIF();
        end

        % Value changed function: Timelapse_LoopCheckbox
        function Timelapse_LoopCheckboxValueChanged(app, event)
            app.Timelapse_LoopEnabled = app.Timelapse_LoopCheckbox.Value;
        end

        % Value changed function: Flicker_Img1DropDown
        function Flicker_Img1DropDownValueChanged(app, event)
            value = app.Flicker_Img1DropDown.Value;
            
            % Check if images are loaded
            if isempty(app.CurrentLoadedTable) || strcmp(value, 'Please load images first')
                app.Flicker_SelectedImg1 = 0;
                app.Flicker_StatusLabel.Text = 'Status: Please load images first';
                app.Flicker_StatusLabel.FontColor = [0.8, 0.5, 0];
                return;
            end
            
            % Find the selected image index
            idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
            if ~isempty(idx)
                app.Flicker_SelectedImg1 = idx;
                app.updateFlickerDisplay();
                app.Flicker_StatusLabel.Text = ['Status: Image A selected - ' value];
                app.Flicker_StatusLabel.FontColor = [0, 0.4, 0];
            else
                app.Flicker_SelectedImg1 = 0;
                app.Flicker_StatusLabel.Text = 'Status: Image A selection failed';
                app.Flicker_StatusLabel.FontColor = [0.8, 0, 0];
            end
        end

        % Value changed function: Flicker_Img2DropDown
        function Flicker_Img2DropDownValueChanged(app, event)
            value = app.Flicker_Img2DropDown.Value;
            
            % Check if images are loaded
            if isempty(app.CurrentLoadedTable) || strcmp(value, 'Please load images first')
                app.Flicker_SelectedImg2 = 0;
                app.Flicker_StatusLabel.Text = 'Status: Please load images first';
                app.Flicker_StatusLabel.FontColor = [0.8, 0.5, 0];
                return;
            end
            
            % Find the selected image index
            idx = find(strcmp(app.CurrentLoadedTable.Name, value), 1);
            if ~isempty(idx)
                app.Flicker_SelectedImg2 = idx;
                app.updateFlickerDisplay();
                app.Flicker_StatusLabel.Text = ['Status: Image B selected - ' value];
                app.Flicker_StatusLabel.FontColor = [0, 0.4, 0];
            else
                app.Flicker_SelectedImg2 = 0;
                app.Flicker_StatusLabel.Text = 'Status: Image B selection failed';
                app.Flicker_StatusLabel.FontColor = [0.8, 0, 0];
            end
        end

        % Value changed function: Flicker_SpeedSlider
        function Flicker_SpeedSliderValueChanged(app, event)
            app.Flicker_CurrentSpeed = app.Flicker_SpeedSlider.Value;
            app.Flicker_SpeedLabel.Text = sprintf('Speed: %.1fs', app.Flicker_CurrentSpeed);
            
            % Update timer if flicker is active
            if app.Flicker_IsFlickering
                app.initializeFlickerTimer();
                start(app.Flicker_Timer);
            end
        end

        % Value changed function: Flicker_ModeDropDown
        function Flicker_ModeDropDownValueChanged(app, event)
            value = app.Flicker_ModeDropDown.Value;
            switch value
                case 'Original ↔ Original'
                    app.Flicker_CurrentMode = "Original";
                case 'Original ↔ Highlight'
                    app.Flicker_CurrentMode = "Highlight";
                case 'Original ↔ Heatmap'
                    app.Flicker_CurrentMode = "Heatmap";
            end
            
            app.Flicker_StatusLabel.Text = ['Status: Mode changed to ' value];
            app.Flicker_StatusLabel.FontColor = [0, 0.4, 0];
        end

        % Value changed function: Flicker_FlickerToggle
        function Flicker_FlickerToggleValueChanged(app, event)
            if app.Flicker_FlickerToggle.Value
                % Start flicker
                try
                    if isempty(app.CroppedImages) || app.Flicker_SelectedImg1 == app.Flicker_SelectedImg2
                        uialert(app.UIFigure, 'Please load images and select two different images', 'Warning');
                        app.Flicker_FlickerToggle.Value = false;
                        return;
                    end
                    
                    app.Flicker_IsFlickering = true;
                    app.Flicker_FlickerToggle.Text = '⏹️ Stop Flicker';
                    app.Flicker_FlickerToggle.BackgroundColor = [0.8 0.2 0.2];
                    
                    app.initializeFlickerTimer();
                    start(app.Flicker_Timer);
                    
                    app.Flicker_StatusLabel.Text = 'Status: Flicker started';
                    app.Flicker_StatusLabel.FontColor = [0, 0.4, 0];
                    
                catch ME
                    app.Flicker_FlickerToggle.Value = false;
                    app.Flicker_StatusLabel.Text = ['Status: Error - ' ME.message];
                    app.Flicker_StatusLabel.FontColor = [0.8, 0, 0];
                    uialert(app.UIFigure, ['Failed to start flicker: ' ME.message], 'Error');
                end
            else
                % Stop flicker
                app.stopFlicker();
            end
        end

        % Button pushed function: Flicker_ResetButton
        function Flicker_ResetButtonPushed(app, event)
            try
                app.resetFlickerInterface();
            catch ME
                app.Flicker_StatusLabel.Text = ['Status: Reset error - ' ME.message];
                app.Flicker_StatusLabel.FontColor = [0.8, 0, 0];
                uialert(app.UIFigure, ['Reset failed: ' ME.message], 'Error');
            end
        end

        % Button pushed function: Flicker_SaveButton
        function Flicker_SaveButtonPushed(app, event)
            try
                % Get current frame from flicker display
                imgHandle = findobj(app.Flicker_Display_Axes, 'Type', 'image');
                if isempty(imgHandle)
                    uialert(app.UIFigure, 'No image to save. Please start flicker first.', 'Warning');
                    return;
                end
                
                % Open save dialog
                [file, path] = uiputfile({'*.png','PNG files (*.png)';...
                                         '*.jpg','JPEG files (*.jpg)';...
                                         '*.fig','MATLAB Figure (*.fig)'},...
                                         'Save Flicker Frame');
                
                if isequal(file, 0)
                    return; % User cancelled
                end
                
                fullPath = fullfile(path, file);
                [~, ~, ext] = fileparts(file);
                
                switch lower(ext)
                    case '.fig'
                        % Save as MATLAB figure
                        tempFig = figure('Visible', 'off');
                        tempAxes = axes(tempFig);
                        copyobj(allchild(app.Flicker_Display_Axes), tempAxes);
                        title(tempAxes, get(get(app.Flicker_Display_Axes, 'Title'), 'String'));
                        savefig(tempFig, fullPath);
                        close(tempFig);
                    otherwise
                        % Save as image
                        imgData = imgHandle.CData;
                        imwrite(imgData, fullPath);
                end
                
                app.Flicker_StatusLabel.Text = 'Status: Frame saved successfully';
                app.Flicker_StatusLabel.FontColor = [0, 0.4, 0];
                
            catch ME
                app.Flicker_StatusLabel.Text = ['Status: Save error - ' ME.message];
                app.Flicker_StatusLabel.FontColor = [0.8, 0, 0];
                uialert(app.UIFigure, ['Save failed: ' ME.message], 'Error');
            end
        end

        % Value changed function: Heatmap_ComprehensiveToggle
        function Heatmap_ComprehensiveToggleValueChanged(app, event)

         if app.Heatmap_ComprehensiveToggle.Value
            app.Heatmap_IsComprehensiveMode = true;
            app.Heatmap_IsHeatmapOn = false;
            app.Heatmap_IsHighlightOn = false;
            app.Heatmap_IsIncreaseOnlyOn = false;
            app.Heatmap_IsDecreaseOnlyOn = false;
            app.Heatmap_ComprehensiveToggle.Text = 'Hide Superimposed';
            app.Heatmap_HeatmapToggle.Value = 0;
            app.Heatmap_HeatmapToggle.Text = 'Basic Heatmap';
            app.Heatmap_showComprehensiveMap();
        else
            app.Heatmap_IsComprehensiveMode = false;
            app.Heatmap_ComprehensiveToggle.Text  = 'Superimposed Map';
            axes(app.Heatmap_Analysis_Axes);
            cla(app.Heatmap_Analysis_Axes);
            title(app.Heatmap_Analysis_Axes, 'Difference Analysis Results', 'FontSize', 13, 'FontWeight', 'bold');
            app.updateHeatmapLamps(false, false);
        end
        end

        % Value changed function: FlickerThickBoarder
        function FlickerThickBoarderValueChanged(app, event)
            if app.FlickerThickBoarder.Value
                app.Flicker_Thick_Boarder = true;
            else 
                app.Flicker_Thick_Boarder = false;
            end
        end

        % Value changed function: SideBySide_SyncButton
        function SideBySide_SyncButtonValueChanged(app, event)
            app.SideBySide_SyncEnabled = app.SideBySide_SyncButton.Value;
            
            if app.SideBySide_SyncEnabled
                app.SideBySide_SyncButton.Text = '🔄 Sync: ON';
                app.SideBySide_SyncButton.BackgroundColor = [0.7 1 0.7];
                app.SideBySide_SyncButton.FontColor = [0.1 0.5 0.1];
                app.SideBySide_StatusLabel.Text = '🔄 Synchronization enabled - Zoom & pan linked';
            else
                app.SideBySide_SyncButton.Text = '🚫 Sync: OFF';
                app.SideBySide_SyncButton.BackgroundColor = [1 0.7 0.7];
                app.SideBySide_SyncButton.FontColor = [0.7 0.1 0.1];
                app.SideBySide_StatusLabel.Text = '🚫 Synchronization disabled - Independent navigation';
            end
            
            % Update info text
            app.updateSideBySideInfoText();
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.SliderViewTab
                % Turn off zoom and pan modes
                try
                    app.resetSideBySideView();
                    app.SideBySide_StatusLabel.Text = '🔄 View reset to default | Both axes refreshed';
                    
                catch ME
                    app.SideBySide_StatusLabel.Text = ['❌ Reset error: ' ME.message];
                end   
            end
        end

        % Value changed function: AlternativeProcessingCheckbox
        function AlternativeProcessingCheckboxValueChanged(app, event)
            app.StatusReadyLabel.Text = sprintf("Status: Please wait - loading and preprocessing %s...", app.CurrentLoadedTable.Properties.UserData);drawnow;
            app.displayDataset(app.CurrentLoadedTable);
            app.preproccessImages();
            app.updateDataset();
            app.StatusReadyLabel.Text = sprintf("Status: Image Set %s loaded. Ready", app.CurrentLoadedTable.Properties.UserData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout11 = uigridlayout(app.Panel);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.Image = uiimage(app.GridLayout12);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'gui', 'img', 'title.svg');
            app.GridLayout13 = uigridlayout(app.GridLayout11);
            app.TabGroup2 = uitabgroup(app.GridLayout13);
            app.OverviewTab = uitab(app.TabGroup2);
            app.OverviewTab.Title = 'Overview';
            app.GridLayout14 = uigridlayout(app.OverviewTab);
            app.SelectInputFilesButton = uibutton(app.GridLayout14, 'push');
            app.SelectInputFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectInputFilesButtonPushed, true);
            app.SelectInputFilesButton.Text = '📁 Select Input Files';
            app.Step1DataImportLabel = uilabel(app.GridLayout14);
            app.Step1DataImportLabel.Text = '📊 Step 1 - Data Import';
            app.Step2RangeSelectionLabel = uilabel(app.GridLayout14);
            app.Step2RangeSelectionLabel.Text = '🔍 Step 2 - Range Selection';
            app.GridLayout21 = uigridlayout(app.GridLayout14);
            app.Label = uilabel(app.GridLayout21);
            app.Label.Text = '';
            app.StartImageDropDown = uidropdown(app.GridLayout21);
            app.StartImageDropDown.Items = {};
            app.StartImageDropDown.ValueChangedFcn = createCallbackFcn(app, @StartImageDropDownValueChanged, true);
            app.StartImageDropDown.Placeholder = 'Select First / Start Image';
            app.StartImageDropDown.Value = {};
            app.DropDown_2Label = uilabel(app.GridLayout21);
            app.DropDown_2Label.Text = '';
            app.EndImageDropDown = uidropdown(app.GridLayout21);
            app.EndImageDropDown.Items = {};
            app.EndImageDropDown.ValueChangedFcn = createCallbackFcn(app, @EndImageDropDownValueChanged, true);
            app.EndImageDropDown.Placeholder = 'Select Second / End Image';
            app.EndImageDropDown.Value = {};
            app.Step3VisualizeLabel = uilabel(app.GridLayout14);
            app.Step3VisualizeLabel.Text = '🗺️Step 3 - Visualize !';
            app.GridLayout36 = uigridlayout(app.GridLayout14);
            app.ClickavisualizationtabatthetopLabel = uilabel(app.GridLayout36);
            app.ClickavisualizationtabatthetopLabel.Text = 'Click a visualization tab at the top';
            app.UsethecontrolsonthelefttoexploreandcomparethedataLabel = uilabel(app.GridLayout36);
            app.UsethecontrolsonthelefttoexploreandcomparethedataLabel.Text = 'Use the controls on the left to explore and compare the data';
            app.UsethecontrolsonthelefttoexploreandcomparethedataLabel_2 = uilabel(app.GridLayout36);
            app.UsethecontrolsonthelefttoexploreandcomparethedataLabel_2.Text = 'Use Range Selection and Files tabs to select or exclude images.';
            app.FilesTab_2 = uitab(app.TabGroup2);
            app.FilesTab_2.Title = 'Files';
            app.GridLayout15 = uigridlayout(app.FilesTab_2);
            app.LoadedFilesLabel = uilabel(app.GridLayout15);
            app.LoadedFilesLabel.Text = 'Loaded Files: ';
            app.FileTree = uitree(app.GridLayout15, 'checkbox');
            app.FileTree.SelectionChangedFcn = createCallbackFcn(app, @FileTreeSelectionChanged, true);
            app.NoFilesLoadedNode = uitreenode(app.FileTree);
            app.NoFilesLoadedNode.Text = 'No Files Loaded';
            % Assign Checked Nodes
            app.FileTree.CheckedNodesChangedFcn = createCallbackFcn(app, @FileTreeCheckedNodesChanged, true);
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout14_2 = uigridlayout(app.SettingsTab);
            app.SettingsLabel = uilabel(app.GridLayout14_2);
            app.SettingsLabel.Text = 'Settings';
            app.SettingsLabel_2 = uilabel(app.GridLayout14_2);
            app.SettingsLabel_2.Text = 'Settings: ';
            app.FlickerThickBoarder = uicheckbox(app.GridLayout14_2);
            app.FlickerThickBoarder.ValueChangedFcn = createCallbackFcn(app, @FlickerThickBoarderValueChanged, true);
            app.FlickerThickBoarder.Text = 'Highlight Thick Boarder';
            app.FlickerThickBoarder.Value = true;
            app.FlickerViewLabel = uilabel(app.GridLayout14_2);
            app.FlickerViewLabel.Text = 'Flicker View';
            app.ImageProcessingLabel = uilabel(app.GridLayout14_2);
            app.ImageProcessingLabel.Text = 'Image Processing';
            app.AlternativeProcessingCheckbox = uicheckbox(app.GridLayout14_2);
            app.AlternativeProcessingCheckbox.ValueChangedFcn = createCallbackFcn(app, @AlternativeProcessingCheckboxValueChanged, true);
            app.AlternativeProcessingCheckbox.Text = 'Use Alternative Processing';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.EarthViewerGUIGroup25Label = uilabel(app.GridLayout3);
            app.EarthViewerGUIGroup25Label.Text = '🌍 EarthViewer GUI - Group25';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DashboardTab = uitab(app.TabGroup);
            app.DashboardTab.Title = '📊 Dashboard';
            app.GridLayout4 = uigridlayout(app.DashboardTab);
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.ImagesLoadedLabel = uilabel(app.GridLayout7);
            app.ImagesLoadedLabel.Text = 'Images Loaded';
            app.ImagesLabel = uilabel(app.GridLayout7);
            app.ImagesLabel.Text = '-- Images';
            app.GridLayout7_2 = uigridlayout(app.GridLayout6);
            app.TimespanCoverageLabel = uilabel(app.GridLayout7_2);
            app.TimespanCoverageLabel.Text = 'Timespan Coverage';
            app.YearsLabel = uilabel(app.GridLayout7_2);
            app.YearsLabel.Text = '-- Years';
            app.GridLayout7_3 = uigridlayout(app.GridLayout6);
            app.AverageIntervalLabel = uilabel(app.GridLayout7_3);
            app.AverageIntervalLabel.Text = 'Average Interval';
            app.MonthLabel = uilabel(app.GridLayout7_3);
            app.MonthLabel.Text = '-- Month';
            app.GridLayout7_4 = uigridlayout(app.GridLayout6);
            app.ProcessedResolutionLabel = uilabel(app.GridLayout7_4);
            app.ProcessedResolutionLabel.Text = 'Processed Resolution';
            app.ImageSizeLabel = uilabel(app.GridLayout7_4);
            app.ImageSizeLabel.Text = '---- x ----';
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.Panel_3 = uipanel(app.GridLayout9);
            app.GridLayout16_2 = uigridlayout(app.Panel_3);
            app.DatasetInformationLabel = uilabel(app.GridLayout16_2);
            app.DatasetInformationLabel.Text = '📋 Dataset Information';
            app.GridLayout22 = uigridlayout(app.GridLayout16_2);
            app.ImageSetNameTagLabel = uilabel(app.GridLayout22);
            app.ImageSetNameTagLabel.Text = 'Image Set Name:';
            app.ImageSetNameLabel = uilabel(app.GridLayout22);
            app.ImageSetNameLabel.Text = 'No Files Loaded';
            app.Panel_4 = uipanel(app.GridLayout9);
            app.GridLayout16_3 = uigridlayout(app.Panel_4);
            app.PreviewSettingsLabel = uilabel(app.GridLayout16_3);
            app.PreviewSettingsLabel.Text = '🔧 Preview Settings';
            app.GridLayout18 = uigridlayout(app.GridLayout16_3);
            app.ImageSelectDropDownLabel = uilabel(app.GridLayout18);
            app.ImageSelectDropDownLabel.Text = 'Image Select';
            app.ImageSelectDropDown = uidropdown(app.GridLayout18);
            app.ImageSelectDropDown.Items = {};
            app.ImageSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageSelectDropDownValueChanged, true);
            app.ImageSelectDropDown.Placeholder = 'None Selected';
            app.ImageSelectDropDown.Value = {};
            app.PreviewModeDropDownLabel = uilabel(app.GridLayout18);
            app.PreviewModeDropDownLabel.Text = 'Preview Mode';
            app.PreviewModeDropDown = uidropdown(app.GridLayout18);
            app.PreviewModeDropDown.Items = {'🔸 Original', '🔹 Aligned', '✂️ Cropped'};
            app.PreviewModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PreviewModeDropDownValueChanged, true);
            app.PreviewModeDropDown.Value = '🔸 Original';
            app.QuickSelectSliderLabel = uilabel(app.GridLayout18);
            app.QuickSelectSliderLabel.Text = 'Quick Select';
            app.QuickSelectSlider = uislider(app.GridLayout18);
            app.QuickSelectSlider.ValueChangingFcn = createCallbackFcn(app, @QuickSelectSliderValueChanging, true);
            app.Panel_2 = uipanel(app.GridLayout8);
            app.GridLayout16 = uigridlayout(app.Panel_2);
            app.GridLayoutImagePreview = uigridlayout(app.GridLayout16);
            app.UIAxesImagePreview = uiaxes(app.GridLayoutImagePreview);
            app.UIAxesImagePreview.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.GridLayout19 = uigridlayout(app.GridLayout16);
            app.ImagePreviewLabel = uilabel(app.GridLayout19);
            app.ImagePreviewLabel.Text = '🖼️ Image Preview';
            app.PreviewFilenameLabel = uilabel(app.GridLayout19);
            app.PreviewFilenameLabel.Text = ' ';
            app.GridLayout10 = uigridlayout(app.GridLayout4);
            app.Panel_5 = uipanel(app.GridLayout10);
            app.GridLayout16_4 = uigridlayout(app.Panel_5);
            app.GridLayoutStartImage = uigridlayout(app.GridLayout16_4);
            app.GridLayout20 = uigridlayout(app.GridLayout16_4);
            app.StartImageLabel = uilabel(app.GridLayout20);
            app.StartImageLabel.Text = '🎯 Start Image';
            app.StartImgFilenameLabel = uilabel(app.GridLayout20);
            app.StartImgFilenameLabel.Text = ' ';
            app.Panel_6 = uipanel(app.GridLayout10);
            app.GridLayout16_5 = uigridlayout(app.Panel_6);
            app.GridLayoutEndImage = uigridlayout(app.GridLayout16_5);
            app.GridLayout20_2 = uigridlayout(app.GridLayout16_5);
            app.EndImageLabel = uilabel(app.GridLayout20_2);
            app.EndImageLabel.Text = '🏁 End Image';
            app.EndImgFilenameLabel = uilabel(app.GridLayout20_2);
            app.EndImgFilenameLabel.Text = ' ';
            app.Panel_7 = uipanel(app.GridLayout10);
            app.GridLayout16_6 = uigridlayout(app.Panel_7);
            app.GUIInfoLabel = uilabel(app.GridLayout16_6);
            app.GUIInfoLabel.Text = '🌍 GUI Info';
            app.GridLayout37 = uigridlayout(app.GridLayout16_6);
            app.StatusReadyLabel = uilabel(app.GridLayout37);
            app.StatusReadyLabel.Text = 'Status: Ready';
            app.SidebySideTab = uitab(app.TabGroup);
            app.SidebySideTab.Title = '🔀 Side-by-Side';
            app.SideBySide_GridLayout1 = uigridlayout(app.SidebySideTab);
            app.SideBySideTitleBar = uipanel(app.SideBySide_GridLayout1);
            app.SideBySideTitleLayout = uigridlayout(app.SideBySideTitleBar);
            app.SideBySide_StatusLabel = uilabel(app.SideBySideTitleLayout);
            app.SideBySide_StatusLabel.Text = '🔄 Synchronization enabled - Zoom & pan linked';
            app.SideBySideMainTitle = uilabel(app.SideBySideTitleLayout);
            app.SideBySideMainTitle.Text = '🔀 Synchronized Side-by-Side Comparison';
            app.SideBySide_GridLayout2 = uigridlayout(app.SideBySide_GridLayout1);
            app.SideBySide_Panel_Main = uipanel(app.SideBySide_GridLayout2);
            app.SideBySideMainLayout = uigridlayout(app.SideBySide_Panel_Main);
            app.SideBySideDisplayLayout = uigridlayout(app.SideBySideMainLayout);
            app.SideBySideDivider = uipanel(app.SideBySideDisplayLayout);
            app.GridLayout34 = uigridlayout(app.SideBySideDisplayLayout);
            app.SideBySide_LeftAxes = uiaxes(app.GridLayout34);
            title(app.SideBySide_LeftAxes, 'Load images to compare')
            app.SideBySide_LeftAxes.XTick = [];
            app.SideBySide_LeftAxes.YTick = [];
            app.SideBySideLeftTitle = uilabel(app.GridLayout34);
            app.SideBySideLeftTitle.Text = '📷 Left Image';
            app.GridLayout34_2 = uigridlayout(app.SideBySideDisplayLayout);
            app.SideBySide_RightAxes = uiaxes(app.GridLayout34_2);
            title(app.SideBySide_RightAxes, 'Load images to compare')
            app.SideBySide_RightAxes.XTick = [];
            app.SideBySide_RightAxes.YTick = [];
            app.SideBySideRightTitle = uilabel(app.GridLayout34_2);
            app.SideBySideRightTitle.Text = '📷 Right Image';
            app.SideBySide_Panel_Control = uipanel(app.SideBySide_GridLayout2);
            app.SideBySideControlLayout = uigridlayout(app.SideBySide_Panel_Control);
            app.GridLayout27_3 = uigridlayout(app.SideBySideControlLayout);
            app.SideBySide_SaveButton = uibutton(app.GridLayout27_3, 'push');
            app.SideBySide_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SideBySide_SaveButtonPushed, true);
            app.SideBySide_SaveButton.Text = '💾 Save as JPG';
            app.SideBySide_SyncButton = uibutton(app.GridLayout27_3, 'state');
            app.SideBySide_SyncButton.ValueChangedFcn = createCallbackFcn(app, @SideBySide_SyncButtonValueChanged, true);
            app.SideBySide_SyncButton.Text = '🔄 Sync: ON';
            app.SideBySide_SyncButton.Value = true;
            app.GridLayout25_9 = uigridlayout(app.SideBySideControlLayout);
            app.SideBySideControlDivider3 = uipanel(app.GridLayout25_9);
            app.SideBySideNavLayout = uigridlayout(app.SideBySideControlLayout);
            app.SideBySide_ResetButton = uibutton(app.SideBySideNavLayout, 'push');
            app.SideBySide_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @SideBySide_ResetButtonPushed, true);
            app.SideBySide_ResetButton.Text = '🔄 Reset View';
            app.SideBySide_PanButton = uibutton(app.SideBySideNavLayout, 'push');
            app.SideBySide_PanButton.ButtonPushedFcn = createCallbackFcn(app, @SideBySide_PanButtonPushed, true);
            app.SideBySide_PanButton.Text = '✋ Pan';
            app.SideBySide_ZoomButton = uibutton(app.SideBySideNavLayout, 'push');
            app.SideBySide_ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @SideBySide_ZoomButtonPushed, true);
            app.SideBySide_ZoomButton.Text = '🔍 Zoom';
            app.GridLayout25_8 = uigridlayout(app.SideBySideControlLayout);
            app.SideBySideControlDivider2 = uipanel(app.GridLayout25_8);
            app.GridLayout24_3 = uigridlayout(app.SideBySideControlLayout);
            app.Label_11 = uilabel(app.GridLayout24_3);
            app.Label_11.Text = '';
            app.DropDown_2Label_4 = uilabel(app.GridLayout24_3);
            app.DropDown_2Label_4.Text = '';
            app.SideBySide_Img2DropDown = uidropdown(app.GridLayout24_3);
            app.SideBySide_Img2DropDown.Items = {};
            app.SideBySide_Img2DropDown.ValueChangedFcn = createCallbackFcn(app, @SideBySide_Img2DropDownValueChanged, true);
            app.SideBySide_Img2DropDown.Placeholder = 'Select Right Image';
            app.SideBySide_Img2DropDown.Value = {};
            app.SideBySide_Img1DropDown = uidropdown(app.GridLayout24_3);
            app.SideBySide_Img1DropDown.Items = {''};
            app.SideBySide_Img1DropDown.ValueChangedFcn = createCallbackFcn(app, @SideBySide_Img1DropDownValueChanged, true);
            app.SideBySide_Img1DropDown.Placeholder = 'Select Left Image';
            app.SideBySide_Img1DropDown.Value = '';
            app.GridLayout25_7 = uigridlayout(app.SideBySideControlLayout);
            app.SideBySideControlDivider1 = uipanel(app.GridLayout25_7);
            app.SideBySideControlTitle = uilabel(app.SideBySideControlLayout);
            app.SideBySideControlTitle.Text = '🎛️ View Controls';
            app.SideBySideBottomBar = uipanel(app.SideBySide_GridLayout2);
            app.SideBySideBottomLayout = uigridlayout(app.SideBySideBottomBar);
            app.SideBySide_InfoTextArea = uitextarea(app.SideBySideBottomLayout);
            app.SideBySide_InfoTextArea.Editable = 'off';
            app.TimelapseTab = uitab(app.TabGroup);
            app.TimelapseTab.Title = '⏱️ Timelapse';
            app.Timelapse_GridLayout1 = uigridlayout(app.TimelapseTab);
            app.TimelapseTitleBar = uipanel(app.Timelapse_GridLayout1);
            app.TimelapseTitleLayout = uigridlayout(app.TimelapseTitleBar);
            app.Timelapse_StatusLabel = uilabel(app.TimelapseTitleLayout);
            app.Timelapse_StatusLabel.Text = '📽️ Ready for animation playback';
            app.TimelapseMainTitle = uilabel(app.TimelapseTitleLayout);
            app.TimelapseMainTitle.Text = '⏱️ Time-Lapse Animation Player';
            app.GridLayout8_8 = uigridlayout(app.Timelapse_GridLayout1);
            app.Timelapse_Panel_Main = uipanel(app.GridLayout8_8);
            app.TimelapseMainLayout = uigridlayout(app.Timelapse_Panel_Main);
            app.Timelapse_MainAxes = uiaxes(app.TimelapseMainLayout);
            app.Timelapse_MainAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Timelapse_FrameLabel = uilabel(app.TimelapseMainLayout);
            app.Timelapse_FrameLabel.Text = '📊 Frame: 1 / 0 - Load images from Dashboard';
            app.Timelapse_Panel_Control = uipanel(app.GridLayout8_8);
            app.TimelapseControlLayout = uigridlayout(app.Timelapse_Panel_Control);
            app.GridLayout25_4 = uigridlayout(app.TimelapseControlLayout);
            app.TimelapseDivider1 = uipanel(app.GridLayout25_4);
            app.TimelapseControlTitle = uilabel(app.TimelapseControlLayout);
            app.TimelapseControlTitle.Text = '🎮 Playback Controls';
            app.GridLayout25_11 = uigridlayout(app.TimelapseControlLayout);
            app.TimelapseDivider4 = uipanel(app.GridLayout25_11);
            app.GridLayout25_10 = uigridlayout(app.TimelapseControlLayout);
            app.Panel_77 = uipanel(app.GridLayout25_10);
            app.Timelapse_SaveGIFButton = uibutton(app.TimelapseControlLayout, 'push');
            app.Timelapse_SaveGIFButton.ButtonPushedFcn = createCallbackFcn(app, @Timelapse_SaveGIFButtonPushed, true);
            app.Timelapse_SaveGIFButton.Text = '💾 Export as GIF';
            app.GridLayout35 = uigridlayout(app.TimelapseControlLayout);
            app.Timelapse_LoopCheckbox = uicheckbox(app.GridLayout35);
            app.Timelapse_LoopCheckbox.ValueChangedFcn = createCallbackFcn(app, @Timelapse_LoopCheckboxValueChanged, true);
            app.Timelapse_LoopCheckbox.Text = '🔄 Loop Playback';
            app.Timelapse_LoopCheckbox.Value = true;
            app.Timelapse_SpeedLabel = uilabel(app.GridLayout35);
            app.Timelapse_SpeedLabel.Text = '🏃 Speed: 2.0 fps';
            app.Timelapse_SpeedSlider = uislider(app.TimelapseControlLayout);
            app.Timelapse_SpeedSlider.Limits = [0.5 10];
            app.Timelapse_SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @Timelapse_SpeedSliderValueChanged, true);
            app.Timelapse_SpeedSlider.Value = 2;
            app.TimelapsePlayLayout = uigridlayout(app.TimelapseControlLayout);
            app.Timelapse_StopButton = uibutton(app.TimelapsePlayLayout, 'push');
            app.Timelapse_StopButton.ButtonPushedFcn = createCallbackFcn(app, @Timelapse_StopButtonPushed, true);
            app.Timelapse_StopButton.Text = '⏹ Stop';
            app.Timelapse_PauseButton = uibutton(app.TimelapsePlayLayout, 'push');
            app.Timelapse_PauseButton.ButtonPushedFcn = createCallbackFcn(app, @Timelapse_PauseButtonPushed, true);
            app.Timelapse_PauseButton.Text = '⏸ Pause';
            app.Timelapse_PlayButton = uibutton(app.TimelapsePlayLayout, 'push');
            app.Timelapse_PlayButton.ButtonPushedFcn = createCallbackFcn(app, @Timelapse_PlayButtonPushed, true);
            app.Timelapse_PlayButton.Text = '▶ Play';
            app.TimelapseNavLayout = uigridlayout(app.TimelapseControlLayout);
            app.Timelapse_NextButton = uibutton(app.TimelapseNavLayout, 'push');
            app.Timelapse_NextButton.ButtonPushedFcn = createCallbackFcn(app, @Timelapse_NextButtonPushed, true);
            app.Timelapse_NextButton.Text = 'Next ▶';
            app.Timelapse_PrevButton = uibutton(app.TimelapseNavLayout, 'push');
            app.Timelapse_PrevButton.ButtonPushedFcn = createCallbackFcn(app, @Timelapse_PrevButtonPushed, true);
            app.Timelapse_PrevButton.Text = '◀ Prev';
            app.GridLayout25_5 = uigridlayout(app.TimelapseControlLayout);
            app.Panel_62 = uipanel(app.GridLayout25_5);
            app.Timelapse_FrameSlider = uislider(app.TimelapseControlLayout);
            app.Timelapse_FrameSlider.Limits = [1 2];
            app.Timelapse_FrameSlider.ValueChangedFcn = createCallbackFcn(app, @Timelapse_FrameSliderValueChanged, true);
            app.Timelapse_FrameSlider.Value = 1;
            app.TimelapseBottomLayout = uigridlayout(app.GridLayout8_8);
            app.Timelapse_InfoTextArea = uitextarea(app.TimelapseBottomLayout);
            app.Timelapse_InfoTextArea.Editable = 'off';
            app.Timelapse_InfoTextArea.Value = {'📽️ Time-Lapse Animation Player - Load images from Dashboard to begin'; '  • Use playback controls to navigate through image sequence'; '  • Adjust speed slider to control animation speed (0.5-10 fps)'; '  • Enable loop playback for continuous animation'; '  • Export animation as GIF file for sharing'''};
            app.SliderViewTab = uitab(app.TabGroup);
            app.SliderViewTab.Title = '🎚️ Slider View';
            app.GridLayout4_2 = uigridlayout(app.SliderViewTab);
            app.SliderTitleBar = uipanel(app.GridLayout4_2);
            app.SliderTitleLayout = uigridlayout(app.SliderTitleBar);
            app.ClickanddragtocontrolcurtainpositionLabel = uilabel(app.SliderTitleLayout);
            app.ClickanddragtocontrolcurtainpositionLabel.Text = '🖱️Click and drag to control curtain position';
            app.SliderMainTitle = uilabel(app.SliderTitleLayout);
            app.SliderMainTitle.Text = '🎚️ Interactive Curtain Slider';
            app.GridLayout8_2 = uigridlayout(app.GridLayout4_2);
            app.Panel_39 = uipanel(app.GridLayout8_2);
            app.SliderMainLayout = uigridlayout(app.Panel_39);
            app.Slider_MainAxes = uiaxes(app.SliderMainLayout);
            title(app.Slider_MainAxes, 'Curtain Slider Display - Select images to begin')
            app.Slider_MainAxes.XTick = [];
            app.Slider_MainAxes.YTick = [];
            app.Slider_StatusLabel = uilabel(app.SliderMainLayout);
            app.Slider_StatusLabel.Text = '✅ Ready for comparison - Load images from Dashboard';
            app.Panel_40 = uipanel(app.GridLayout8_2);
            app.GridLayout23 = uigridlayout(app.Panel_40);
            app.SliderControlsLabel = uilabel(app.GridLayout23);
            app.SliderControlsLabel.Text = '⚙️ Slider Controls';
            app.GridLayout24 = uigridlayout(app.GridLayout23);
            app.Label_3 = uilabel(app.GridLayout24);
            app.Label_3.Text = '';
            app.Slider_Img1DropDown = uidropdown(app.GridLayout24);
            app.Slider_Img1DropDown.Items = {''};
            app.Slider_Img1DropDown.ValueChangedFcn = createCallbackFcn(app, @Slider_Img1DropDownValueChanged, true);
            app.Slider_Img1DropDown.Placeholder = 'Select Left Image';
            app.Slider_Img1DropDown.Value = '';
            app.DropDown_2Label_2 = uilabel(app.GridLayout24);
            app.DropDown_2Label_2.Text = '';
            app.Slider_Img2DropDown = uidropdown(app.GridLayout24);
            app.Slider_Img2DropDown.Items = {};
            app.Slider_Img2DropDown.ValueChangedFcn = createCallbackFcn(app, @Slider_Img2DropDownValueChanged, true);
            app.Slider_Img2DropDown.Placeholder = 'Select Right Image';
            app.Slider_Img2DropDown.Value = {};
            app.GridLayout25 = uigridlayout(app.GridLayout23);
            app.Panel_41 = uipanel(app.GridLayout25);
            app.GridLayout25_2 = uigridlayout(app.GridLayout23);
            app.Panel_42 = uipanel(app.GridLayout25_2);
            app.GridLayout25_3 = uigridlayout(app.GridLayout23);
            app.Panel_43 = uipanel(app.GridLayout25_3);
            app.SliderPositionCardLayout = uigridlayout(app.GridLayout23);
            app.Slider_ResetLeftButton = uibutton(app.SliderPositionCardLayout, 'push');
            app.Slider_ResetLeftButton.ButtonPushedFcn = createCallbackFcn(app, @Slider_ResetLeftButtonPushed, true);
            app.Slider_ResetLeftButton.Text = '◀ Show Left';
            app.Slider_ResetCenterButton = uibutton(app.SliderPositionCardLayout, 'push');
            app.Slider_ResetCenterButton.ButtonPushedFcn = createCallbackFcn(app, @Slider_ResetCenterButtonPushed, true);
            app.Slider_ResetCenterButton.Text = '⚌ Center';
            app.Slider_ResetRightButton = uibutton(app.SliderPositionCardLayout, 'push');
            app.Slider_ResetRightButton.ButtonPushedFcn = createCallbackFcn(app, @Slider_ResetRightButtonPushed, true);
            app.Slider_ResetRightButton.Text = 'Show Right ▶';
            app.GridLayout27 = uigridlayout(app.GridLayout23);
            app.Slider_AutoScanButton = uibutton(app.GridLayout27, 'push');
            app.Slider_AutoScanButton.ButtonPushedFcn = createCallbackFcn(app, @Slider_AutoScanButtonPushed, true);
            app.Slider_AutoScanButton.Text = '🔄 Auto Scan';
            app.Slider_SaveGIFButton = uibutton(app.GridLayout27, 'push');
            app.Slider_SaveGIFButton.ButtonPushedFcn = createCallbackFcn(app, @Slider_SaveGIFButtonPushed, true);
            app.Slider_SaveGIFButton.Text = '💾 Export as GIF';
            app.SliderInstructionCard = uilabel(app.GridLayout27);
            app.SliderInstructionCard.Text = '💡 Interactive Controls: Click & drag image to move curtain';
            app.DifferenceHighlightTab = uitab(app.TabGroup);
            app.DifferenceHighlightTab.Title = '🔍 Difference Highlight';
            app.GridLayout4_3 = uigridlayout(app.DifferenceHighlightTab);
            app.GridLayout4_8 = uigridlayout(app.GridLayout4_3);
            app.Panel_44 = uipanel(app.GridLayout4_8);
            app.DiffHighlightMainLayout = uigridlayout(app.Panel_44);
            app.DiffHighlight_Analysis_Axes = uiaxes(app.DiffHighlightMainLayout);
            title(app.DiffHighlight_Analysis_Axes, '🔍 Difference Highlight Analysis Results')
            app.DiffHighlight_Analysis_Axes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Panel_45 = uipanel(app.GridLayout4_8);
            app.GridLayout28 = uigridlayout(app.Panel_45);
            app.DiffHighlight_StatusLabel = uilabel(app.GridLayout28);
            app.DiffHighlight_StatusLabel.Text = 'Status: Ready for analysis';
            app.imgSelectionLabel = uilabel(app.GridLayout28);
            app.imgSelectionLabel.Text = '📷 Image Selection';
            app.GridLayout29 = uigridlayout(app.GridLayout28);
            app.Label_5 = uilabel(app.GridLayout29);
            app.Label_5.Text = '';
            app.DiffHighlight_Img2DropDown = uidropdown(app.GridLayout29);
            app.DiffHighlight_Img2DropDown.Items = {''};
            app.DiffHighlight_Img2DropDown.ValueChangedFcn = createCallbackFcn(app, @DiffHighlight_Img2DropDownValueChanged, true);
            app.DiffHighlight_Img2DropDown.Placeholder = 'Select Right Image';
            app.DiffHighlight_Img2DropDown.Value = '';
            app.Label_4 = uilabel(app.GridLayout29);
            app.Label_4.Text = '';
            app.DiffHighlight_Img1DropDown = uidropdown(app.GridLayout29);
            app.DiffHighlight_Img1DropDown.Items = {''};
            app.DiffHighlight_Img1DropDown.ValueChangedFcn = createCallbackFcn(app, @DiffHighlight_Img1DropDownValueChanged, true);
            app.DiffHighlight_Img1DropDown.Placeholder = 'Select Left Image';
            app.DiffHighlight_Img1DropDown.Value = '';
            app.thresholdLabel = uilabel(app.GridLayout28);
            app.thresholdLabel.Text = '⚙️ Threshold Settings';
            app.DiffHighlight_ThresholdSlider = uislider(app.GridLayout28);
            app.DiffHighlight_ThresholdSlider.Limits = [0 1];
            app.DiffHighlight_ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @DiffHighlight_ThresholdSliderValueChanged, true);
            app.DiffHighlight_ThresholdSlider.Value = 0.5;
            app.DiffHighlight_ThresholdLabel = uilabel(app.GridLayout28);
            app.DiffHighlight_ThresholdLabel.Text = 'Threshold: 0.10';
            app.analysisFuncLabel = uilabel(app.GridLayout28);
            app.analysisFuncLabel.Text = '🔬Analysis Functions';
            app.DiffHighlight_HighlightToggle = uibutton(app.GridLayout28, 'state');
            app.DiffHighlight_HighlightToggle.ValueChangedFcn = createCallbackFcn(app, @DiffHighlight_HighlightToggleValueChanged, true);
            app.DiffHighlight_HighlightToggle.Text = '✨ Show Highlight Differences';
            app.DiffDetailButtonLayout = uigridlayout(app.GridLayout28);
            app.DiffHighlight_DecreaseOnlyButton = uibutton(app.DiffDetailButtonLayout, 'push');
            app.DiffHighlight_DecreaseOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @DiffHighlight_DecreaseOnlyButtonPushed, true);
            app.DiffHighlight_DecreaseOnlyButton.Text = '📉 Decrease Only';
            app.DiffHighlight_IncreaseOnlyButton = uibutton(app.DiffDetailButtonLayout, 'push');
            app.DiffHighlight_IncreaseOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @DiffHighlight_IncreaseOnlyButtonPushed, true);
            app.DiffHighlight_IncreaseOnlyButton.Text = '📈 Increase Only';
            app.GridLayout10_8 = uigridlayout(app.GridLayout4_3);
            app.Panel_46 = uipanel(app.GridLayout10_8);
            app.GridLayout16_7 = uigridlayout(app.Panel_46);
            app.DiffHighlight_LeftImage_Grid = uigridlayout(app.GridLayout16_7);
            app.GridLayout20_3 = uigridlayout(app.GridLayout16_7);
            app.FirstImageLabel = uilabel(app.GridLayout20_3);
            app.FirstImageLabel.Text = 'First Image';
            app.DiffHighlight_LeftImage_Label = uilabel(app.GridLayout20_3);
            app.DiffHighlight_LeftImage_Label.Text = ' ';
            app.Panel_47 = uipanel(app.GridLayout10_8);
            app.GridLayout16_8 = uigridlayout(app.Panel_47);
            app.DiffHighlight_RightImage_Grid = uigridlayout(app.GridLayout16_8);
            app.GridLayout20_4 = uigridlayout(app.GridLayout16_8);
            app.SecondImageLabel = uilabel(app.GridLayout20_4);
            app.SecondImageLabel.Text = 'Second Image';
            app.DiffHighlight_RightImage_Label = uilabel(app.GridLayout20_4);
            app.DiffHighlight_RightImage_Label.Text = ' ';
            app.Panel_48 = uipanel(app.GridLayout10_8);
            app.GridLayout16_9 = uigridlayout(app.Panel_48);
            app.OperationToolsLabel = uilabel(app.GridLayout16_9);
            app.OperationToolsLabel.Text = '🛠️ Operation Tools';
            app.GridLayout32 = uigridlayout(app.GridLayout16_9);
            app.SaveAnalysisResultsButton = uibutton(app.GridLayout32, 'push');
            app.SaveAnalysisResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisResultsButtonPushed, true);
            app.SaveAnalysisResultsButton.Text = '💾 Save Analysis Results';
            app.DiffHighlight_ResetButton = uibutton(app.GridLayout32, 'push');
            app.DiffHighlight_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @DiffHighlight_ResetButtonPushed, true);
            app.DiffHighlight_ResetButton.Text = '🔄 Reset';
            app.HeatmapTab = uitab(app.TabGroup);
            app.HeatmapTab.Title = '🌡️ Heatmap';
            app.GridLayout4_11 = uigridlayout(app.HeatmapTab);
            app.GridLayout4_12 = uigridlayout(app.GridLayout4_11);
            app.Panel_54 = uipanel(app.GridLayout4_12);
            app.DiffHighlightMainLayout_3 = uigridlayout(app.Panel_54);
            app.Heatmap_Analysis_Axes = uiaxes(app.DiffHighlightMainLayout_3);
            title(app.Heatmap_Analysis_Axes, 'Heatmap Analysis Results')
            app.Heatmap_Analysis_Axes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Panel_55 = uipanel(app.GridLayout4_12);
            app.GridLayout28_3 = uigridlayout(app.Panel_55);
            app.Heatmap_StatusLabel = uilabel(app.GridLayout28_3);
            app.Heatmap_StatusLabel.Text = 'Status: Ready for analysis';
            app.imgSelectionLabel_3 = uilabel(app.GridLayout28_3);
            app.imgSelectionLabel_3.Text = '📷 Image Selection';
            app.GridLayout29_3 = uigridlayout(app.GridLayout28_3);
            app.Label_8 = uilabel(app.GridLayout29_3);
            app.Label_8.Text = '';
            app.Heatmap_Img2DropDown = uidropdown(app.GridLayout29_3);
            app.Heatmap_Img2DropDown.Items = {''};
            app.Heatmap_Img2DropDown.ValueChangedFcn = createCallbackFcn(app, @Heatmap_Img2DropDownValueChanged, true);
            app.Heatmap_Img2DropDown.Placeholder = 'Select Left Image';
            app.Heatmap_Img2DropDown.Value = '';
            app.Label_9 = uilabel(app.GridLayout29_3);
            app.Label_9.Text = '';
            app.Heatmap_Img1DropDown = uidropdown(app.GridLayout29_3);
            app.Heatmap_Img1DropDown.Items = {''};
            app.Heatmap_Img1DropDown.ValueChangedFcn = createCallbackFcn(app, @Heatmap_Img1DropDownValueChanged, true);
            app.Heatmap_Img1DropDown.Placeholder = 'Select Left Image';
            app.Heatmap_Img1DropDown.Value = '';
            app.thresholdLabel_3 = uilabel(app.GridLayout28_3);
            app.thresholdLabel_3.Text = '⚙️ Threshold Settings';
            app.Heatmap_ThresholdSlider = uislider(app.GridLayout28_3);
            app.Heatmap_ThresholdSlider.Limits = [0 1];
            app.Heatmap_ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @Heatmap_ThresholdSliderValueChanged, true);
            app.Heatmap_ThresholdSlider.Value = 0.5;
            app.Heatmap_ThresholdLabel = uilabel(app.GridLayout28_3);
            app.Heatmap_ThresholdLabel.Text = 'Threshold: 0.10';
            app.analysisFuncLabel_3 = uilabel(app.GridLayout28_3);
            app.analysisFuncLabel_3.Text = '🔬Analysis Functions';
            app.Heatmap_HeatmapToggle = uibutton(app.GridLayout28_3, 'state');
            app.Heatmap_HeatmapToggle.ValueChangedFcn = createCallbackFcn(app, @Heatmap_HeatmapToggleValueChanged, true);
            app.Heatmap_HeatmapToggle.Text = '🌡️ Show Heatmap';
            app.HeatmapDetailButtonLayout = uigridlayout(app.GridLayout28_3);
            app.Heatmap_DecreaseOnlyButton = uibutton(app.HeatmapDetailButtonLayout, 'push');
            app.Heatmap_DecreaseOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @Heatmap_DecreaseOnlyButtonPushed, true);
            app.Heatmap_DecreaseOnlyButton.Text = '📉 Decrease Only';
            app.Heatmap_IncreaseOnlyButton = uibutton(app.HeatmapDetailButtonLayout, 'push');
            app.Heatmap_IncreaseOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @Heatmap_IncreaseOnlyButtonPushed, true);
            app.Heatmap_IncreaseOnlyButton.Text = '📈 Increase Only';
            app.GridLayout10_10 = uigridlayout(app.GridLayout4_11);
            app.Panel_56 = uipanel(app.GridLayout10_10);
            app.GridLayout16_13 = uigridlayout(app.Panel_56);
            app.HeatmapIndicatorLayout = uigridlayout(app.GridLayout16_13);
            app.Heatmap_DecreaseLamp = uilabel(app.HeatmapIndicatorLayout);
            app.Heatmap_DecreaseLamp.Text = '● Dec';
            app.Heatmap_IncreaseLamp = uilabel(app.HeatmapIndicatorLayout);
            app.Heatmap_IncreaseLamp.Text = '● Inc';
            app.GridLayout20_7 = uigridlayout(app.GridLayout16_13);
            app.IndicatorInformationLabel = uilabel(app.GridLayout20_7);
            app.IndicatorInformationLabel.Text = 'Indicator Information';
            app.Panel_57 = uipanel(app.GridLayout10_10);
            app.GridLayout16_14 = uigridlayout(app.Panel_57);
            app.Heatmap_RightImage_Grid = uigridlayout(app.GridLayout16_14);
            app.Heatmap_ComprehensiveToggle = uibutton(app.Heatmap_RightImage_Grid, 'state');
            app.Heatmap_ComprehensiveToggle.ValueChangedFcn = createCallbackFcn(app, @Heatmap_ComprehensiveToggleValueChanged, true);
            app.Heatmap_ComprehensiveToggle.Text = '🌡️ Show Superimposed Heatmap';
            app.GridLayout20_8 = uigridlayout(app.GridLayout16_14);
            app.SuperimposeHeatmapSettingsLabel = uilabel(app.GridLayout20_8);
            app.SuperimposeHeatmapSettingsLabel.Text = 'Superimpose Heatmap Settings';
            app.Panel_58 = uipanel(app.GridLayout10_10);
            app.GridLayout16_15 = uigridlayout(app.Panel_58);
            app.OperationToolsLabel_3 = uilabel(app.GridLayout16_15);
            app.OperationToolsLabel_3.Text = '🛠️ Operation Tools';
            app.GridLayout32_3 = uigridlayout(app.GridLayout16_15);
            app.Heatmap_SaveButton = uibutton(app.GridLayout32_3, 'push');
            app.Heatmap_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @Heatmap_SaveButtonPushed, true);
            app.Heatmap_SaveButton.Text = '💾 Save Analysis Results';
            app.Heatmap_ResetButton = uibutton(app.GridLayout32_3, 'push');
            app.Heatmap_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Heatmap_ResetButtonPushed, true);
            app.Heatmap_ResetButton.Text = '🔄 Reset';
            app.FlickerViewTab = uitab(app.TabGroup);
            app.FlickerViewTab.Title = '⚡ Flicker View';
            app.GridLayout4_15 = uigridlayout(app.FlickerViewTab);
            app.GridLayout4_16 = uigridlayout(app.GridLayout4_15);
            app.Panel_69 = uipanel(app.GridLayout4_16);
            app.FlickerMainLayout = uigridlayout(app.Panel_69);
            app.Flicker_Display_Axes = uiaxes(app.FlickerMainLayout);
            title(app.Flicker_Display_Axes, 'Flicker Display')
            app.Flicker_Display_Axes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Panel_70 = uipanel(app.GridLayout4_16);
            app.FlickerControlLayout = uigridlayout(app.Panel_70);
            app.Label_12 = uilabel(app.FlickerControlLayout);
            app.Label_12.Text = '';
            app.Flicker_ModeDropDown = uidropdown(app.FlickerControlLayout);
            app.Flicker_ModeDropDown.Items = {'Original ↔ Original', 'Original ↔ Highlight', 'Original ↔ Heatmap'};
            app.Flicker_ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @Flicker_ModeDropDownValueChanged, true);
            app.Flicker_ModeDropDown.Placeholder = 'Original ↔ Original';
            app.Flicker_ModeDropDown.Value = 'Original ↔ Original';
            app.modeLabel = uilabel(app.FlickerControlLayout);
            app.modeLabel.Text = '🔬 Flicker Mode';
            app.Flicker_FlickerToggle = uibutton(app.FlickerControlLayout, 'state');
            app.Flicker_FlickerToggle.ValueChangedFcn = createCallbackFcn(app, @Flicker_FlickerToggleValueChanged, true);
            app.Flicker_FlickerToggle.Text = '⚡ Start Flicker';
            app.Flicker_SpeedLabel = uilabel(app.FlickerControlLayout);
            app.Flicker_SpeedLabel.Text = 'Speed: 0.5s';
            app.Flicker_SpeedSlider = uislider(app.FlickerControlLayout);
            app.Flicker_SpeedSlider.Limits = [0.1 2];
            app.Flicker_SpeedSlider.ValueChangedFcn = createCallbackFcn(app, @Flicker_SpeedSliderValueChanged, true);
            app.Flicker_SpeedSlider.Value = 0.5;
            app.speedLabel = uilabel(app.FlickerControlLayout);
            app.speedLabel.Text = '⚙️ Flicker Speed';
            app.GridLayout29_4 = uigridlayout(app.FlickerControlLayout);
            app.Label_14 = uilabel(app.GridLayout29_4);
            app.Label_14.Text = '';
            app.Flicker_Img2DropDown = uidropdown(app.GridLayout29_4);
            app.Flicker_Img2DropDown.Items = {''};
            app.Flicker_Img2DropDown.ValueChangedFcn = createCallbackFcn(app, @Flicker_Img2DropDownValueChanged, true);
            app.Flicker_Img2DropDown.Placeholder = 'Select Right Image';
            app.Flicker_Img2DropDown.Value = '';
            app.Label_13 = uilabel(app.GridLayout29_4);
            app.Label_13.Text = '';
            app.Flicker_Img1DropDown = uidropdown(app.GridLayout29_4);
            app.Flicker_Img1DropDown.Items = {''};
            app.Flicker_Img1DropDown.ValueChangedFcn = createCallbackFcn(app, @Flicker_Img1DropDownValueChanged, true);
            app.Flicker_Img1DropDown.Placeholder = 'Select Left Image';
            app.Flicker_Img1DropDown.Value = '';
            app.FlickerImgSelectionLabel = uilabel(app.FlickerControlLayout);
            app.FlickerImgSelectionLabel.Text = '📷 Image Selection';
            app.Flicker_StatusLabel = uilabel(app.FlickerControlLayout);
            app.Flicker_StatusLabel.Text = 'Status: Ready for analysis';
            app.GridLayout10_11 = uigridlayout(app.GridLayout4_15);
            app.Panel_73 = uipanel(app.GridLayout10_11);
            app.GridLayout16_18 = uigridlayout(app.Panel_73);
            app.toolsLabel = uilabel(app.GridLayout16_18);
            app.toolsLabel.Text = '🛠️ Operation Tools';
            app.GridLayout32_4 = uigridlayout(app.GridLayout16_18);
            app.Flicker_SaveButton = uibutton(app.GridLayout32_4, 'push');
            app.Flicker_SaveButton.ButtonPushedFcn = createCallbackFcn(app, @Flicker_SaveButtonPushed, true);
            app.Flicker_SaveButton.Text = '💾 Save Current Frame';
            app.Flicker_ResetButton = uibutton(app.GridLayout32_4, 'push');
            app.Flicker_ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Flicker_ResetButtonPushed, true);
            app.Flicker_ResetButton.Text = '🔄 Reset';
            app.GridLayout16_19 = uigridlayout(app.GridLayout10_11);
            app.AnalysisInformationLabel = uilabel(app.GridLayout16_19);
            app.AnalysisInformationLabel.Text = '📊 Analysis Information';
            app.SideBySide_InfoTextArea_2 = uitextarea(app.GridLayout16_19);
            app.SideBySide_InfoTextArea_2.Editable = 'off';
            app.SideBySide_InfoTextArea_2.Value = {'1. Select two different images for comparison'; '2. Adjust flicker speed for comfortable viewing'; '3. Choose flicker mode:'; '   • Original ↔ Original: Compare raw images'; '   • Original ↔ Highlight: Compare with difference highlight'; '   • Original ↔ Heatmap: Compare with heatmap overlay'};
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = '📄 Report';
            app.GridLayout4_7 = uigridlayout(app.ReportTab);
            app.GridLayout8_7 = uigridlayout(app.GridLayout4_7);
            app.GridLayout9_7 = uigridlayout(app.GridLayout8_7);
            app.Panel_36 = uipanel(app.GridLayout9_7);
            app.Panel_35 = uipanel(app.GridLayout8_7);
            app.GridLayout10_7 = uigridlayout(app.GridLayout4_7);
            app.Panel_37 = uipanel(app.GridLayout10_7);
            app.Panel_38 = uipanel(app.GridLayout10_7);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cv_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end